classdef PEGS_4Channel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PositroninducedElectronGammaUIFigure  matlab.ui.Figure
        OptionsMenu                     matlab.ui.container.Menu
        RiseTimesMenu                   matlab.ui.container.Menu
        PeakLocationMenu                matlab.ui.container.Menu
        CollectionTimeMenu              matlab.ui.container.Menu
        InitialSampleMenu               matlab.ui.container.Menu
        EnergyCalibrationMenu           matlab.ui.container.Menu
        MultiStopConditionsMenu         matlab.ui.container.Menu
        StabilizationSettingsMenu       matlab.ui.container.Menu
        ShapingParameterOptimizationMenu  matlab.ui.container.Menu
        TimingParameterOptimizationMenu  matlab.ui.container.Menu
        SelectMultipleFoldersMenu       matlab.ui.container.Menu
        ANNsMenu                        matlab.ui.container.Menu
        TimingMenu                      matlab.ui.container.Menu
        InstallNewNetworkMenu           matlab.ui.container.Menu
        InstallNewDiscriminationVectorMenu  matlab.ui.container.Menu
        InstallNewParameterMatrixMenu   matlab.ui.container.Menu
        ShapingMenu                     matlab.ui.container.Menu
        InstallNewNetworkMenu_2         matlab.ui.container.Menu
        numSamples                      matlab.ui.control.NumericEditField
        NumberofSamplesPerFolderLabel   matlab.ui.control.Label
        TracesFolderButton              matlab.ui.control.Button
        PercentageCompleteGaugeLabel    matlab.ui.control.Label
        PercentageCompleteGauge         matlab.ui.control.Gauge
        SavingFolderButton              matlab.ui.control.Button
        TracesFolder                    matlab.ui.control.EditField
        SavingFolder                    matlab.ui.control.EditField
        PlotsSwitchLabel                matlab.ui.control.Label
        PlotsSwitch                     matlab.ui.control.Switch
        PAUSEButton                     matlab.ui.control.StateButton
        ModeLampLabel                   matlab.ui.control.Label
        ModeLamp                        matlab.ui.control.Lamp
        DPlotSwitchLabel                matlab.ui.control.Label
        DPlotSwitch                     matlab.ui.control.Switch
        SAVECURRENTButton               matlab.ui.control.Button
        STARTButton_2                   matlab.ui.control.StateButton
        MultiFolderSwitchLabel          matlab.ui.control.Label
        MultiFolderSwitch               matlab.ui.control.Switch
        AnalysisRateHzLabel             matlab.ui.control.Label
        RateGauge                       matlab.ui.control.SemicircularGauge
        TabGroup                        matlab.ui.container.TabGroup
        ToFSpectrumControlsTab          matlab.ui.container.Tab
        TimingWindowsKnobLabel          matlab.ui.control.Label
        TimingWindowsKnob               matlab.ui.control.DiscreteKnob
        MultiStopSwitchLabel            matlab.ui.control.Label
        MultiStopSwitch                 matlab.ui.control.Switch
        CoinChDropDownLabel             matlab.ui.control.Label
        CoinChDropDown                  matlab.ui.control.DropDown
        DropDown_5Label_2               matlab.ui.control.Label
        CoinChDropDown2                 matlab.ui.control.DropDown
        SecondECutoff                   matlab.ui.control.NumericEditField
        LastElectronCutoffnsLabel       matlab.ui.control.Label
        UpperLabel_6                    matlab.ui.control.Label
        TimeWindowUpper                 matlab.ui.control.NumericEditField
        LowerLabel_7                    matlab.ui.control.Label
        TimeWindowLower                 matlab.ui.control.NumericEditField
        MultiStopSwitchLabel_2          matlab.ui.control.Label
        GammaSpectrumControlsTab        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        Tab_10                          matlab.ui.container.Tab
        Ch1GammaEditField               matlab.ui.control.NumericEditField
        GammaWindowSliderLabel          matlab.ui.control.Label
        GammaStableCh                   matlab.ui.control.NumericEditField
        GammaStableChLabel              matlab.ui.control.Label
        GammaStabilizerSwitchLabel      matlab.ui.control.Label
        Ch1GammaStabilizerSwitch        matlab.ui.control.Switch
        SaveStabilizerControlSwitchLabel  matlab.ui.control.Label
        Ch1SaveStabilizerControlSwitch  matlab.ui.control.Switch
        SetStableChSwitchLabel          matlab.ui.control.Label
        Ch1SetStableChSwitch            matlab.ui.control.Switch
        Ch1GammaEditField_Lower         matlab.ui.control.NumericEditField
        GammaWindowSliderLabel_8        matlab.ui.control.Label
        Tab_11                          matlab.ui.container.Tab
        GammaWindowSliderLabel_2        matlab.ui.control.Label
        GammaStableCh_2                 matlab.ui.control.NumericEditField
        GammaStableChLabel_2            matlab.ui.control.Label
        GammaStabilizerSwitch_2Label    matlab.ui.control.Label
        Ch2GammaStabilizerSwitch        matlab.ui.control.Switch
        SaveStabilizerControlSwitch_2Label  matlab.ui.control.Label
        Ch2SaveStabilizerControlSwitch  matlab.ui.control.Switch
        SetStableChSwitch_2Label        matlab.ui.control.Label
        Ch2SetStableChSwitch            matlab.ui.control.Switch
        Ch2GammaEditField               matlab.ui.control.NumericEditField
        GammaWindowSliderLabel_7        matlab.ui.control.Label
        Ch2GammaEditField_Lower         matlab.ui.control.NumericEditField
        Tab_12                          matlab.ui.container.Tab
        GammaWindowSliderLabel_3        matlab.ui.control.Label
        GammaStableCh_3                 matlab.ui.control.NumericEditField
        GammaStableChLabel_3            matlab.ui.control.Label
        GammaStabilizerSwitch_3Label    matlab.ui.control.Label
        Ch3GammaStabilizerSwitch        matlab.ui.control.Switch
        SaveStabilizerControlSwitch_3Label  matlab.ui.control.Label
        Ch3SaveStabilizerControlSwitch  matlab.ui.control.Switch
        SetStableChSwitch_3Label        matlab.ui.control.Label
        Ch3SetStableChSwitch            matlab.ui.control.Switch
        Ch3GammaEditField               matlab.ui.control.NumericEditField
        GammaWindowSliderLabel_6        matlab.ui.control.Label
        Ch3GammaEditField_Lower         matlab.ui.control.NumericEditField
        Tab_9                           matlab.ui.container.Tab
        GammaWindowSliderLabel_4        matlab.ui.control.Label
        GammaStableCh_4                 matlab.ui.control.NumericEditField
        GammaStableChLabel_4            matlab.ui.control.Label
        Ch4GammaEditField               matlab.ui.control.NumericEditField
        GammaStabilizerSwitch_4Label    matlab.ui.control.Label
        Ch4GammaStabilizerSwitch        matlab.ui.control.Switch
        SaveStabilizerControlSwitch_4Label  matlab.ui.control.Label
        Ch4SaveStabilizerControlSwitch  matlab.ui.control.Switch
        SetStableChSwitch_4Label        matlab.ui.control.Label
        Ch4SetStableChSwitch            matlab.ui.control.Switch
        GammaWindowSliderLabel_5        matlab.ui.control.Label
        Ch4GammaEditField_Lower         matlab.ui.control.NumericEditField
        BandPassFilterPanel             matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        Channels12Tab_2                 matlab.ui.container.Tab
        FFTFilterCh1Upper               matlab.ui.control.NumericEditField
        Ch1Label                        matlab.ui.control.Label
        Ch2Label                        matlab.ui.control.Label
        FFTFilterCh2Upper               matlab.ui.control.NumericEditField
        FFTFilterCutoffFrequenciesMHzLabel  matlab.ui.control.Label
        FFTFilterPlot                   matlab.ui.control.UIAxes
        PlotLPFButton                   matlab.ui.control.Button
        FFTFilterPlot_2                 matlab.ui.control.UIAxes
        PlotLPFButtonNext               matlab.ui.control.Button
        FFTFilterCh2Lower               matlab.ui.control.NumericEditField
        FFTFilterCh1Lower               matlab.ui.control.NumericEditField
        Channels34Tab_2                 matlab.ui.container.Tab
        FFTFilterCh3Upper               matlab.ui.control.NumericEditField
        Ch3Label                        matlab.ui.control.Label
        Ch4Label                        matlab.ui.control.Label
        FFTFilterCh4Upper               matlab.ui.control.NumericEditField
        FFTFilterCutoffFrequenciesMHzLabel_2  matlab.ui.control.Label
        FFTFilterPlot_3                 matlab.ui.control.UIAxes
        PlotLPFButton_2                 matlab.ui.control.Button
        FFTFilterPlot_4                 matlab.ui.control.UIAxes
        PlotLPFButtonNext_2             matlab.ui.control.Button
        FFTFilterCh4Lower               matlab.ui.control.NumericEditField
        FFTFilterCh3Lower               matlab.ui.control.NumericEditField
        FFTFilterSwitch                 matlab.ui.control.Switch
        ShapingFittingPanel             matlab.ui.container.Panel
        TabGroup5                       matlab.ui.container.TabGroup
        Tab_5                           matlab.ui.container.Tab
        RiseFallLabel                   matlab.ui.control.Label
        TopLabel                        matlab.ui.control.Label
        Ch1TrapezoidalFilterTop         matlab.ui.control.NumericEditField
        ShapingConstantssLabel_2        matlab.ui.control.Label
        Ch1TrapezoidalFilterRiseFall    matlab.ui.control.NumericEditField
        TrapezoidalFilterPlot           matlab.ui.control.UIAxes
        TrapezoidalFilterButton         matlab.ui.control.Button
        TrapezoidalFilterButtonNext     matlab.ui.control.Button
        Tab_6                           matlab.ui.container.Tab
        RiseFallLabel_2                 matlab.ui.control.Label
        TopLabel_2                      matlab.ui.control.Label
        Ch2TrapezoidalFilterTop         matlab.ui.control.NumericEditField
        ShapingConstantssLabel_3        matlab.ui.control.Label
        Ch2TrapezoidalFilterRiseFall    matlab.ui.control.NumericEditField
        TrapezoidalFilterPlot_2         matlab.ui.control.UIAxes
        TrapezoidalFilterButton_2       matlab.ui.control.Button
        TrapezoidalFilterButtonNext_2   matlab.ui.control.Button
        Tab_7                           matlab.ui.container.Tab
        RiseFallLabel_4                 matlab.ui.control.Label
        TopLabel_4                      matlab.ui.control.Label
        Ch3TrapezoidalFilterTop         matlab.ui.control.NumericEditField
        ShapingConstantssLabel_5        matlab.ui.control.Label
        Ch3TrapezoidalFilterRiseFall    matlab.ui.control.NumericEditField
        TrapezoidalFilterPlot_3         matlab.ui.control.UIAxes
        TrapezoidalFilterButton_3       matlab.ui.control.Button
        TrapezoidalFilterButtonNext_3   matlab.ui.control.Button
        Tab_8                           matlab.ui.container.Tab
        RiseFallLabel_3                 matlab.ui.control.Label
        TopLabel_3                      matlab.ui.control.Label
        Ch4TrapezoidalFilterTop         matlab.ui.control.NumericEditField
        ShapingConstantssLabel_4        matlab.ui.control.Label
        Ch4TrapezoidalFilterRiseFall    matlab.ui.control.NumericEditField
        TrapezoidalFilterPlot_4         matlab.ui.control.UIAxes
        TrapezoidalFilterButton_4       matlab.ui.control.Button
        TrapezoidalFilterButtonNext_4   matlab.ui.control.Button
        RiseTimeFilterPanel             matlab.ui.container.Panel
        UpperLabel                      matlab.ui.control.Label
        RiseTimeFilterUpper             matlab.ui.control.NumericEditField
        LowerLabel                      matlab.ui.control.Label
        RiseTimeFilterLower             matlab.ui.control.NumericEditField
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        CutOffValuesnsLabel             matlab.ui.control.Label
        RejectionField1                 matlab.ui.control.NumericEditField
        RejectionField2                 matlab.ui.control.NumericEditField
        RejectionRatioLabel             matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        Plots12Tab                      matlab.ui.container.Tab
        Plot1                           matlab.ui.control.UIAxes
        Plot2                           matlab.ui.control.UIAxes
        Plots34Tab                      matlab.ui.container.Tab
        Plot3                           matlab.ui.control.UIAxes
        Plot4                           matlab.ui.control.UIAxes
        RejectionField3                 matlab.ui.control.NumericEditField
        RejectionField4                 matlab.ui.control.NumericEditField
        ChannelConfigurationPanel       matlab.ui.container.Panel
        TabGroup6                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Channel1Type                    matlab.ui.control.DropDown
        ChannelTypeLabel                matlab.ui.control.Label
        TimingTypeDropDownLabel         matlab.ui.control.Label
        Ch1Timing                       matlab.ui.control.DropDown
        Ch1Shaping                      matlab.ui.control.DropDown
        ShapingTypeLabel                matlab.ui.control.Label
        ofBinsEditFieldLabel_2          matlab.ui.control.Label
        BinsEditField1                  matlab.ui.control.NumericEditField
        CFDValueofMagLabel              matlab.ui.control.Label
        Ch1ELETLower                    matlab.ui.control.NumericEditField
        Ch1ELETUpper                    matlab.ui.control.NumericEditField
        ValueLabel                      matlab.ui.control.Label
        UpperLabel_2                    matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        Channel2Type                    matlab.ui.control.DropDown
        ChannelTypeLabel_2              matlab.ui.control.Label
        Ch2Shaping                      matlab.ui.control.DropDown
        ShapingTypeLabel_3              matlab.ui.control.Label
        TimingTypeDropDown_2Label       matlab.ui.control.Label
        Ch2Timing                       matlab.ui.control.DropDown
        ofBinsEditFieldLabel            matlab.ui.control.Label
        BinsEditField2                  matlab.ui.control.NumericEditField
        ELETValuesofMagLabel_2          matlab.ui.control.Label
        Ch2ELETLower                    matlab.ui.control.NumericEditField
        Ch2ELETUpper                    matlab.ui.control.NumericEditField
        LowerLabel_4                    matlab.ui.control.Label
        UpperLabel_3                    matlab.ui.control.Label
        Tab_3                           matlab.ui.container.Tab
        Channel3Type                    matlab.ui.control.DropDown
        ChannelTypeLabel_3              matlab.ui.control.Label
        ShapingTypeLabel_4              matlab.ui.control.Label
        TimingTypeDropDown_3Label       matlab.ui.control.Label
        Ch3Timing                       matlab.ui.control.DropDown
        Ch3Shaping                      matlab.ui.control.DropDown
        ofBinsEditFieldLabel_3          matlab.ui.control.Label
        BinsEditField3                  matlab.ui.control.NumericEditField
        ELETValuesofMagLabel_3          matlab.ui.control.Label
        Ch3ELETLower                    matlab.ui.control.NumericEditField
        Ch3ELETUpper                    matlab.ui.control.NumericEditField
        LowerLabel_5                    matlab.ui.control.Label
        UpperLabel_4                    matlab.ui.control.Label
        Tab_4                           matlab.ui.container.Tab
        Channel4Type                    matlab.ui.control.DropDown
        ChannelTypeLabel_4              matlab.ui.control.Label
        ShapingTypeLabel_5              matlab.ui.control.Label
        TimingTypeDropDown_4Label       matlab.ui.control.Label
        Ch4Timing                       matlab.ui.control.DropDown
        Ch4Shaping                      matlab.ui.control.DropDown
        ofBinsEditFieldLabel_4          matlab.ui.control.Label
        BinsEditField4                  matlab.ui.control.NumericEditField
        ELETValuesofMagLabel_4          matlab.ui.control.Label
        Ch4ELETLower                    matlab.ui.control.NumericEditField
        Ch4ELETUpper                    matlab.ui.control.NumericEditField
        LowerLabel_6                    matlab.ui.control.Label
        UpperLabel_5                    matlab.ui.control.Label
        GEGammaChannelDropDownLabel     matlab.ui.control.Label
        PEGSGammaChannelDropDown        matlab.ui.control.DropDown
        GammaGammaCoincidenceChannelsDropDownLabel  matlab.ui.control.Label
        GGCChannel_1                    matlab.ui.control.DropDown
        GGCChannel_2                    matlab.ui.control.DropDown
        Plot1Label                      matlab.ui.control.Label
        Plot2Label                      matlab.ui.control.Label
        Plot3Label                      matlab.ui.control.Label
        Plot4Label                      matlab.ui.control.Label
        ElectronCoincidenceLabel        matlab.ui.control.Label
        ElectronCoincidenceSwitch       matlab.ui.control.Switch
        numSamplesT                     matlab.ui.control.NumericEditField
        TotalNumberofSamplesLabel       matlab.ui.control.Label
    end


    methods (Access = private)
    
        function EPlusE = multiStopping(app,timeOfFlight,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper,numBinsLocal,energyCoinCha,multiStop,ToFChannel)
            global ToF;
            global ToF2;
            global ToF3;
            global xdataToF;
            global ax2;
            global ax3;
            global ax4;
            global ax5;
            global electronVsElectron;
            global electronVsElectronEnergy;
            global electronPlusElectron;
            global electronMinusElectron;
            global sumDiffMatrix;
            global multiStopConditions;
            global totalMultiToFs;
            
            timeOfFlight1 = cell(numel(timeOfFlight));
            ePe = zeros(1,10)';
            eMe = zeros(1,10)';
            EPlusE = zeros(1,10)';
            EMinusE = zeros(1,10)';
            E2PlusE3 = zeros(1,10)';
            E2PlusE3Energy = zeros(1,10)';
            cutoff = app.SecondECutoff.Value*10.^(-9);

            for l=1:numel(timeOfFlight)
                if numel(timeOfFlight{l}) > 1
                    timeOfFlight1{l} = timeOfFlight{l}(1);
                    
                else
                    timeOfFlight1{l} = NaN;
                    
                end
                
            end
            
            timeOfFlight2 = cell(numel(timeOfFlight));
            
            for l=1:numel(timeOfFlight)
                
                if numel(timeOfFlight{l}) > 1 && timeOfFlight1{l} ~= 1
                    timeOfFlight2{l} = timeOfFlight{l}(2);
                    
                    %{
                    % -----------------------------------------------------------------------------------
                    % Second electron time-of-flight randomizer
                    % -----------------------------------------------------------------------------------
                    X = 1;
                    rng('shuffle');
                    while X <= timeOfFlight1{l} || X > 10000*10.^(-9)
                        X = (1300*randn + 580)*10.^(-9);
                    end
                    
                    timeOfFlight2{l} = X;
                    % -----------------------------------------------------------------------------------
                    %}
                    
                else
                    timeOfFlight2{l} = NaN;
                    
                end
                
            end
            
            % ----------------------------------------------------------------
            % REMOVE CONDITION
            % ----------------------------------------------------------------
            if  multiStopConditions == 6
                for s=1:numel(timeOfFlight2)
                    
                    if isempty(timeOfFlight2{s}) == 0 && (timeOfFlight2{s} > cutoff || ...
                            isnan(timeOfFlight2{s}) || isnan(timeOfFlight1{s}))
                        timeOfFlight1{s} = NaN;
                        timeOfFlight2{s} = NaN;
                        
                        'ToF2 NAN Error'
                        
                    end
                    
                end
                
                for l=1:numel(timeOfFlight1)
                    if ~isempty(timeOfFlight1{l}) && ~isempty(timeOfFlight2{l}) && ~isnan(timeOfFlight1{l}) && ~isnan(timeOfFlight2{l})
                        totalMultiToFs = horzcat(totalMultiToFs,[timeOfFlight1{l}; timeOfFlight2{l}]);
                        assignin('base','totalMultiToFs',totalMultiToFs);
                        
                    end
                    
                end
            end
            
            if multiStopConditions == 7
                timeOfFlight3 = cell(numel(timeOfFlight));
                
                for l=1:numel(timeOfFlight)
                    
                    if numel(timeOfFlight{l}) > 2
                        timeOfFlight3{l} = timeOfFlight{l}(3);
                        
                    else
                        timeOfFlight3{l} = NaN;
                        timeOfFlight2{l} = NaN;
                        timeOfFlight1{l} = NaN;
                        
                    end
                    
                end
                
                for l=1:numel(timeOfFlight1)
                    if ~isempty(timeOfFlight1{l}) && ~isempty(timeOfFlight2{l}) && ~isnan(timeOfFlight1{l}) && ~isnan(timeOfFlight2{l})
                        totalMultiToFs = horzcat(totalMultiToFs,[timeOfFlight1{l}; timeOfFlight2{l}; timeOfFlight2{l}]);
                        assignin('base','totalMultiToFs',totalMultiToFs);
                        
                    end
                    
                end
                
                for s=1:numel(timeOfFlight3)
                    
                    if isempty(timeOfFlight3{s}) == 0 && (timeOfFlight3{s} > cutoff || ...
                            isnan(timeOfFlight3{s}) || isnan(timeOfFlight2{s}) || isnan(timeOfFlight1{s}))
                        timeOfFlight1{s} = NaN;
                        timeOfFlight2{s} = NaN;
                        timeOfFlight3{s} = NaN;
                        %'CUTOFF ERROR'
                        
                    end
                    
                end
                
                [hToF,~,~,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                    [],timeOfFlight3,energyCoinCha,1,[],false,false,multiStopConditions,app.SecondECutoff);
                
                %Ge2 = Ge2 + hToF;
                ToF3 = ToF3 + hToF;
                
            end
            
            [hToF,~,~,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                [],timeOfFlight1,energyCoinCha,1,[],false,false,multiStopConditions,app.SecondECutoff);
            
            ToF = ToF + hToF;
            
            [hToF,~,~,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                [],timeOfFlight2,energyCoinCha,1,[],false,false,multiStopConditions,app.SecondECutoff);
            
            %Ge2 = Ge2 + hToF;
            ToF2 = ToF2 + hToF;
            
            % Creating a two-electron "coincidence" 2-dimensional spectrum.
            if app.ElectronCoincidenceSwitch.Value == '1'
                
                for s=1:10
                    
                    eeCoincidenceToF{1}(s) = timeOfFlight1{s};
                    eeCoincidenceToF{2}(s) = timeOfFlight2{s};
                    %TMinusT = timeOfFlight2{s} - timeOfFlight1{s};
                    
                    if multiStopConditions == 7
                        
                        eeCoincidenceToF{3}(s) = timeOfFlight3{s};
                        
                    end
                    
                    % Energy conversion attempt.
                    if (timeOfFlight1{s}*10.^(9)*1.899627E-4 - 0.0087631) > 0 && ...
                            (timeOfFlight2{s}*10.^(9)*1.899627E-4 - 0.0087631) > 0 && ...
                            timeOfFlight1{s}*10.^(9)*1.899627E-4 - 0.0087631 < 1000 && ...
                            timeOfFlight2{s}*10.^(9)*1.899627E-4 - 0.0087631 < 1000 && ...
                            timeOfFlight1{s} < timingWindowUpper && timeOfFlight2{s} < timingWindowUpper && ...
                            timeOfFlight2{s} <= cutoff
                        
                        eeCoincidenceEnergy{1}(s) = (timeOfFlight1{s}*10.^(9)*1.899627E-4 - 0.0087631).^(-2);
                        eeCoincidenceEnergy{2}(s) = (timeOfFlight2{s}*10.^(9)*1.899627E-4 - 0.0087631).^(-2);
                        
                        if multiStopConditions == 7 && (timeOfFlight3{s}*10.^(9)*1.899627E-4 - 0.0087631) > 0 && ...
                                timeOfFlight3{s}*10.^(9)*1.899627E-4 - 0.0087631 < 1000 && ...
                                timeOfFlight3{s} < timingWindowUpper
                            
                            eeCoincidenceEnergy{3}(s) = (timeOfFlight3{s}*10.^(9)*1.899627E-4 - 0.0087631).^(-2);
                            E2PlusE3Energy(s) = eeCoincidenceEnergy{2}(s) + eeCoincidenceEnergy{3}(s);
                            E2PlusE3(s) = ((E2PlusE3Energy(s).^(-1/2) + 0.0087631)/(10.^(9)*1.899627E-4));
                            
                        else
                            eeCoincidenceEnergy{3}(s) = 0;
                            E2PlusE3(s) = 0;
                            %eeCoincidenceToF{2}(s) = E2PlusE3(s);
                        end
                        
                        ePe(s) = eeCoincidenceEnergy{1}(s) + eeCoincidenceEnergy{2}(s) + eeCoincidenceEnergy{3}(s);
                        eMe(s) = eeCoincidenceEnergy{1}(s) - (eeCoincidenceEnergy{2}(s) + eeCoincidenceEnergy{3}(s));
                        
                        EPlusE(s) = ((ePe(s).^(-1/2) + 0.0087631)/(10.^(9)*1.899627E-4));
                        EMinusE(s) = ((eMe(s).^(-1/2) + 0.0087631)/(10.^(9)*1.899627E-4));
                        
                        if isreal(EPlusE(s)) == 0 || isreal(EMinusE(s)) == 0
                            EPlusE(s) = 1;
                            EMinusE(s) = 1;
                            
                        end
                        
                        %{
                                    assignin('base','eeCoincidenceToF',eeCoincidenceToF{1}(s));
                                    assignin('base','eeCoincidenceEnergy',eeCoincidenceEnergy{1}(s));
                                    assignin('base','EPlusE',EPlusE(s));
                                    evalin('base','eeCoincidenceToFNew = horzcat(eeCoincidenceToFNew,eeCoincidenceToF);');
                                    evalin('base','eeCoincidenceEnergyNew = horzcat(eeCoincidenceEnergyNew,eeCoincidenceEnergy);');
                                    evalin('base','EPlusENew = horzcat(EPlusENew,EPlusE);');
                        %}
                        
                        ESumEDiff{1}(s) = EPlusE(s);
                        ESumEDiff{2}(s) = EMinusE(s);
                        
                    else
                        eeCoincidenceEnergy{1}(s) = 0;
                        eeCoincidenceEnergy{2}(s) = 0;
                        EPlusE(s) = 1;
                        EMinusE(s) = 1;
                        ESumEDiff{1}(s) = 1;
                        ESumEDiff{2}(s) = 1;
                        
                    end
                    
                end
                
                %EPlusE(isreal(EPlusE) == 0) = 1;
                %EMinusE(isreal(EMinusE) == 0) = 1;
                
                
                [~,~,~,~,eVse] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                    [],eeCoincidenceToF,5,1,[],false,true,multiStopConditions,app.SecondECutoff);
                
                electronVsElectron = electronVsElectron + eVse;
                
                [~,~,~,~,sumDiff] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                    [],ESumEDiff,5,1,[],false,true,multiStopConditions,app.SecondECutoff);
                
                sumDiffMatrix = sumDiffMatrix + sumDiff;
                
                % Energy conversion plotting attempt.
                
                [~,~,~,~,eVseEnergy] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,1000, ...
                    [],eeCoincidenceEnergy,5,1,[],false,true,multiStopConditions,app.SecondECutoff);
                
                electronVsElectronEnergy = electronVsElectronEnergy + eVseEnergy;
                
                % E + E and E - E plots.
                %EPlusE = eeCoincidenceEnergy{1} + eeCoincidenceEnergy{2};
                %EMinusE = eeCoincidenceEnergy{1} - eeCoincidenceEnergy{2};
                
                [hToF,~,~,~,~] = histo(10,'0',numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                    [],EPlusE,5,1,[],false,false,multiStopConditions,app.SecondECutoff);
                
                hToF(1) = 0;
                hToF(numBinsLocal(ToFChannel)) = 0;
                electronPlusElectron = electronPlusElectron + hToF;
                
                [hToF,~,~,~,~] = histo(10,'0',numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                    [],EMinusE,5,1,[],false,false,multiStopConditions,app.SecondECutoff);
                
                hToF(1) = 0;
                hToF(numBinsLocal(ToFChannel)) = 0;
                electronMinusElectron = electronMinusElectron + hToF;
                
                %[hToF,~,~,~,~] = histo(10,'0',numBinsLocal,ToFChannel,gammaWindow,timingWindowLower,timingWindowUpper, ...
                %    [],TMinusT,energyCoinCha,1,[],false,false,multiStopConditions);
                
                %hToF(1) = 0;
                %hToF(numBinsLocal(ToFChannel)) = 0;
                %assignin('base','hToF',hToF);
                %evalin('base','TMinusT = TMinusT + hToF');
                
            end
            
            if app.PlotsSwitch.Value == '1'
                
                try
                    plot(ax2,xdataToF,ToF2);
                    
                    if multiStopConditions == 7
                        plot(ax5,xdataToF,ToF3);
                    end
                    
                    if app.ElectronCoincidenceSwitch.Value == '1'
                        plot(ax3,xdataToF,electronPlusElectron);
                        plot(ax4,xdataToF,electronMinusElectron);
                    end
                    
                catch
                    
                    if multiStopConditions == 6
                        f2 = figure('Name','ToF 2');
                        ax2 = axes(f2);
                        plot(ax2,xdataToF,ToF2);
                        
                    end
                    
                    if multiStopConditions == 7
                        f5 = figure('Name','ToF 3');
                        ax5 = axes(f5);
                        plot(ax5,xdataToF,ToF3);
                        
                    end
                    
                    if app.ElectronCoincidenceSwitch.Value == '1'
                        f3 = figure('Name','E + E');
                        ax3 = axes(f3);
                        plot(ax3,xdataToF,electronPlusElectron);
                        
                        f4 = figure('Name','E - E');
                        ax4 = axes(f4);
                        plot(ax4,xdataToF,electronMinusElectron);
                        
                    end
                    
                end
                
            end
            
            %plot(ax2,xdataToF,ToF2);
        end
        
        % Plotting function for spectra that can be used throughout the app.
        
        function plots(app, event)
            
            % Defining global variables in order to send to plot() function.
            
            global ToF;
            global ToF2;
            global Ge1;
            global Ge2;
            global Ge3;
            global Ge4;
            global xdataToF;
            global xdataGe1;
            global xdataGe2;
            global xdataGe3;
            global xdataGe4;
            global ToFPlotting;
            global ToF2OnlySecond;
            global GePlotting1;
            global GePlotting2;
            global GePlotting3;
            global GePlotting4;
            global AugerGammaPlotting;
            global ax;
            global ax2;
            global gammaVsToF;
            global chType;
            global multiStopConditions;
            
            if event == 0
                
                if chType(1) == 2 || chType(1) == 3
                    GePlotting1 = plot(app.Plot1,xdataGe1,Ge1);
                    GePlotting1.XDataSource = 'xdataGe1';
                    GePlotting1.YDataSource = 'Ge1';
                    
                elseif chType(1) == 1
                    ToFPlotting = plot(app.Plot1,xdataToF,ToF);
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                end
                
                if chType(2) == 2 || chType(2) == 3
                    %GePlotting2 = plot(app.Plot2,newData(5:numBinsGe(2)-5),newData2(5:numBinsGe(2)-5));
                    GePlotting2 = plot(app.Plot2,xdataGe2,Ge2);
                    GePlotting2.XDataSource = 'xdataGe2';
                    GePlotting2.YDataSource = 'Ge2';
                    
                elseif chType(2) == 1
                    ToFPlotting = plot(app.Plot2,xdataToF,ToF);
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                end
                
                if chType(3) == 2 || chType(3) == 3
                    GePlotting3 = plot(app.Plot3,xdataGe3,Ge3);
                    GePlotting3.XDataSource = 'xdataGe3';
                    GePlotting3.YDataSource = 'Ge3';
                    
                elseif chType(3) == 1
                    ToFPlotting = plot(app.Plot3,xdataToF,ToF);
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                end
                
                if chType(4) == 2 || chType(4) == 3
                    GePlotting4 = plot(app.Plot4,xdataGe4,Ge4);
                    GePlotting4.XDataSource = 'xdataGe4';
                    GePlotting4.YDataSource = 'Ge4';
                    
                elseif chType(4) == 1
                    ToFPlotting = plot(app.Plot4,xdataToF,ToF);
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                end
                
                % Ignore these warnings.
                
                if app.DPlotSwitch.Value == '1'
                    f = figure;
                    ax = axes(f);
                    AugerGammaPlotting = surf(ax,gammaVsToF);
                    linkdata on;
                    shading interp;
                    xlim(ax,[1 500]);
                    ylim(ax,[1000 3000]);
                    zlim(ax,[1 30]);
                    view(ax,[45 45]);
                    
                end
                
                try
                    
                if multiStopConditions == 6
                    f2 = figure('Name','ToF 2');
                    ax2 = axes(f2);
                    ToF2OnlySecond = plot(ax2,xdataToF,ToF2);
                    ToF2OnlySecond.XDataSource = 'xdataToF';
                    ToF2OnlySecond.YDataSource = 'ToF2';
                    
                elseif multiStopConditions == 7
                    f2 = figure('Name','ToF 2');
                    ax2 = axes(f2);
                    ToF2OnlySecond = plot(ax2,xdataToF,ToF2);
                    ToF2OnlySecond.XDataSource = 'xdataToF';
                    ToF2OnlySecond.YDataSource = 'ToF2';
                    
                end
                
                catch
                    errordlg('Check multi-stop conditions.','Multi-Stop Error');
                    app.STARTButton_2.BackgroundColor = [0 1 0];
                    app.STARTButton_2.Text = 'START';
                    app.STARTButton_2.Value = 0;
                    app.ModeLamp.Color = [1 0 0];
                    drawnow;
                    return;
                    
                end
                
            elseif event == 2
                if app.DPlotSwitch.Value == '1'
                    f = figure;
                    ax = axes(f);
                    AugerGammaPlotting = surf(ax,gammaVsToF);
                    linkdata on;
                    shading interp;
                    xlim(ax,[1 500]);
                    ylim(ax,[1000 3000]);
                    zlim(ax,[1 30]);
                    view(ax,[45 45]);
                    
                end
                
            end
            %AugerGammaPlotting.ZDataSource = 'gammaVsToF';
            %AugerGammaPlotting.YDataSource = 'xdataToF';
            %AugerGammaPlotting.XDataSource = 'xdataGe';
            %linkdata on
            %{
                linkdata(app.Plot1);
                linkdata(app.Plot2);
                linkdata(app.AugerGammaPlot);
            %}
            
        end
        
        % ////////////////////////////////////////////////////////////////////////////
        % ----------------------------------------------------------------------------
        % Analysis function.
        % ----------------------------------------------------------------------------
        % ////////////////////////////////////////////////////////////////////////////
      
        function analysis(app)
            %try
                tic
                
                % Defining globals used between the functions of the app.
                global ToF;
                global ToF2;
                global ToF3;
                global gammaVsToF;
                global selectPath;
                global savePath;
                global xdataToF;
                global xdataEnergy;
                global Ge1;
                global Ge2;
                global Ge3;
                global Ge4;
                global xdataGe1;
                global xdataGe2;
                global xdataGe3;
                global xdataGe4;
                global GePlotting1;
                global GePlotting2;
                global GePlotting3;
                global GePlotting4;
                global ToFPlotting;
                global ax;
                global ax2;
                global ax3;
                global ax4;
                global ax5;
                global numBins;
                global analysisType;
                global peakStart;
                global initialSample;
                global multiStopConditions;
                global LPFCounter;
                global trapezoidalFilterCounter;
                global kdeSpectra;
                global timeOfFlightKde;
                global geOutMaxKde;
                global stableLength;
                global control;
                global gammaMaxChange;
                %global runThrough;
                global chType;
                global gammaVsGamma;
                global electronVsElectron;
                global electronVsElectronEnergy;
                global electronPlusElectron;
                global electronMinusElectron;
                global sumDiffMatrix;
                global selectedMultiFolders;
                global newPaths;
                global gToF;
                global totalCounts;
                global numFiles;
                global ELETParam;
                global neuron;
                global ParameterMatrix;
                global DiscriminationVector;
                global ClusteringNetwork;
                global ggc;
                global totalMultiToFs;
                
                numBinsLocal = numBins;
                app.STARTButton_2.BackgroundColor = [1 1 0];
                app.STARTButton_2.Text = 'STARTING...';
                app.ModeLamp.Color = [1 1 0];
                drawnow;
                'dsf'
                selectPath
                savePath
                assignin('base','newPaths',newPaths);
                %app.checkPaths(selectPath,savePath,2);
                
                % Assigning app inputs to variables so that they cannot be accidentally changed during analysis.
                app.TracesFolder.Value = selectPath;
                app.SavingFolder.Value = savePath;
                trapezoidalFilterCounter = zeros(1,4) + 1;
                LPFCounter = zeros(1,2) + 1;
                
                
                FFTInput(1,1) = app.FFTFilterCh1Upper.Value*10.^6;
                FFTInput(1,2) = app.FFTFilterCh1Lower.Value*10.^6;
                FFTInput(2,1) = app.FFTFilterCh2Upper.Value*10.^6;
                FFTInput(2,2) = app.FFTFilterCh2Lower.Value*10.^6;
                FFTInput(3,1) = app.FFTFilterCh3Upper.Value*10.^6;
                FFTInput(3,2) = app.FFTFilterCh3Lower.Value*10.^6;
                FFTInput(4,1) = app.FFTFilterCh4Upper.Value*10.^6;
                FFTInput(4,2) = app.FFTFilterCh4Lower.Value*10.^6;

                RTFUpperInput = app.RiseTimeFilterUpper.Value*10.^(-9);
                RTFLowerInput = app.RiseTimeFilterLower.Value*10.^(-9);

                shaperTopInput(1) = app.Ch1TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(1) = app.Ch1TrapezoidalFilterRiseFall.Value*10.^(-6);
                shaperTopInput(2) = app.Ch2TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(2) = app.Ch2TrapezoidalFilterRiseFall.Value*10.^(-6);
                shaperTopInput(3) = app.Ch3TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(3) = app.Ch3TrapezoidalFilterRiseFall.Value*10.^(-6);
                shaperTopInput(4) = app.Ch4TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(4) = app.Ch4TrapezoidalFilterRiseFall.Value*10.^(-6);
                
                %ch2Timing = str2double(app.Ch2DropDownTiming.Value);
                %ch2Shaping = str2double(app.Ch1Shaping.Value);
                
                gammaWindow(1) = app.Ch1GammaEditField.Value;
                gammaWindow(2) = app.Ch2GammaEditField.Value;
                gammaWindow(3) = app.Ch3GammaEditField.Value;
                gammaWindow(4) = app.Ch4GammaEditField.Value;
                
                try
                    gammaLower(1) = app.Ch1GammaEditField_Lower.Value;
                    gammaLower(2) = app.Ch2GammaEditField_Lower.Value;
                    gammaLower(3) = app.Ch3GammaEditField_Lower.Value;
                    gammaLower(4) = app.Ch4GammaEditField_Lower.Value;
                    
                catch
                    gammaLower(1) = 0;
                    gammaLower(2) = 0;
                    gammaLower(3) = 0;
                    gammaLower(4) = 0;
                    
                end
                
                stableSwitch(1) = app.Ch1GammaStabilizerSwitch.Value;
                stableSwitch(2) = app.Ch2GammaStabilizerSwitch.Value;
                stableSwitch(3) = app.Ch3GammaStabilizerSwitch.Value;
                stableSwitch(4) = app.Ch4GammaStabilizerSwitch.Value;
                
                multiStop = app.MultiStopSwitch.Value;
                BPFSwitch = app.FFTFilterSwitch.Value;
                
                gammaStableCh(1) = app.GammaStableCh.Value;
                gammaStableCh(2) = app.GammaStableCh_2.Value;
                gammaStableCh(3) = app.GammaStableCh_3.Value;
                gammaStableCh(4) = app.GammaStableCh_4.Value;
                
                chType(1) = str2double(app.Channel1Type.Value);
                chType(2) = str2double(app.Channel2Type.Value);
                chType(3) = str2double(app.Channel3Type.Value);
                chType(4) = str2double(app.Channel4Type.Value);
                timing(1) = str2double(app.Ch1Timing.Value);
                timing(2) = str2double(app.Ch2Timing.Value);
                timing(3) = str2double(app.Ch3Timing.Value);
                timing(4) = str2double(app.Ch4Timing.Value);
                shaping(1) = str2double(app.Ch1Shaping.Value);
                shaping(2) = str2double(app.Ch2Shaping.Value);
                shaping(3) = str2double(app.Ch3Shaping.Value);
                shaping(4) = str2double(app.Ch4Shaping.Value);
                
                saveStable(1) = app.Ch1SaveStabilizerControlSwitch.Value;
                saveStable(2) = app.Ch2SaveStabilizerControlSwitch.Value;
                saveStable(3) = app.Ch3SaveStabilizerControlSwitch.Value;
                saveStable(4) = app.Ch4SaveStabilizerControlSwitch.Value;
                
                setStable(1) = app.Ch1SetStableChSwitch.Value;
                setStable(2) = app.Ch2SetStableChSwitch.Value;
                setStable(3) = app.Ch3SetStableChSwitch.Value;
                setStable(4) = app.Ch4SetStableChSwitch.Value;
                
                coinCh(1) = str2double(app.CoinChDropDown.Value);
                coinCh(2) = str2double(app.CoinChDropDown2.Value);
                
                energyCoinCha = str2double(app.PEGSGammaChannelDropDown.Value);
                
                GGCoinCha(1) = str2double(app.GGCChannel_1.Value);
                GGCoinCha(2) = str2double(app.GGCChannel_2.Value);
                
                numBinsLocal(1) = app.BinsEditField1.Value;
                numBinsLocal(2) = app.BinsEditField2.Value;
                numBinsLocal(3) = app.BinsEditField3.Value;
                numBinsLocal(4) = app.BinsEditField4.Value;
                
                ELETUpper(1) = app.Ch1ELETUpper.Value;
                ELETLower(1) = app.Ch1ELETLower.Value;
                ELETUpper(2) = app.Ch2ELETUpper.Value;
                ELETLower(2) = app.Ch2ELETLower.Value;
                ELETUpper(3) = app.Ch3ELETUpper.Value;
                ELETLower(3) = app.Ch3ELETLower.Value;
                ELETUpper(4) = app.Ch4ELETUpper.Value;
                ELETLower(4) = app.Ch4ELETLower.Value;
                
                secondECutoff = app.SecondECutoff.Value;
                
                if exist('newPaths','var') == 1 && isempty(newPaths) == 0 && app.MultiFolderSwitch.Value == '1'
                    
                    selectPath = newPaths{1};
                    
                end
                
                app.TracesFolder.Value = selectPath;
                
                for g=1:4
                    geOutMax{g} = [];
                    
                end
                
                flightTime = [];
                kdeFlightTime = [];
                folderNumber = 1;
                geOutMaxKde = 0;
                timeOfFlightKde = 0;
                timeOfFlight5 = 0;
                gammaMax = 0;
                rejection = zeros(4,1);
                numberAnalyzed = zeros(4,1) + 1;
                dataFilter = cell(1,4);
                newTimes = zeros(5,1);
                filesMissingCounter = 0;
                totalCounts = 1;
                baselineControl = 0;
                newLength = 0;
                firstLoop = true;
                enterIf = false;
                baselineAverageResult = 0;
                numAnalyzedTotal = 0;
                totalMultiToFs = [];
                TFTopInputFast = 0;
                TFRFInputFast = 0;
                
                timingWindowLower = -0.25*10.^(-6);
                timingWindowUpper = str2double(app.TimingWindowsKnob.Value)*10.^(-6);
                
                if app.TimingWindowsKnob.Value == '0'
                    timingWindowLower = app.TimeWindowLower.Value*10.^(-6);
                    timingWindowUpper = app.TimeWindowUpper.Value*10.^(-6);
                    
                end
                
                %------------------ ANN -----------------------
                %if ggc == 1 || isempty(neuron) || exist('neuron','var') == 0
                   % neuron = 0;
                    
                %end
                %------------------ ANN -----------------------
                
                 
                %numGammaChannels = sum(chType(:) == 2) + sum(chType(:) == 3);
                
                %ch1Polarity = app.Channel1PolarityDropDown.Value;
                %ch2Polarity = app.Channel2PolarityDropDown.Value;
                
                for s=1:4
                    
                    if saveStable(s) ~= '1'
                        control(s) = 0;
                    end
                    
                    if setStable(s) == '1'
                        control(s) = gammaStableCh(s);
                        gammaMaxChange(s) = true;
                    else
                        gammaMaxChange(s) = false;
                    end
                    
                    % Default number of samples to fit for the stabilizer.
                    if isempty(stableLength) || exist('stableLength', 'var') == 0 || ...
                            numel(stableLength) ~= 4
                        
                        stableLength(s) = 5000;
                        
                    end
                    
                end
                
                stableLength
                'sadfasdfsa'
                % Default initial sample.
                if isempty(initialSample) == 1 || exist('initialSample','var') == 0
                    i = 1;
                else
                    i = initialSample;
                end
                
                if isempty(ELETParam) == 1 || exist('ELETParam','var') == 0 ...
                        || isempty(neuron) || neuron == 0
                    
                    %ELETParam = [7 9];
                    
                end
                assignin('base','numFiles',numFiles);
                if isempty(numFiles) || numFiles == 0 || numFiles > 1E9 || numFiles == 0
                    numFiles = 99999;%app.numSamplesT.Value;
                    
                end
                
                % Default analysis chType.
                if isempty(analysisType) == 1 || exist('analysisType','var') == 0
                    analysisType = 1;
                end
                
                for g=1:4
                    
                    % Default numbers of bins.
                    %{
                    if isempty(numBinsLocal) || exist('numBinsLocal','var') == 0 || ...
                            numel(numBinsLocal) ~= 4
                        numBinsLocal(g) = 4096;
                        
                    end
                    %}
                    % Default peak start variable for gamma stabilization.
                    if isempty(peakStart) == 1 || exist('peakStart','var') == 0 || ...
                            numel(peakStart) ~= 4
                        peakStart(g) = 200;
                    end
                    
                    stableGe{g} = [];
                    
                end
                
                % Default multi-stop conditions.
                if isempty(multiStopConditions) || exist('multiStopConditions','var') == 0
                    multiStopConditions = 4;
                end
                
                % Default KDE Spectra setting.
                if isempty(kdeSpectra) || exist('kdeSpectra','var') == 0
                    kdeSpectra = 0;
                end
                
                for c=1:4
                    if chType(c) == 1
                        ToFChannel = c;
                        break;
                        
                    else
                        ToFChannel = 5;
                        
                    end
                    
                end
                
                % Defining spectra vectors.
                if ToFChannel ~= 5
                    xdataToF = linspace(timingWindowLower,timingWindowUpper,numBinsLocal(ToFChannel))';
                    ToF = zeros(1,numBinsLocal(ToFChannel))';
                    ToF2 = zeros(1,numBinsLocal(ToFChannel))';
                    ToF3 = zeros(1,numBinsLocal(ToFChannel))';
                    
                end
                
                xdataGe1 = linspace(0,numBinsLocal(1)-1,numBinsLocal(1))';
                Ge1 = zeros(1,numBinsLocal(1))';
                
                xdataGe2 = linspace(0,numBinsLocal(2)-1,numBinsLocal(2))';
                Ge2 = zeros(1,numBinsLocal(2))';
                
                xdataGe3 = linspace(0,numBinsLocal(3)-1,numBinsLocal(3))';
                Ge3 = zeros(1,numBinsLocal(3))';
                
                xdataGe4 = linspace(0,numBinsLocal(4)-1,numBinsLocal(4))';
                Ge4 = zeros(1,numBinsLocal(4))';
                
                xdataToF = linspace(timingWindowLower,timingWindowUpper,numBinsLocal(1))';
                gToF = zeros(1,numBinsLocal(1))';
                
                if energyCoinCha ~= 5 && ToFChannel ~= 5 && multiStopConditions ~= 6
                    gammaVsToF = zeros(numBinsLocal(energyCoinCha),numBinsLocal(ToFChannel));
                    
                elseif energyCoinCha ~= 5 && ToFChannel ~= 5 && multiStopConditions == 6
                    gammaVsToF = zeros((numBinsLocal(energyCoinCha)),numBinsLocal(ToFChannel),numBinsLocal(ToFChannel),'uint16');
                    
                end
                
                if GGCoinCha(1) ~= 5 && GGCoinCha(2) ~= 5
                    gammaVsGamma = zeros(numBinsLocal(GGCoinCha(1)),numBinsLocal(GGCoinCha(2)));
                    
                end
                
                if app.ElectronCoincidenceSwitch.Value == '1'
                    xdataEnergy = linspace(0,700,numBinsLocal(ToFChannel))';
                    electronVsElectron = zeros(numBinsLocal(ToFChannel),numBinsLocal(ToFChannel));
                    electronVsElectronEnergy = zeros(numBinsLocal(ToFChannel),numBinsLocal(ToFChannel));
                    electronPlusElectron = zeros(1,numBinsLocal(ToFChannel))';
                    electronMinusElectron = zeros(1,numBinsLocal(ToFChannel))';
                    sumDiffMatrix = zeros(numBinsLocal(ToFChannel),numBinsLocal(ToFChannel));
                    evalin('base','ToF = [];');
                    
                end
                
                % Begin plots if switch is turned on.
                if app.PlotsSwitch.Value == '1'
                    app.plots(0);
                    
                end
        
                app.STARTButton_2.BackgroundColor = [1 0 0];
                app.STARTButton_2.Text = 'STOP/SAVE';
                app.ModeLamp.Color = [0 1 0];
                drawnow;
                
                % ////////////////////////////////////////////////////////////////////////////
                % ----------------------------------------------------------------------------
                % Primary Loop.
                % ----------------------------------------------------------------------------
                % ////////////////////////////////////////////////////////////////////////////
                
                while app.STARTButton_2.Value == 1 %&& i + 10 <= app.numSamples.Value
                    
                    if (GGCoinCha(1) == 5 && GGCoinCha(2) ~= 5) || ...
                            (GGCoinCha(1) ~= 5 && GGCoinCha(2) == 5)
                        
                        errordlg('Check Gamma-Gamma Coincidence channels.','Gamma-Gamma Coincidence Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                        
                    end
                    
                    if app.PEGSGammaChannelDropDown.Value ~= '5' && sum(chType(chType == 1)) == 0
                        errordlg('Check PEGS Gamma channel.','PEGS Coincidence Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                        
                    end
                    
                    if app.MultiStopSwitch.Value == '0' && app.ElectronCoincidenceSwitch.Value == '1'
                        errordlg('Check Multi-Stop or Electron Coincidence switches.','Multi-Stop Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                        
                    end
                    
                    if multiStopConditions ~= 6 && app.ElectronCoincidenceSwitch.Value == '1'
                        errordlg('Multi-Stop option must be 2 Only - Split Spectra to perform electron-electron coincidence.','Multi-Stop Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                        
                    end
                    
                    %try
                    %app = guidata(app);
                    %shaping

                    % Primary function.
                    VoutMax = 0;
                    timeOfFlight = 0;
                    assignin('base','ClusteringNetwork',ClusteringNetwork);
                    [VoutMax,timeOfFlight,errorOccurred,noFilesFound,dataFilter,baselineAverage] = ...
                        tripleCoincidence_GeTiming_Trapezoidal_Functional_Input(selectPath, ...
                        i,analysisType,multiStop,multiStopConditions,chType,timing,shaping,FFTInput, ...
                        coinCh,ggc,RTFLowerInput,RTFUpperInput,shaperTopInput,shaperRiseFallInput, ...
                        TFTopInputFast,TFRFInputFast,BPFSwitch,dataFilter,DiscriminationVector, ...
                        ParameterMatrix,ClusteringNetwork,neuron,ELETParam,ELETUpper,ELETLower);
                    %shaping(1)
                    %VoutMax
                    
                    if errorOccurred == true
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                        
                    end
                    
                    if noFilesFound == true
                        filesMissingCounter = filesMissingCounter + 1;
                        if filesMissingCounter <= 3
                            i = i + 10;
                            continue;
                            
                        else
                            filesMissingCounter = 0;
                            i = app.numSamples.Value + 1;
                            if folderNumber < numel(newPaths)
                                folderNumber = folderNumber + 1;
                                selectPath = newPaths{folderNumber}
                                i = initialSample;
                                continue;
                                
                            else
                                i = app.numSamples.Value;
                                app.saveAll();
                                app.STARTButton_2.BackgroundColor = [0 1 0];
                                app.STARTButton_2.Text = 'START';
                                app.STARTButton_2.Value = 0;
                                app.ModeLamp.Color = [1 0 0];
                                drawnow;
                                break;
                                
                            end
                        end
                    end
                    %'TIME OF FLIGHT'
                    %timeOfFlight
                    %gammaWindow
                    
                    % Histogram function.
                    kdeSpectra = 0;
                    %multiStopConditions
                    if ToFChannel ~= 5 && multiStopConditions ~= 6 && multiStopConditions ~= 7
                        
                        if kdeSpectra == 0
                            [hToF,~,~,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,...
                                timingWindowUpper,[],timeOfFlight,energyCoinCha,1,[],false,false,multiStopConditions,secondECutoff);
                            %'IN HERE'
                            ToF = ToF + hToF;
                            
                        else
                            kdeFlightTime = vertcat(kdeFlightTime,timeOfFlight');
                            
                            if floor((i-1)/1000) == ((i-1)/1000) && i ~= 1
                                [~,ToFKde,~,~] = kde(timeOfFlightKde,numBinsLocal(ToFChannel),timingWindowLower, ...
                                    timingWindowUpper);
                                
                                ToF = ToF + ToFKde;
                                
                            end
                        end
                        
                    % Conditions to split the spectra if the multi-stop conditions so imply.
                    elseif ToFChannel ~= 5 && multiStop == '1' &&  (multiStopConditions == 6 || multiStopConditions == 7)
                        
                        EPlusE = app.multiStopping(timeOfFlight,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper,numBinsLocal,energyCoinCha,multiStop,ToFChannel);
                        
                        %{
                        if app.ElectronCoincidenceSwitch.Value == '1'
                            timeOfFlight = num2cell(EPlusE);
                            
                        end
                        %}
                        
                    % Gamma-Gamma coincidence timing.
                    elseif ToFChannel == 5 && sum(timing(:) == 3) == 2
                        if app.PlotsSwitch.Value == '1'
                            [hToF,~,~,~,~] = histo(10,'0',numBinsLocal,1,gammaWindow,gammaLower,timingWindowLower, ...
                                timingWindowUpper,[],timeOfFlight,energyCoinCha,1,[],false,false,multiStopConditions,secondECutoff);
                            
                            gToF = gToF + hToF;
                            %{
                            try
                                plot(ax5,xdataToF,gToF);
                                
                            catch
                                f5 = figure('Name','Gamma-Gamma Time Difference');
                                ax5 = axes(f5);
                                plot(ax5,xdataToF,gToF);
                                
                            end
                            %}
                        end
                    end
                    
                    %chType(2)
                    %chType(2)
                    %shaping(2)
                    
                    % ------------------------------------------------------------------------------------
                    % Gamma spectrum stabilization.
                    % ------------------------------------------------------------------------------------
                    g = 1;
                    for g=1:4
                        
                        chType(g)
                        
                        if ((chType(g) == 2 || chType(g) == 3) && stableSwitch(g) == '1')
                            
                            flightTime = vertcat(flightTime,timeOfFlight');
                            assignin('base','flightTime',flightTime);
                            stableSwitch(g)
                            
                            if stableSwitch(g) == '1'
                                
                                % Piling up all VoutMax and timeOfFlight values.
                                geOutMax{g} = vertcat(geOutMax{g},VoutMax{g}');
                                
                                if firstLoop == true
                                    baselineControl = baselineAverage
                                    firstLoop = false;
                                    minStabilizer = stableLength(g);
                                    stableLengthOriginal(g) = stableLength(g);
                                    
                                end
                                i - newLength
                                if i - newLength <= 0
                                    newLength = 0;
                                    
                                end
                                
                                minStabilizer
                                mod((i-1)/2,2)
                                %baselineAverageResult = baselineAverageResult + baselineAverage;
                                %{
                                if mod((i-1)/2,2) == 0
                                    baselineAverage = baselineAverageResult/2;
                                    baselineAverageResult = 0;
                                    '2'
                                    
                                    baselineAverage
                                    baselineControl
                                %}
                                
                                
                                %if (baselineAverage < baselineControl - 0.2E-3 || baselineAverage > baselineControl + 0.2E-3) && ...
                                if       i - newLength >= minStabilizer && i >= 1
                                    stableLength(g) = i - newLength;
                                    newLength = i - 1;
                                    baselineControl = baselineAverage
                                    enterIf = true;
                                    'into'
                                    
                                end
                                
                                
                                %end
                                
                                if enterIf == true %floor((i-1)/stableLength(g)) == ((i-1)/stableLength(g)) && i ~= 1
                                    enterIf = false;
                                    % To allow stabilization with an initial sample number other than 1.
                                    if i - initialSample <= stableLength(g)
                                        newSampleNumber = i - initialSample;
                                        
                                    else
                                        newSampleNumber = stableLength(g);
                                        
                                    end
                                    
                                    if numel(geOutMax{g}) < newSampleNumber
                                        geOutMax{g} = vertcat(geOutMax{g},zeros(newSampleNumber-numel(geOutMax{g}),1));
                                        
                                    end
                                    
                                    % Ensuring the length of the flightTime vector is equal to the length of the gamma vector.
                                    if numel(flightTime) < newSampleNumber
                                        flightTime = vertcat(flightTime,zeros(newSampleNumber-numel(flightTime)+1,1));
                                        
                                    end
                                    
                                    if (i-1)/stableLength(g) == 1 && setStable(g) == '1'
                                        %gammaMax(g) = max(geOutMax{g}(:));
                                        %gammaWindow(g) = gammaMax(g);
                                        %control(g) = gammaStableCh(g);
                                        
                                    end
                                    
                                    % Stabilize and then send to the send to the histogram function.
                                    % ------------------------------------------------
                                    % If the peak appears to be shifted away from the control value, it's likely
                                    % that the peakStart value is too high.
                                    % ------------------------------------------------
                                    
                                    [stableGe{g},control(g)] = stable(peakStart(g),folderNumber,geOutMax{g}(:),control(g), ...
                                        numBinsLocal(g),gammaWindow(g));
                                    
                                    %[~,GeTemp,geMaxVsToF] = histo(newSampleNumber,multiStop,numBinsGeLocal(g),numBinsToFLocal,gammaWindow(g), ...
                                    %    timingWindow,stableGe(g),timeOfFlight2,energyCoinCha);
                                    
                                    if chType(1) == 2 || chType(1) == 3 && stableSwitch(1) == '1'
                                        [~,hGe,geMaxVsToF,~,~] = histo(newSampleNumber,multiStop,numBinsLocal,ToFChannel,gammaLower(1),gammaWindow(1),timingWindowLower, ...
                                            timingWindowUpper,stableGe{g}(:),flightTime,energyCoinCha,1,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                        
                                        Ge1 = Ge1 + hGe;
                                        % Removing edges of histogram.
                                        Ge1(1) = 0;
                                        Ge1(numBinsLocal(g)) = 0;
                                    end
                                    
                                    if chType(2) == 2 || chType(2) == 3 && stableSwitch(2) == '1'
                                        [~,hGe,geMaxVsToF,~,~] = histo(newSampleNumber,multiStop,numBinsLocal,ToFChannel,gammaWindow(2),gammaLower(2),timingWindowLower,timingWindowUpper, ...
                                            stableGe{g}(:),flightTime,energyCoinCha,2,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                        
                                        Ge2 = Ge2 + hGe;
                                        % Removing edges of histogram.
                                        Ge2(1) = 0;
                                        Ge2(numBinsLocal(g)) = 0;
                                    end
                                    
                                    if chType(3) == 2 || chType(3) == 3 && stableSwitch(3) == '1'
                                        [~,hGe,geMaxVsToF,~,~] = histo(newSampleNumber,multiStop,numBinsLocal,ToFChannel,gammaWindow(3),gammaLower(3),timingWindowLower,timingWindowUpper, ...
                                            stableGe{g}(:),flightTime,energyCoinCha,3,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                        
                                        Ge3 = Ge3 + hGe;
                                        % Removing edges of histogram.
                                        Ge3(1) = 0;
                                        Ge3(numBinsLocal(g)) = 0;
                                    end
                                    
                                    if chType(4) == 2 || chType(4) == 3 && stableSwitch(4) == '1'
                                        [~,hGe,geMaxVsToF,~,~] = histo(newSampleNumber,multiStop,numBinsLocal,ToFChannel,gammaWindow(4),gammaLower(4),timingWindowLower,timingWindowUpper, ...
                                            stableGe{g}(:),flightTime,energyCoinCha,4,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                        
                                        Ge4 = Ge4 + hGe;
                                        % Removing edges of histogram.
                                        Ge4(1) = 0;
                                        Ge4(numBinsLocal(g)) = 0;
                                        
                                    end
                                    
                                    if energyCoinCha ~= 5 && sum(chType(:) == 1) ~= 0
                                        gammaVsToF = gammaVsToF + geMaxVsToF;
                                        flightTime = [];
                                        assignin('base','gammaVsToF',gammaVsToF);
                                        
                                    end
                                    
                                    if GGCoinCha(1) ~= 5 && GGCoinCha(2) ~= 5
                                        
                                        [~,~,~,gVsg,~] = histo(newSampleNumber,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                                            stableGe,flightTime,energyCoinCha,1,GGCoinCha,true,false,multiStopConditions,secondECutoff);
                                        
                                        gammaVsGamma = gammaVsGamma + gVsg;
                                        
                                    end
                                    geOutMax{g} = [];
                                    
                                    if kdeSpectra == 1
                                        geOutMaxKde = vertcat(geOutMaxKde,stableGe(g));
                                    end
                                    
                                    enterIf = false;
                                    
                                end
                                
                            end
                            
                            % If gamma stabilization is turned off.
                            
                        elseif ((chType(g) == 2 || chType(g) == 3) && stableSwitch(g) == '0')
                            hGe = 0;
                            
                            if stableSwitch(stableSwitch == '1') >= 1
                                geOutMax{g} = vertcat(geOutMax{g},VoutMax{g}');
                                stableGe{g} = geOutMax{g};
                                
                            end
                            
                            if (chType(1) == 2 || chType(1) == 3) && shaping(1) ~= 8 && g == 1
                                [~,hGe,geMaxVsToF,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow(1),gammaLower(1),timingWindowLower,timingWindowUpper, ...
                                    VoutMax{1}(:),timeOfFlight,energyCoinCha,1,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                
                                Ge1 = Ge1 + hGe;
                                % Removing edges of histogram.
                                Ge1(1) = 0;
                                Ge1(numBinsLocal(1)) = 0;
                            end
                            
                            if (chType(2) == 2 || chType(2) == 3) && shaping(2) ~= 8 && g == 2
                                [~,hGe,geMaxVsToF,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow(2),gammaLower(2),timingWindowLower,timingWindowUpper, ...
                                    VoutMax{2}(:),timeOfFlight,energyCoinCha,2,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                
                                Ge2 = Ge2 + hGe;
                                % Removing edges of histogram.
                                Ge2(1) = 0;
                                Ge2(numBinsLocal(2)) = 0;
                            end
                            
                            if (chType(3) == 2 || chType(3) == 3) && shaping(3) ~= 8 && g ==3
                                [~,hGe,geMaxVsToF,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow(3),gammaLower(3),timingWindowLower,timingWindowUpper, ...
                                    VoutMax{3}(:),timeOfFlight,energyCoinCha,3,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                
                                Ge3 = Ge3 + hGe;
                                % Removing edges of histogram.
                                Ge3(1) = 0;
                                Ge3(numBinsLocal(3)) = 0;
                            end
                            
                            if (chType(4) == 2 || chType(4) == 3) && shaping(4) ~= 8 && g == 4
                                [~,hGe,geMaxVsToF,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow(4),gammaLower(4),timingWindowLower,timingWindowUpper, ...
                                    VoutMax{4}(:),timeOfFlight,energyCoinCha,4,GGCoinCha,false,false,multiStopConditions,secondECutoff);
                                
                                Ge4 = Ge4 + hGe;
                                % Removing edges of histogram.
                                Ge4(1) = 0;
                                Ge4(numBinsLocal(4)) = 0;
                            end
                            
                            if energyCoinCha ~= 5 && sum(chType(:) == 1) ~= 0 && sum(shaping(:) ~= 8) ~= 0
                                
                                if app.ElectronCoincidenceSwitch.Value == '1'
                                    [~,~,geMaxVsToF,~,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow(energyCoinCha),gammaLower(energyCoinCha),timingWindowLower,timingWindowUpper, ...
                                        VoutMax{energyCoinCha}(:),timeOfFlight,energyCoinCha,energyCoinCha,GGCoinCha,false,true,multiStopConditions,secondECutoff);
                                    
                                end
                                
                                gammaVsToF = gammaVsToF + geMaxVsToF;
                                gammaVsToF(1:10,1,1)
                                
                            end
                            GGCoinCha(1)
                            GGCoinCha(2)
                            sum(shaping(:) ~= 8)
                            stableSwitch(stableSwitch == '1')
                            
                            if GGCoinCha(1) ~= 5 && GGCoinCha(2) ~= 5 && sum(shaping(:) ~= 8) == 2 && sum(stableSwitch(:) == '1') == 0
                                [~,~,~,gVsg,~] = histo(10,multiStop,numBinsLocal,ToFChannel,gammaWindow,gammaLower,timingWindowLower,timingWindowUpper, ...
                                    VoutMax,timeOfFlight,energyCoinCha,1,GGCoinCha,true,false,multiStopConditions,secondECutoff);
                                
                                gammaVsGamma = gammaVsGamma + gVsg;
                                
                            end
                            
                        end
                    end
                    %plot(Ge1)
                    if kdeSpectra == 1
                        VoutMax2 = VoutMax';
                        timeOfFlight3 = timeOfFlight';
                        
                        if multiStop == '1'
                            %assignin('base','timeOfFlight3',timeOfFlight3);
                            for L=1:10 % This was 1:10
                                
                                for PS=1:numel(timeOfFlight3{L})
                                    timeOfFlight5 = vertcat(timeOfFlight5,timeOfFlight3{L}(PS));
                                    
                                end
                                
                            end
                            timeOfFlightKde = vertcat(timeOfFlightKde,timeOfFlight5);
                            
                        else
                            timeOfFlightKde = vertcat(timeOfFlightKde,timeOfFlight3);
                            
                        end
                        
                        if stableSwitch ~= '1'
                            geOutMaxKde = vertcat(geOutMaxKde,VoutMax2);
                            
                        end
                        
                    end
                    
                    % Removing edges of histogram.
                    if ToFChannel ~= 5
                        ToF(1,1) = 0;
                        ToF(numBinsLocal(ToFChannel,1)) = 0;
                        ToF2(1,1) = 0;
                        ToF2(numBinsLocal(ToFChannel,1)) = 0;
                        
                    end
                    
                    % Plot if the corresponding switches are turned on.
                    if app.PlotsSwitch.Value == '1'
                        
                        if chType(1) == 2 || chType(1) == 3
                            set(GePlotting1,'XData',xdataGe1,'YData',Ge1);
                        end
                        if chType(2) == 2 || chType(2) == 3
                            set(GePlotting2,'XData',xdataGe2,'YData',Ge2);
                        end
                        if chType(3) == 2 || chType(3) == 3
                            set(GePlotting3,'XData',xdataGe3,'YData',Ge3);
                        end
                        if chType(4) == 2 || chType(4) == 3
                            set(GePlotting4,'XData',xdataGe4,'YData',Ge4);
                        end
                        
                        if chType(1) == 1 || chType(2) == 1 || ...
                                chType(3) == 1 || chType(4) == 1
                            
                            %refreshdata(ToFPlotting,'caller');
                            set(ToFPlotting,'XData',xdataToF,'YData',ToF);
                            
                        end
                        
                    end
                    
                    if exist('ax','var') && app.DPlotSwitch.Value == '1'
                        refreshdata(ax,'caller');
                        
                    end
                    
                    % Rejection ratio calculations.
                    
                    for c=1:4
                        
                        if multiStop == '0' && chType(c) == 1
                            rejection(c) = rejection(c) + sum(isnan(timeOfFlight(:))); % + sum(timeOfFlight(:) == 0);
                            
                        elseif multiStop == '1' && chType(c) == 1
                            ToFValues = [timeOfFlight{:}];
                            
                            %{
                            findInCellTime = cellfun(@(x) find(isnan(x)),timeOfFlight,'Uni',0);
                            %findInCellTime2 = cellfun(@(x) find(x == 0),timeOfFlight,'Uni',0);
                            %}
                            'rejections';
                            rejection(c) =  -(rejection(c) + 10 - numel(ToFValues)); % + numel(findInCellTime2{:});
                            
                        elseif chType(c) == 2 || chType(c) == 3
                            rejection(c) = rejection(c) + sum(VoutMax{c}(:) == 0) + sum(VoutMax{c}(:) == 1);
                            
                        end
                        
                    end
                    
                    i = i + 10; % This was i + 10
                    totalCounts = totalCounts + 10;
                    
                    if i >= 50 && floor((i-1)/50) == ((i-1)/50)
                        app.RejectionField1.Value = (rejection(1)/totalCounts)*100;
                        app.RejectionField2.Value = (rejection(2)/totalCounts)*100;
                        app.RejectionField3.Value = (rejection(3)/totalCounts)*100;
                        app.RejectionField4.Value = (rejection(4)/totalCounts)*100;
                        
                    end
                    
                    elapsedTime = toc
                    i
                    
                    for t=2:5
                        newTimes(t-1) = newTimes(t);
                    end
                    
                    newTimes(5) = elapsedTime;
                    
                    % Percentage and rate gauge updates and pause for callbacks.
                    app.PercentageCompleteGauge.Value = ((totalCounts+initialSample)/numFiles)*100;
                    app.RateGauge.Value = 50/(elapsedTime-newTimes(1)); %(i-initialSample)/elapsedTime;
                    drawnow;
                    pause(0.00001);
                    
                    % If pause button is pushed.
                    while app.PAUSEButton.Value == 1
                        pause(1);
                        app.ModeLamp.Color = [0.65 0.65 0.65];
                        if app.PAUSEButton.Value == 0
                            app.ModeLamp.Color = [0 1 0];
                            
                        end
                        
                    end
                    
                    if exist('VoutMax','var') && exist('hGe','var')
                        clearvars('VoutMax','hGe');
                    end
                    
                    if exist('timeOfFlight','var') && exist('hToF','var')
                        clearvars('timeOfFlight','hToF');
                    end
                    
                    % If Multi-Folder switch is switched to On.
                    if i + 10 >= app.numSamples.Value
                        if app.MultiFolderSwitch.Value == '1'
                            
                            if selectedMultiFolders == false
                            
                            answer = questdlg(['Folder Complete: ',num2str(folderNumber), ...
                                '.',newline,'Select another folder?'],'Continue','Yes','No','No');
                            
                            % Handle response.
                            switch answer
                                case 'Yes'
                                    if kdeSpectra == 1
                                        app.kdeFunction();
                                    end
                                    
                                    app.saveAll();
                                    app.TracesFolderButtonPushed();
                                    app.InitialSampleMenuSelected();
                                    folderNumber = folderNumber + 1;
                                    i = 1;
                                    
                                case 'No'
                                    if kdeSpectra == 1
                                        app.kdeFunction();
                                    end
                                    
                                    app.saveAll();
                                    app.STARTButton_2.BackgroundColor = [0 1 0];
                                    app.STARTButton_2.Text = 'START';
                                    app.STARTButton_2.Value = 0;
                                    app.ModeLamp.Color = [1 0 0];
                                    drawnow;
                                    
                                case ''
                                    if kdeSpectra == 1
                                        app.kdeFunction();
                                    end
                                    
                                    app.saveAll();
                                    app.STARTButton_2.BackgroundColor = [0 1 0];
                                    app.STARTButton_2.Text = 'START';
                                    app.STARTButton_2.Value = 0;
                                    app.ModeLamp.Color = [1 0 0];
                                    drawnow;
                                    
                            end
                            
                            else
                                if folderNumber < numel(newPaths)
                                    app.saveAll();
                                    folderNumber = folderNumber + 1;
                                    selectPath = newPaths{folderNumber};
                                    app.TracesFolder.Value = selectPath;
                                    i = 1;
                                    LPFCounter = zeros(1,2) + 1;
                                    trapezoidalFilterCounter = zeros(1,4) + 1;
                                    
                                    if stableSwitch(g) == '1'
                                        newLength = 0;
                                        stableLength = stableLengthOriginal;
                                        
                                    end
                                    
                                else
                                    app.saveAll();
                                    app.STARTButton_2.BackgroundColor = [0 1 0];
                                    app.STARTButton_2.Text = 'START';
                                    app.STARTButton_2.Value = 0;
                                    app.ModeLamp.Color = [1 0 0];
                                    drawnow;
                                    msgbox('Analysis complete.','Complete');
                                    
                                end
                                
                            end
                            % Once all samples have been analyzed, save spectra and files.
                        else
                            if kdeSpectra == 1
                                app.kdeFunction();
                                
                            end
                            
                            app.saveAll();
                            app.STARTButton_2.BackgroundColor = [0 1 0];
                            app.STARTButton_2.Text = 'START';
                            app.STARTButton_2.Value = 0;
                            app.ModeLamp.Color = [1 0 0];
                            drawnow;
                            
                        end
                        
                    end
                    
                    %{
                    catch
                        errordlg('Unknown error. Try again or restart the program.','Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                    end
                    %}
                end
                %{
            catch
                errordlg('Unknown error. Try again or restart the program.','Error');
                app.STARTButton_2.BackgroundColor = [0 1 0];
                app.STARTButton_2.Text = 'START';
                app.STARTButton_2.Value = 0;
                app.ModeLamp.Color = [1 0 0];
                drawnow;
                %}
                
        end
        

        function saveAll(app)
            global ToF;
            global ToF2;
            global ToF3;
            global Ge1;
            global Ge2;
            global Ge3;
            global Ge4;
            global gammaVsToF;
            global savePath;
            global xdataToF;
            global xdataGe1;
            global xdataGe2;
            global xdataGe3;
            global xdataGe4;
            global xdataEnergy;
            global ax;
            global ax2;
            global ax3;
            global ax4;
            global ax5;
            global chType;
            global timing;
            global gammaVsGamma;
            global electronVsElectron;
            global electronVsElectronEnergy;
            global electronPlusElectron;
            global electronMinusElectron;
            global sumDiffMatrix;
            global multiStopConditions;
            global gToF;
            global totalCounts;
            global gammaWindow;
            global totalMultiToFs;
            
            savePath
            'SAVING?'
            
            [~,savePath] = app.checkPaths('',savePath,1);
            
            multiStopString = {'1 Only','2 Only','3 Only','All','> 1 Only','2 Only - Split Spectra','3 Only - Split Spectra'};
            
            if sum(chType(:) == 1) ~= 0 && app.PEGSGammaChannelDropDown.Value ~= '5'
                save([savePath,'\gammaVsToF.mat'], 'gammaVsToF','-v7.3');
                %save([savePath,'\gammaVsToF.txt'], 'gammaVsToF','-ascii','-double','-tabs');
                
            end
            
            if app.GGCChannel_1.Value ~= '5' && app.GGCChannel_2.Value ~= '5'
                save([savePath,'\gammaVsGamma.mat'], 'gammaVsGamma');
                %save([savePath,'\gammaVsGamma.txt'], 'gammaVsGamma','-ascii','-double','-tabs');
                
            end
            
            if app.MultiStopSwitch.Value == '1' && (multiStopConditions == 6 || multiStopConditions == 7)
                ToFMatrix2 = horzcat(xdataToF,ToF2);
                save([savePath,'\ToF2.txt'], 'ToFMatrix2','-ascii','-double','-tabs');
                %save([savePath,'\totalMultiToFs.mat'], 'totalMultiToFs');
                
                try
                    saveas(ax2,[savePath,'\ToF2.jpg']);
                    saveas(ax2,[savePath,'\ToF2.fig']);
                    
                catch
                end
                
                if multiStopConditions == 7
                    ToFMatrix3 = horzcat(xdataToF,ToF3);
                    save([savePath,'\ToF3.txt'], 'ToFMatrix3','-ascii','-double','-tabs');
                    
                    try
                        saveas(ax5,[savePath,'\ToF3.jpg']);
                        saveas(ax5,[savePath,'\ToF3.fig']);
                        
                    catch
                    end
                end
                
                if app.ElectronCoincidenceSwitch.Value == '1'
                    save([savePath,'\electronVsElectron.mat'], 'electronVsElectron');
                    save([savePath,'\electronVsElectronEnergy.mat'], 'electronVsElectronEnergy');
                    save([savePath,'\ESumVsEDiff.mat'], 'sumDiffMatrix');
                    
                    ToFMatrixP = horzcat(xdataToF,electronPlusElectron);
                    save([savePath,'\EPlusE.txt'], 'ToFMatrixP','-ascii','-double','-tabs');
                    ToFMatrixM = horzcat(xdataToF,electronMinusElectron);
                    save([savePath,'\EMinusE.txt'], 'ToFMatrixM','-ascii','-double','-tabs');
                    
                end
                
                save([savePath, '\totalMultiToFs.mat'], 'totalMultiToFs')
                
            end
            
            if sum(timing(:) == 3) == 2 && sum(chType(:) == 1) ~= 0
                    
                save([savePath,'\gToF.txt'], 'gToF','-ascii','-double','-tabs');
                
            end
            
            totalCounts
            items1 = app.Ch1Shaping.Items;
            itemsData1 = app.Ch1Shaping.ItemsData;
            itemsValue1 = app.Ch1Shaping.Value;
            itemsIndex1 = find(ismember(itemsData1, itemsValue1));
            
            items2 = app.Ch2Shaping.Items;
            itemsData2 = app.Ch2Shaping.ItemsData;
            itemsValue2 = app.Ch2Shaping.Value;
            itemsIndex2 = find(ismember(itemsData2, itemsValue2));
            
            items3 = app.Ch3Shaping.Items;
            itemsData3 = app.Ch3Shaping.ItemsData;
            itemsValue3 = app.Ch3Shaping.Value;
            itemsIndex3 = find(ismember(itemsData3, itemsValue3));
            
            items4 = app.Ch4Shaping.Items;
            itemsData4 = app.Ch4Shaping.ItemsData;
            itemsValue4 = app.Ch4Shaping.Value;
            itemsIndex4 = find(ismember(itemsData4, itemsValue4));
            str2num(app.Ch1GammaStabilizerSwitch.Value)
            
            info = ['# of Events Analyzed: ',num2str(totalCounts), ...
                newline, ...
                newline,'Total # of Events in Dataset: ',num2str(app.numSamplesT.Value), ...
                newline, ...
                newline,'Channel Types ', ...
                newline,'Ch. 1: ',app.Channel1Type.Items{str2num(app.Channel1Type.Value)}, ...
                newline,'Ch. 2: ',app.Channel2Type.Items{str2num(app.Channel2Type.Value)}, ...
                newline,'Ch. 3: ',app.Channel3Type.Items{str2num(app.Channel3Type.Value)}, ...
                newline,'Ch. 4: ',app.Channel4Type.Items{str2num(app.Channel4Type.Value)}, ...
                newline, ...
                newline,'Rejection Ratios ', ...
                newline,'Ch. 1: ',num2str(app.RejectionField1.Value), ...
                newline,'Ch. 2: ',num2str(app.RejectionField2.Value), ...
                newline,'Ch. 3: ',num2str(app.RejectionField3.Value), ...
                newline,'Ch. 4: ',num2str(app.RejectionField4.Value), ...
                newline, ...
                newline,'Band-Pass Filter: ',app.FFTFilterSwitch.Items{str2num(app.FFTFilterSwitch.Value) + 1} ...
                newline,'Ch. 1 Lower: ',num2str(app.FFTFilterCh1Lower.Value), ...
                newline,'Ch. 1 Upper: ',num2str(app.FFTFilterCh1Upper.Value), ...
                newline,'Ch. 2 Lower: ',num2str(app.FFTFilterCh2Lower.Value), ...
                newline,'Ch. 2 Upper: ',num2str(app.FFTFilterCh2Upper.Value), ...
                newline,'Ch. 3 Lower: ',num2str(app.FFTFilterCh3Lower.Value), ...
                newline,'Ch. 3 Upper: ',num2str(app.FFTFilterCh3Upper.Value), ...
                newline,'Ch. 4 Lower: ',num2str(app.FFTFilterCh4Lower.Value), ...
                newline,'Ch. 4 Upper: ',num2str(app.FFTFilterCh4Upper.Value), ...
                newline, ...
                newline,'Rise-Time Filter: ','Ch. ',app.ChannelDropDown.Value, ...
                newline,'Lower Cut-Off (ns): ',num2str(app.RiseTimeFilterLower.Value), ...
                newline,'Upper Cut-Off (ns): ',num2str(app.RiseTimeFilterUpper.Value), ...
                newline, ...
                newline,'Timing Window (micro s): ',num2str(app.TimingWindowsKnob.Value), ...
                newline, ...
                newline,'Multi-Stop: ',app.MultiStopSwitch.Items{str2num(app.MultiStopSwitch.Value) + 1}, ...
                newline, ...
                newline,'Multi-Stop Conditions (If On): ',multiStopString{multiStopConditions} ...
                newline, ...
                newline,'Timing Parameters ', ...
                newline,'Ch. 1 Lower: ',num2str(app.Ch1ELETLower.Value), ...
                newline,'Ch. 1 Upper: ',num2str(app.Ch1ELETUpper.Value), ...
                newline,'Ch. 2 Lower: ',num2str(app.Ch2ELETLower.Value), ...
                newline,'Ch. 2 Upper: ',num2str(app.Ch2ELETUpper.Value), ...
                newline,'Ch. 3 Lower: ',num2str(app.Ch3ELETLower.Value), ...
                newline,'Ch. 3 Upper: ',num2str(app.Ch3ELETUpper.Value), ...
                newline,'Ch. 4 Lower: ',num2str(app.Ch4ELETLower.Value), ...
                newline,'Ch. 4 Upper: ',num2str(app.Ch4ELETUpper.Value), ...
                newline, ...
                newline,'Gamma Windows ', ...
                newline,'Ch. 1: ',num2str(app.Ch1GammaEditField.Value), ...
                newline,'Ch. 2: ',num2str(app.Ch2GammaEditField.Value), ...
                newline,'Ch. 3: ',num2str(app.Ch3GammaEditField.Value), ...
                newline,'Ch. 4: ',num2str(app.Ch4GammaEditField.Value), ...
                newline, ...
                newline,'Gamma Shaping Type: ', ...
                newline,'Ch. 1: ',items1{itemsIndex1}, ...
                newline,'Ch. 2: ',items2{itemsIndex2}, ...
                newline,'Ch. 3: ',items3{itemsIndex3}, ...
                newline,'Ch. 4: ',items4{itemsIndex4}, ...
                newline, ...
                newline,'Shaping Constants ', ...
                newline, 'Ch. 1 Top: ',num2str(app.Ch1TrapezoidalFilterTop.Value), ...
                newline, 'Ch. 1 Rise/Fall: ',num2str(app.Ch1TrapezoidalFilterRiseFall.Value), ...
                newline, 'Ch. 2 Top: ',num2str(app.Ch2TrapezoidalFilterTop.Value), ...
                newline, 'Ch. 2 Rise/Fall: ',num2str(app.Ch2TrapezoidalFilterRiseFall.Value), ...
                newline, 'Ch. 3 Top: ',num2str(app.Ch3TrapezoidalFilterTop.Value), ...
                newline, 'Ch. 3 Rise/Fall: ',num2str(app.Ch3TrapezoidalFilterRiseFall.Value), ...
                newline, 'Ch. 4 Top: ',num2str(app.Ch4TrapezoidalFilterTop.Value), ...
                newline, 'Ch. 4 Rise/Fall: ',num2str(app.Ch4TrapezoidalFilterRiseFall.Value), ...
                newline, ...
                newline,'Gamma Stabilizer ', ...
                newline,'Ch. 1: ',app.Ch1GammaStabilizerSwitch.Items{str2num(app.Ch1GammaStabilizerSwitch.Value) + 1}, ...
                newline,'Ch. 2: ',app.Ch2GammaStabilizerSwitch.Items{str2num(app.Ch2GammaStabilizerSwitch.Value) + 1}, ...
                newline,'Ch. 3: ',app.Ch3GammaStabilizerSwitch.Items{str2num(app.Ch3GammaStabilizerSwitch.Value) + 1}, ...
                newline,'Ch. 4: ',app.Ch4GammaStabilizerSwitch.Items{str2num(app.Ch4GammaStabilizerSwitch.Value) + 1}, ...
                ];
            
            fid = fopen([savePath,'\info.txt'],'wt');
            fprintf(fid, info);
            fclose(fid);
            
            % Saving Ge figure (spectrum) as necessary.
            
            if chType(1) == 2 || chType(1) == 3
                
                if app.PlotsSwitch.Value == '1'
                    x = xdataGe1; %app.Plot2.XAxis.Parent.Children.XData;
                    y = Ge1; %app.Plot2.YAxis.Parent.Children.YData;
                    f2 = figure('Visible','off');
                    h2 = axes(f2);
                    newPlot2 = plot(x,y);
                    
                    h2.YLabel.String = app.Plot1.YLabel.String;
                    h2.YLabel.FontSize = app.Plot1.YLabel.FontSize;
                    h2.XLabel.String = app.Plot1.XLabel.String;
                    h2.XLabel.FontSize = app.Plot1.XLabel.FontSize;
                    h2.Title.String = app.Plot1.Title.String;
                    h2.Title.FontSize = app.Plot1.Title.FontSize;
                    
                    %h2.XLim = [0 max(x)];
                    %h2.YLim = [0 max(y)+1];
                    saveas(h2,[savePath,'\Ge1.jpg']);
                    saveas(h2,[savePath,'\Ge1.fig']);
                    delete(newPlot2)
                    delete(h2)
                    delete(f2)
                    
                end
                
                GeMatrix = horzcat(xdataGe1,Ge1);
                save([savePath,'\Ge1.txt'], 'GeMatrix','-ascii','-double','-tabs');
                
                smoothedGe = smooth(Ge1,25,'lowess');
                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                peakMaxIndex = peakMaxIndex + 10
                
                try
                    gaussFit = fit(xdataGe1(peakMaxIndex-40:peakMaxIndex+50), ...
                        Ge1(peakMaxIndex-40:peakMaxIndex+50),'gauss1');
                    
                    %plot(gaussFit,xdataGe1(peakMaxIndex-40:peakMaxIndex+50),Ge1(peakMaxIndex-40:peakMaxIndex+50));
                    
                    cValues = coeffvalues(gaussFit);
                    FWHM = (2*sqrt(log(2))*cValues(3))/sqrt(2);
                    
                    app.checkPaths('',savePath,1);
                    
                    save([savePath,'\Op_Ge1_',num2str(app.Ch2TrapezoidalFilterRiseFall.Value),'usRT_', ...
                        num2str(app.Ch2TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                        'Ge1','-ascii','-double','-tabs');
                    
                catch
                    
                end
                
            elseif chType(1) == 1
                if app.PlotsSwitch.Value == '1'
                    % Saving ToF figure (spectrum) as necessary.
                    x = xdataToF; %app.Plot1.XAxis.Parent.Children.XData;
                    y = ToF; %app.Plot1.YAxis.Parent.Children.YData;
                    f1 = figure('Visible','off');
                    h1 = axes(f1);
                    newPlot1 = plot(x,y);
                    
                    h1.YLabel.String = app.Plot1.YLabel.String;
                    h1.YLabel.FontSize = app.Plot1.YLabel.FontSize;
                    h1.XLabel.String = app.Plot1.XLabel.String;
                    h1.XLabel.FontSize = app.Plot1.XLabel.FontSize;
                    h1.Title.String = app.Plot1.Title.String;
                    h1.Title.FontSize = app.Plot1.Title.FontSize;
                    
                    %h1.XLim = [0 max(x)];
                    %h1.YLim = [0 max(y)+1];
                    saveas(h1,[savePath,'\ToF.jpg']);
                    saveas(h1,[savePath,'\ToF.fig']);
                    delete(newPlot1)
                    delete(h1)
                    delete(f1)
                    
                end
                
                ToFMatrix = horzcat(xdataToF,ToF);
                save([savePath,'\ToF.txt'], 'ToFMatrix','-ascii','-double','-tabs');
                
            end
            
            if chType(2) == 2 || chType(2) == 3
                if app.PlotsSwitch.Value == '1'
                    x = xdataGe2; %app.Plot2.XAxis.Parent.Children.XData;
                    y = Ge2; %app.Plot2.YAxis.Parent.Children.YData;
                    f2 = figure('Visible','off');
                    h2 = axes(f2);
                    newPlot2 = plot(x,y);
                    
                    h2.YLabel.String = app.Plot2.YLabel.String;
                    h2.YLabel.FontSize = app.Plot2.YLabel.FontSize;
                    h2.XLabel.String = app.Plot2.XLabel.String;
                    h2.XLabel.FontSize = app.Plot2.XLabel.FontSize;
                    h2.Title.String = app.Plot2.Title.String;
                    h2.Title.FontSize = app.Plot2.Title.FontSize;
                    
                    %h2.XLim = [0 max(x)];
                    %h2.YLim = [0 max(y)+1];
                    saveas(h2,[savePath,'\Ge2.jpg']);
                    saveas(h2,[savePath,'\Ge2.fig']);
                    delete(newPlot2)
                    delete(h2)
                    delete(f2)
                    
                end
                
                GeMatrix = horzcat(xdataGe2,Ge2);
                save([savePath,'\Ge2.txt'], 'GeMatrix','-ascii','-double','-tabs');
                
                smoothedGe = smooth(Ge2,25,'lowess');
                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                peakMaxIndex = peakMaxIndex + 10
                
                try
                    gaussFit = fit(xdataGe2(peakMaxIndex-40:peakMaxIndex+50), ...
                        Ge2(peakMaxIndex-40:peakMaxIndex+50),'gauss1');
                    
                    %plot(gaussFit,xdataGe2(peakMaxIndex-40:peakMaxIndex+50),Ge2(peakMaxIndex-40:peakMaxIndex+50));
                    
                    cValues = coeffvalues(gaussFit);
                    FWHM = (2*sqrt(log(2))*cValues(3))/sqrt(2);
                    
                    app.checkPaths('',savePath,1);
                    
                    save([savePath,'\Op_Ge2_',num2str(app.Ch2TrapezoidalFilterRiseFall.Value),'usRT_', ...
                        num2str(app.Ch2TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                        'Ge2','-ascii','-double','-tabs');
                    
                catch
                end
                
            elseif chType(2) == 1
                if app.PlotsSwitch.Value == '1'
                    % Saving ToF figure (spectrum) as necessary.
                    x = xdataToF; %app.Plot1.XAxis.Parent.Children.XData;
                    y = ToF; %app.Plot1.YAxis.Parent.Children.YData;
                    f1 = figure('Visible','off');
                    h1 = axes(f1);
                    newPlot1 = plot(x,y);
                    
                    h1.YLabel.String = app.Plot2.YLabel.String;
                    h1.YLabel.FontSize = app.Plot2.YLabel.FontSize;
                    h1.XLabel.String = app.Plot2.XLabel.String;
                    h1.XLabel.FontSize = app.Plot2.XLabel.FontSize;
                    h1.Title.String = app.Plot2.Title.String;
                    h1.Title.FontSize = app.Plot2.Title.FontSize;
                    
                    %h1.XLim = [0 max(x)];
                    %h1.YLim = [0 max(y)+1];
                    saveas(h1,[savePath,'\ToF.jpg']);
                    saveas(h1,[savePath,'\ToF.fig']);
                    delete(newPlot1)
                    delete(h1)
                    delete(f1)
                    
                end
                
                ToFMatrix = horzcat(xdataToF,ToF);
                save([savePath,'\ToF.txt'], 'ToFMatrix','-ascii','-double','-tabs');
            
            end
                
            if chType(3) == 2 || chType(3) == 3
                if app.PlotsSwitch.Value == '1'
                    x = xdataGe3; %app.Plot2.XAxis.Parent.Children.XData;
                    y = Ge3; %app.Plot2.YAxis.Parent.Children.YData;
                    f2 = figure('Visible','off');
                    h2 = axes(f2);
                    newPlot2 = plot(x,y);
                    
                    h2.YLabel.String = app.Plot3.YLabel.String;
                    h2.YLabel.FontSize = app.Plot3.YLabel.FontSize;
                    h2.XLabel.String = app.Plot3.XLabel.String;
                    h2.XLabel.FontSize = app.Plot3.XLabel.FontSize;
                    h2.Title.String = app.Plot3.Title.String;
                    h2.Title.FontSize = app.Plot3.Title.FontSize;
                    
                    %h2.XLim = [0 max(x)];
                    %h2.YLim = [0 max(y)+1];
                    saveas(h2,[savePath,'\Ge3.jpg']);
                    saveas(h2,[savePath,'\Ge3.fig']);
                    delete(newPlot2)
                    delete(h2)
                    delete(f2)
                    
                end
                
                GeMatrix = horzcat(xdataGe3,Ge3);
                save([savePath,'\Ge3.txt'], 'GeMatrix','-ascii','-double','-tabs');
                
                smoothedGe = smooth(Ge3,25,'lowess');
                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                peakMaxIndex = peakMaxIndex + 10
                
                try
                    gaussFit = fit(xdataGe3(peakMaxIndex-40:peakMaxIndex+50), ...
                        Ge3(peakMaxIndex-40:peakMaxIndex+50),'gauss1');
                    
                    plot(gaussFit,xdataGe3(peakMaxIndex-40:peakMaxIndex+50),Ge3(peakMaxIndex-40:peakMaxIndex+50));
                    
                    cValues = coeffvalues(gaussFit);
                    FWHM = (2*sqrt(log(2))*cValues(3))/sqrt(2);
                    
                    app.checkPaths('',savePath,1);
                    
                    save([savePath,'\Op_Ge3_',num2str(app.Ch2TrapezoidalFilterRiseFall.Value),'usRT_', ...
                        num2str(app.Ch2TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                        'Ge3','-ascii','-double','-tabs');
                    
                catch
                end
                
            elseif chType(3) == 1
                if app.PlotsSwitch.Value == '1'
                    % Saving ToF figure (spectrum) as necessary.
                    x = xdataToF; %app.Plot1.XAxis.Parent.Children.XData;
                    y = ToF; %app.Plot1.YAxis.Parent.Children.YData;
                    f1 = figure('Visible','off');
                    h1 = axes(f1);
                    newPlot1 = plot(x,y);
                    
                    h1.YLabel.String = app.Plot3.YLabel.String;
                    h1.YLabel.FontSize = app.Plot3.YLabel.FontSize;
                    h1.XLabel.String = app.Plot3.XLabel.String;
                    h1.XLabel.FontSize = app.Plot3.XLabel.FontSize;
                    h1.Title.String = app.Plot3.Title.String;
                    h1.Title.FontSize = app.Plot3.Title.FontSize;
                    
                    %h1.XLim = [0 max(x)];
                    %h1.YLim = [0 max(y)+1];
                    saveas(h1,[savePath,'\ToF.jpg']);
                    saveas(h1,[savePath,'\ToF.fig']);
                    delete(newPlot1)
                    delete(h1)
                    delete(f1)
                    
                end
                
                ToFMatrix = horzcat(xdataToF,ToF);
                save([savePath,'\ToF.txt'], 'ToFMatrix','-ascii','-double','-tabs');
            
            end
                
            if chType(4) == 2 || chType(4) == 3
                if app.PlotsSwitch.Value == '1'
                    x = xdataGe4; %app.Plot2.XAxis.Parent.Children.XData;
                    y = Ge4; %app.Plot2.YAxis.Parent.Children.YData;
                    f2 = figure('Visible','off');
                    h2 = axes(f2);
                    newPlot2 = plot(x,y);
                    
                    h2.YLabel.String = app.Plot4.YLabel.String;
                    h2.YLabel.FontSize = app.Plot4.YLabel.FontSize;
                    h2.XLabel.String = app.Plot4.XLabel.String;
                    h2.XLabel.FontSize = app.Plot4.XLabel.FontSize;
                    h2.Title.String = app.Plot4.Title.String;
                    h2.Title.FontSize = app.Plot4.Title.FontSize;
                    
                    %h2.XLim = [0 max(x)];
                    %h2.YLim = [0 max(y)+1];
                    saveas(h2,[savePath,'\Ge4.jpg']);
                    saveas(h2,[savePath,'\Ge4.fig']);
                    delete(newPlot2)
                    delete(h2)
                    delete(f2)
                    
                end
                
                GeMatrix = horzcat(xdataGe4,Ge4);
                save([savePath,'\Ge4.txt'], 'GeMatrix','-ascii','-double','-tabs');
                
                smoothedGe = smooth(Ge4,25,'lowess');
                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                peakMaxIndex = peakMaxIndex + 10
                
                try
                    gaussFit = fit(xdataGe4(peakMaxIndex-40:peakMaxIndex+50), ...
                        Ge4(peakMaxIndex-40:peakMaxIndex+50),'gauss1');
                    
                    plot(gaussFit,xdataGe4(peakMaxIndex-40:peakMaxIndex+50),Ge4(peakMaxIndex-40:peakMaxIndex+50));
                    
                    cValues = coeffvalues(gaussFit);
                    FWHM = (2*sqrt(log(2))*cValues(3))/sqrt(2);
                    
                    app.checkPaths('',savePath,1);
                    
                    save([savePath,'\Op_Ge4_',num2str(app.Ch2TrapezoidalFilterRiseFall.Value),'usRT_', ...
                        num2str(app.Ch2TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                        'Ge4','-ascii','-double','-tabs');
                    
                catch
                end
                
            elseif chType(4) == 1
                if app.PlotsSwitch.Value == '1'
                    % Saving ToF figure (spectrum) as necessary.
                    x = xdataToF; %app.Plot1.XAxis.Parent.Children.XData;
                    y = ToF; %app.Plot1.YAxis.Parent.Children.YData;
                    f1 = figure('Visible','off');
                    h1 = axes(f1);
                    newPlot1 = plot(x,y);
                    
                    h1.YLabel.String = app.Plot4.YLabel.String;
                    h1.YLabel.FontSize = app.Plot4.YLabel.FontSize;
                    h1.XLabel.String = app.Plot4.XLabel.String;
                    h1.XLabel.FontSize = app.Plot4.XLabel.FontSize;
                    h1.Title.String = app.Plot4.Title.String;
                    h1.Title.FontSize = app.Plot4.Title.FontSize;
                    
                    %h1.XLim = [0 max(x)];
                    %h1.YLim = [0 max(y)+1];
                    saveas(h1,[savePath,'\ToF.jpg']);
                    saveas(h1,[savePath,'\ToF.fig']);
                    delete(newPlot1)
                    delete(h1)
                    delete(f1)
                    
                end
                
                ToFMatrix = horzcat(xdataToF,ToF);
                save([savePath,'\ToF.txt'], 'ToFMatrix','-ascii','-double','-tabs');
                
            end
        
            if exist('ax') && app.DPlotSwitch.Value == '1' && app.PEGSGammaChannelDropDown.Value ~= 5 ...
                    && sum(chType(:) == 1) ~= 0
                saveas(ax,[savePath,'\gammaVsToF.fig']);
                
            end
            
        end
        
        % Function for checking the existence of directories.
        function [selectPath,savePath] = checkPaths(app,selectPath,savePath,pathNumber)
            % -------------------------------------------------------------
            % PathNumber:
            % 0 = selectPath only.
            % 1 = savePath only.
            % 2 = both.
            % -------------------------------------------------------------
           
            selectPath
            
            if pathNumber ~= 1
                if isempty(selectPath) || isnumeric(selectPath) == 1 || exist(selectPath,'dir') == 0
                    
                    errordlg('Select a folder containing traces.');
                    selectPath = uigetdir('','Select a folder containing traces.');
                    selectPath2 = selectPath;
                    selectPathOriginal = app.TracesFolder.Value;
                    
                    if isempty(selectPath) || isnumeric(selectPath) == 1
                        if isnumeric(selectPath2) == 1 && isempty(selectPathOriginal)
                            while isnumeric(selectPath2) == 1
                                errordlg('No current traces path. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        elseif isnumeric(selectPath) == 1 && isempty(selectPathOriginal) == 0
                            selectPath = selectPathOriginal;
                            
                        elseif exist(selectPath2,'dir') == 0
                            while exist(selectPath2,'dir') == 0
                                errordlg('Folder does not exist. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        end
                        
                    else
                        app.TracesFolder.Value = selectPath;
                    end
                end
            end
            
            if pathNumber ~= 0
                if isempty(savePath) || isnumeric(savePath) == 1 || exist(savePath,'dir') == 0
                    savePathOriginal = app.SavingFolder.Value;
                    
                    errordlg('Select a folder in which to save the histogram.');
                    savePath = uigetdir('','Select a folder in which to save the histogram.');
                    app.SavingFolder.Value = savePath;
                    
                    savePath2 = savePath;
                    
                    % Checking if a folder in which to save the spectra and data has been selected.
                    if isempty(savePathOriginal) || isnumeric(savePath) == 1
                        if isnumeric(savePath2) == 1 && isempty(savePathOriginal)
                            while isnumeric(savePath2) == 1
                                errordlg('No current path. Select a folder in which to save the spectra.');
                                savePath2 = uigetdir('','Select a folder in which to save the spectra');
                            end
                            savePath = savePath2;
                            app.SavingFolder.Value = savePath;
                            
                        elseif isnumeric(savePath2) == 1 && isempty(savePathOriginal) == 0
                            savePath = savePathOriginal;
                            
                        elseif exist(savePath2,'dir') == 0
                            while exist(savePath2,'dir') == 0
                                errordlg('Folder does not exist. Select a folder in which to save the spectra.');
                                savePath2 = uigetdir('','Select a folder in which to save the spectra');
                            end
                            savePath = savePath2;
                            app.SavingFolder.Value = savePath;
                            
                        end
                        
                    else
                        app.SavingFolder.Value = savePath;
                    end
                end
            end
        end
        
        function results4 = kdeFunction(app,i)    
            global timeOfFlightKde;
            global geOutMaxKde;
            global numBins;
            global numBinsToF;
            global xdataToF;
            global xdataGe;
            global ToFKde;
            global GeKde;
            global savePath;
            %{
            timingWindowTemp = str2double(app.TimingWindowsKnob.Value);
            timingWindow = timingWindowTemp*10.^(-6);
            gammaWindow = app.Ch1GammaEditField.Value;
            [~,ToFKde,~,~] = kde(timeOfFlightKde,numBinsToF,-250*10.^(-9), ...
                timingWindow);
            ToFKdeMatrix = horzcat(xdataToF,ToFKde);
            save([savePath,'\ToFKDE.txt'], 'ToFKdeMatrix','-ascii','-double','-tabs');
            [~,GeKde,~,~] = kde(geOutMaxKde,numBinsGe,0, ...
                gammaWindow);
            GeKdeMatrix = horzcat(xdataGe,GeKde);
            save([savePath,'\GeKDE.txt'], 'GeKdeMatrix','-ascii','-double','-tabs');
            %}
        end
    
    % -----------------------------------------------------------------------------------------------------------------------------
    % Shaping Buttons Function
    % -----------------------------------------------------------------------------------------------------------------------------
    
    
    function shapingResults = shapingButtons(app,channel)
        
        % Function to plot a trapezoidal-shaped pulse in the app window.
        
        global selectPath;
        global trapezoidalFilterCounter;
        
        switch channel
            case 1
                shapingTypeIn = str2double(app.Ch1Shaping.Value);
                FFTInput(1,1) = app.FFTFilterCh1Upper.Value*10.^6;
                FFTInput(1,2) = app.FFTFilterCh1Lower.Value*10.^6;
                shaperTopInput(1) = app.Ch1TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(1) = app.Ch1TrapezoidalFilterRiseFall.Value*10.^(-6);
                
            case 2
                shapingTypeIn = str2double(app.Ch2Shaping.Value);
                FFTInput(2,1) = app.FFTFilterCh2Upper.Value*10.^6;
                FFTInput(2,2) = app.FFTFilterCh2Lower.Value*10.^6;
                shaperTopInput(2) = app.Ch2TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(2) = app.Ch2TrapezoidalFilterRiseFall.Value*10.^(-6);
                
            case 3
                shapingTypeIn = str2double(app.Ch3Shaping.Value);
                FFTInput(3,1) = app.FFTFilterCh3Upper.Value*10.^6;
                FFTInput(3,2) = app.FFTFilterCh3Lower.Value*10.^6;
                shaperTopInput(3) = app.Ch3TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(3) = app.Ch3TrapezoidalFilterRiseFall.Value*10.^(-6);
                
            case 4
                shapingTypeIn = str2double(app.Ch4Shaping.Value);
                FFTInput(4,1) = app.FFTFilterCh4Upper.Value*10.^6;
                FFTInput(4,2) = app.FFTFilterCh4Lower.Value*10.^6;
                shaperTopInput(4) = app.Ch4TrapezoidalFilterTop.Value*10.^(-6);
                shaperRiseFallInput(4) = app.Ch4TrapezoidalFilterRiseFall.Value*10.^(-6);
                
        end
        
        
        if isempty(trapezoidalFilterCounter) || isnumeric(trapezoidalFilterCounter) == 0
            trapezoidalFilterCounter(channel) = 1;
        end
        
        lo = dir([selectPath,'\*00001.trc']);
        k = strfind(lo(1).name,'Trace');
        
        if isempty(k)
            channelNumber = ['C',num2str(channel),'1'];
            i = trapezoidalFilterCounter(channel);
            
            if i < 10
                appendage = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
        end
        
        if ~isempty(k)
            channelNumber = ['C',num2str(channel),'Trace'];
            i = trapezoidalFilterCounter(channel);
            
            if i < 10
                appendage = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
        end
        
        if (false)
            channelNumber{c} = ['C',stringconversion(c),'XX'];
            i = trapezoidalFilterCounter(channel);
            
            if i < 10
                appendage = ['000000000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['00000000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['0000000',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['000000',stringconversion(i)];
            elseif i >= 10000 && i < 100000
                appendage = ['00000',stringconversion(i)];
            elseif i >= 100000 && i < 1000000
                appendage = ['0000',stringconversion(i)];
            elseif i >= 1000000 && i < 10000000
                appendage = ['000',stringconversion(i)];
            elseif i >= 10000000 && i < 100000000
                appendage = ['00',stringconversion(i)];
            elseif i >= 10000000 && i < 100000000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
        end
        
        %try
        fileName = [selectPath,'\',channelNumber,appendage,'.trc'];
        pulse{channel}(trapezoidalFilterCounter(channel)) = waveform.ReadLeCroyBinaryWaveform(fileName);
        oldPulse = pulse{channel}(trapezoidalFilterCounter(channel)).y;
        
        if app.FFTFilterSwitch.Value == '1'
           [pulse{channel}(trapezoidalFilterCounter(channel)).y,~] = bpfilter(pulse{channel}(trapezoidalFilterCounter(channel)), ...
               channel,FFTInput,[]);
            
            %pulse{channel}(trapezoidalFilterCounter(channel)).y = pulse{channel}(trapezoidalFilterCounter(channel)).y - ...
             %   mean(pulse{channel}(trapezoidalFilterCounter(channel)).y(100:800));
            %{
        else
            T = pulse{channel}(trapezoidalFilterCounter(channel)).desc.Ts;
            offset = -pulse{channel}(trapezoidalFilterCounter(channel)).info.OFFSET;
            pulse{channel}(trapezoidalFilterCounter(channel)).y = pulse{channel}(trapezoidalFilterCounter(channel)).y - ...
                mean(pulse{channel}(trapezoidalFilterCounter(channel)).y(round((offset-3E-6)/T):round((offset-1E-6)/T)));
             %}
            
        end
        
        
            [~,Vout] = trapezoidalFilter(pulse{channel}(trapezoidalFilterCounter(channel)), ...
                oldPulse, ...
                shapingTypeIn,shaperRiseFallInput(channel),shaperTopInput(channel),0);
        
        switch channel
            case 1
                plot(app.TrapezoidalFilterPlot,Vout);
                
            case 2
                plot(app.TrapezoidalFilterPlot_2,Vout);
                
            case 3
                plot(app.TrapezoidalFilterPlot_3,Vout);
                
            case 4
                plot(app.TrapezoidalFilterPlot_4,Vout);
                
        end
        
        trapezoidalFilterCounter(channel)
        Vout = Vout';
        
        %assignin('base','unshaped',pulse{2}(trapezoidalFilterCounter).y);
        %assignin('base','shaped',Vout);
        %save([savePath,'\trapezoid',num2str(trapezoidalFilterCounter),'.txt'], 'Vout','-ascii','-double','-tabs');
        
        %catch
        %   errordlg('No file.');
        
        %end
        
    end
    
    end

    methods (Access = public)
    
        function out = stopped(app)
            out = app.STARTButton_2.Value;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Startup function to initialize variables that should reset.
            
            global LPFCounter;
            global trapezoidalFilterCounter;
            global initialSample;
            global kdeSpectra;
            global control;
            global gammaMaxChange;
            global numBins;
            global multiStopConditions;
            global ParameterMatrix;
            global DiscriminationVector;
            global ClusteringNetwork;
            
            LPFCounter = zeros(1,2) + 1;
            trapezoidalFilterCounter = zeros(1,4) + 1;
            initialSample = 1;
            kdeSpectra = 0;
            control = 0;
            gammaMaxChange = false;
            numBins = zeros(4,1) + 4096;
            multiStopConditions = 4;
            neuron = 0;
            
            homePath = pwd;
            
            ClusteringNetwork = load([homePath,'\SOMClusteringNetwork.mat']);
            a = fieldnames(ClusteringNetwork);
            ClusteringNetwork = getfield(ClusteringNetwork,a{1});
            ClusteringNetwork = network(ClusteringNetwork);
            
            DiscriminationVector = load([homePath,'\DiscriminationVector.mat']);
            ParameterMatrix = load([homePath,'\ParameterMatrix.mat']);
            
            a = fieldnames(ParameterMatrix);
            ParameterMatrix = getfield(ParameterMatrix,a{1});

        end

        % Button pushed function: TracesFolderButton
        function TracesFolderButtonPushed(app, event)
            
            global selectPath;
            global LPFCounter;
            global trapezoidalFilterCounter;
            global initialSample;
            global gammaMaxChange;
            global selectedMultiFolders;
            global newPaths;
            global numFiles;
            
            selectedMultiFolders = false;
            selectPathOriginal = selectPath;
            newPaths = [];
            
            try
                selectPath = uigetdir;
                app.TracesFolder.Value = selectPath;
                
                % Resetting trapezoidal filter and band pass filter counters if a new folder is selected.
                LPFCounter = zeros(1,2) + 1;
                trapezoidalFilterCounter = zeros(1,4) + 1;
                initialSample = 1;
                gammaMaxChange = false;
                
            catch
                selectPath2 = selectPath;
                
                if isnumeric(selectPath)
                    [selectPath,~] = app.checkPaths(selectPathOriginal,'',0);
                else
                    [selectPath,~] = app.checkPaths(selectPath,'',0);
                end
                
            end
            
            numFiles = 0;
            fileList = dir([selectPath,'\*.trc']);
            numFilesT = length(fileList);
            numFiles = (numFiles + numFilesT)/2;
            
            app.numSamplesT.Value = numFiles;
        end

        % Value changed function: FFTFilterCh1Upper
        function FFTFilterCh1UpperValueChanged(app, event)
            FFTFilterCh1Input = app.FFTFilterCh1Upper.Value;
            
        end

        % Value changed function: FFTFilterCh2Upper
        function FFTFilterCh2UpperValueChanged(app, event)
            FFTFilterCh2Input = app.FFTFilterCh2Upper.Value;
            
        end

        % Value changed function: numSamples
        function numSamplesValueChanged(app, event)
            numSamplesInput = app.numSamples.Value;
            
        end

        % Value changed function: RiseTimeFilterUpper
        function RiseTimeFilterUpperValueChanged(app, event)
            riseTimeFilterUpperInput = app.RiseTimeFilterUpper.Value;
            
        end

        % Value changed function: RiseTimeFilterLower
        function RiseTimeFilterLowerValueChanged(app, event)
            riseTimeFilterLowerInput = app.RiseTimeFilterLower.Value;
            
        end

        % Value changed function: Ch1TrapezoidalFilterTop
        function Ch1TrapezoidalFilterTopValueChanged(app, event)
            trapezoidalFilterTopInput = app.Ch1TrapezoidalFilterTop.Value;
            
        end

        % Value changed function: Ch1TrapezoidalFilterRiseFall
        function Ch1TrapezoidalFilterRiseFallValueChanged(app, event)
            trapezoidalFilterRiseFallInput = app.Ch1TrapezoidalFilterRiseFall.Value;
            
        end

        % Callback function
        function STOPSAVEButtonPushed(app, event)
            
        end

        % Button pushed function: PlotLPFButton
        function PlotLPFButtonPushed(app, event)
            % Function to plot the band pass filtered pulses in the app window.
            
            global selectPath;
            global LPFCounter;
            
            FFTInput(1,1) = app.FFTFilterCh1Upper.Value*10.^6;
            FFTInput(1,2) = app.FFTFilterCh1Lower.Value*10.^6;
            FFTInput(2,1) = app.FFTFilterCh2Upper.Value*10.^6;
            FFTInput(2,2) = app.FFTFilterCh2Lower.Value*10.^6;
            FFTInput(3,1) = app.FFTFilterCh3Upper.Value*10.^6;
            FFTInput(3,2) = app.FFTFilterCh3Lower.Value*10.^6;
            FFTInput(4,1) = app.FFTFilterCh4Upper.Value*10.^6;
            FFTInput(4,2) = app.FFTFilterCh4Lower.Value*10.^6;
            
            if isempty(LPFCounter) || LPFCounter(1) < 1
                LPFCounter(1) = 1;
            end
            
            i = LPFCounter(1);
            
            lo = dir([selectPath,'\*00001.trc']);
            k = strfind(lo(1).name,'Trace');
                
            if isempty(k)
                channelNumber1 = 'C11';
                channelNumber2 = 'C21';
                
                if i < 10
                    appendage = ['0000',stringconversion(i)];
                elseif i >= 10 && i < 100
                    appendage = ['000',stringconversion(i)];
                elseif i >= 100 && i < 1000
                    appendage = ['00',stringconversion(i)];
                elseif i >= 1000 && i < 10000
                    appendage = ['0',stringconversion(i)];
                else
                    appendage = stringconversion(i);
                end
                
            end
            
            if ~isempty(k)
                channelNumber1 = 'C1Trace';
                channelNumber2 = 'C2Trace';
                
                if i < 10
                    appendage = ['0000',stringconversion(i)];
                elseif i >= 10 && i < 100
                    appendage = ['000',stringconversion(i)];
                elseif i >= 100 && i < 1000
                    appendage = ['00',stringconversion(i)];
                elseif i >= 1000 && i < 10000
                    appendage = ['0',stringconversion(i)];
                else
                    appendage = stringconversion(i);
                end
                
            end
            
            %try
            
            if app.Channel1Type.Value ~= '4'
                fileName2 = [selectPath,'\',channelNumber1,appendage,'.trc'];
                pulse{1}(i) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                if app.FFTFilterSwitch.Value == '1'
                    [pulse{1}(i).y,~] = bpfilter(pulse{1}(i),1,FFTInput,[]);
                    
                else
                    pulse{1}(i).y = pulse{1}(i).y - mean(pulse{1}(i).y(100:800));
                    
                end
                
                plot(app.FFTFilterPlot_2,pulse{1}(i).x,pulse{1}(i).y);
                
            end
            
            if app.Channel2Type.Value ~= '4'
                fileName2 = [selectPath,'\',channelNumber2,appendage,'.trc'];
                pulse{2}(i) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                if app.FFTFilterSwitch.Value == '1'
                    [pulse{2}(i).y,~] = bpfilter(pulse{2}(i),2,FFTInput,[]);
                    
                else
                    pulse{2}(i).y = pulse{2}(i).y - mean(pulse{2}(i).y(100:800));
                    
                end
                
                plot(app.FFTFilterPlot,pulse{2}(i).x,pulse{2}(i).y);
                
            end
            
            %catch
             %   errordlg('No file.');
                
            %end
            
            %assignin('base','pulse',pulse{2}(i).y);
            
        end

        % Button pushed function: TrapezoidalFilterButton
        function TrapezoidalFilterButtonPushed(app, event)
            shapingButtons(app,1);
            
        end

        % Button pushed function: SavingFolderButton
        function SavingFolderButtonPushed(app, event)
            global savePath;
            
            savePathOriginal = savePath;
            
            try
                savePath = uigetdir;
                app.SavingFolder.Value = savePath;
                
            catch
                savePath2 = savePath;
                
                % Checking if a folder in which to save the spectra and data has been selected.
                if isnumeric(savePath)
                    [~,savePath] = app.checkPaths('',savePathOriginal,1);
                else
                    [~,savePath] = app.checkPaths('',savePath,1);
                end
                
            end
        end

        % Value changed function: PAUSEButton
        function PAUSEButtonValueChanged(app, event)
            value = app.PAUSEButton.Value;
            
        end

        % Value changed function: PlotsSwitch
        function PlotsSwitchValueChanged(app, event)
            value = app.PlotsSwitch.Value;
            
            % Allowing plots to be turned on or off during analysis.
            if value == '1'
                app.plots(0);
            end
        end

        % Callback function
        function GammaWindowSliderValueChanging(app, event)
            changingValue = event.Value;
            % Setting gamma edit field value to be equal to the gamma window slider.
            app.Ch1GammaEditField.Value = changingValue;
            
        end

        % Value changed function: Ch1GammaEditField
        function Ch1GammaEditFieldValueChanged(app, event)
            value = app.Ch1GammaEditField.Value;
            
        end

        % Button pushed function: TrapezoidalFilterButtonNext
        function TrapezoidalFilterButtonNextPushed(app, event)
            %global selectPath;
            global trapezoidalFilterCounter;
            trapezoidalFilterCounter(1) = trapezoidalFilterCounter(1) + 1;
            shapingButtons(app,1);
            
        end

        % Button pushed function: PlotLPFButtonNext
        function PlotLPFButtonNextPushed(app, event)
            global LPFCounter;
            LPFCounter(1) = LPFCounter(1) + 1;
            PlotLPFButtonPushed(app);
        end

        % Callback function
        function SAVECURRENTButtonValueChanged(app, event)
            
        end

        % Button pushed function: SAVECURRENTButton
        function SAVECURRENTButtonPushed(app, event)
            originalLampColor = app.ModeLamp.Color;
            originalButtonColor = app.SAVECURRENTButton.BackgroundColor;
            app.ModeLamp.Color = [1 1 0];
            app.SAVECURRENTButton.BackgroundColor = [1 1 0];
            app.SAVECURRENTButton.Text = 'SAVING...';
            drawnow;
            
            app.saveAll();
            
            pause(1)
            app.ModeLamp.Color = originalLampColor;
            app.SAVECURRENTButton.BackgroundColor = originalButtonColor;
            app.SAVECURRENTButton.Text = 'SAVE CURRENT';
            drawnow;
        end

        % Value changed function: STARTButton_2
        function STARTButton_2ValueChanged(app, event)
            
            % Defining globals used between the functions of the app.
            global stopped
            global kdeSpectra
            
            % Begin analysis if start button has been pressed.
            if app.STARTButton_2.Value == 1
                stopped = 0;
                app.analysis();
                
                % Save spectra and files if stop button is pushed.
            elseif app.STARTButton_2.Value == 0
                stopped = 1;
                originalLamp = app.ModeLamp.Color;
                app.ModeLamp.Color = [1 1 0];
                app.STARTButton_2.BackgroundColor = [1 1 0];
                app.STARTButton_2.Text = 'SAVING...';
                drawnow;
                
                if kdeSpectra == 1
                    app.kdeFunction();
                end
                
                app.saveAll();
                
                app.ModeLamp.Color = [1 0 0];
                app.STARTButton_2.BackgroundColor = [0 1 0];
                app.STARTButton_2.Text = 'START';
                drawnow;
                
            end
            
        end

        % Value changed function: TracesFolder
        function TracesFolderValueChanged(app, event)
            global selectPath;
            value = app.TracesFolder.Value;
            selectPath = value;
            
            if exist(selectPath,'dir') == 0
                errordlg('Traces folder does not exist.');
                
            end
            
        end

        % Value changed function: SavingFolder
        function SavingFolderValueChanged(app, event)
            global savePath;
            value = app.SavingFolder.Value;
            savePath = value;
            
            if exist(savePath,'dir') == 0
                errordlg('Saving folder does not exist.');
                
            end
            
        end

        % Callback function
        function BinningMenuSelected(app, event)
            global numBins;
            
            prompt = {'Plot 1','Plot 2','Plot 3','Plot 4'};
            title = 'Number of Bins';
            dims = [1 20];
            definput = {'4096','4096','4096','4096'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result)
                numBins = zeros(4,1) + 4096;
                
            else
                for c=1:4
                numBins(c) = str2double(result{c});
                
                end
                
            end
        end

        % Menu selected function: RiseTimesMenu
        function RiseTimesMenuSelected(app, event)
            global selectPath;
            global savePath;
            
            [selectPath,savePath] = app.checkPaths(selectPath,savePath,2);
            
            prompt = {'Number of samples: ','Channel #: '};
            title = 'Ge Rise Time Histogram';
            dims = [1 20];
            definput = {'1000','2'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result) == 0
                
                numSamplesT = str2double(result{1});
                numChannel = str2double(result{2});
                
                if numChannel == 1 && app.Channel1Type.Value == '3'
                    FFTIn(numChannel,1) = app.FFTFilterCh1Upper.Value;
                    FFTIn(numChannel,2) = app.FFTFilterCh1Lower.Value;
                    
                elseif numChannel == 2 && app.Channel2Type.Value == '3'
                    FFTIn(numChannel,1) = app.FFTFilterCh2Upper.Value;
                    FFTIn(numChannel,2) = app.FFTFilterCh2Lower.Value;
                    
                elseif numChannel == 3 && app.Channel3Type.Value == '3'
                    FFTIn(numChannel,1) = app.FFTFilterCh3Upper.Value;
                    FFTIn(numChannel,2) = app.FFTFilterCh3Lower.Value;
                    
                elseif numChannel == 4 && app.Channel4Type.Value == '3'
                    FFTIn(numChannel,1) = app.FFTFilterCh4Upper.Value;
                    FFTIn(numChannel,2) = app.FFTFilterCh4Lower.Value;
                    
                else
                    errordlg('Channel does not contain unshaped gamma pulses.');
                    return;
                    
                end
                
                opts.Interpreter = 'none';
                opts.Default = 'No';
                answer = questdlg('Plot result?','Ge Rise Time Histogram','Yes','No',opts);
                
                app.ModeLamp.Color = [1 1 0];
                app.STARTButton_2.Enable = false;
                app.PAUSEButton.Enable = false;
                app.SAVECURRENTButton.Enable = false;
                drawnow;
                
                if app.FFTFilterSwitch.Value == '1'
                    BPF = 1;
                    
                else
                    BPF = 2;
                    
                end
                
                [binsRiseTime,riseTimeHistogram] = RiseTimeHistogram(numSamplesT,selectPath,FFTIn,numChannel,BPF);
                outputs = horzcat(binsRiseTime,riseTimeHistogram);
                
                save([savePath,'\riseTimeHistogram.txt'], 'outputs','-ascii','-double','-tabs');
                
                switch answer
                    
                    case 'Yes'
                        figure;
                        plot(binsRiseTime,riseTimeHistogram);
                        saveas(gcf,[savePath,'\Rise Time Histogram.jpg']);
                        
                    case 'No'
                        'Did not plot.'
                        
                end
                
                app.ModeLamp.Color = [1 0 0];
                app.STARTButton_2.Enable = true;
                app.PAUSEButton.Enable = true;
                app.SAVECURRENTButton.Enable = true;
                drawnow;
                
            end
            
        end

        % Value changed function: Ch1Shaping
        function Ch1ShapingValueChanged(app, event)
            value = str2double(app.Ch1Shaping.Value);
            
            if  value == 8
                app.TrapezoidalFilterButton.Enable = false;
                app.TrapezoidalFilterButtonNext.Enable = false;
                app.Ch1TrapezoidalFilterTop.Enable = false;
                app.Ch1TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel.Text = 'N/A';
                app.Ch1GammaStabilizerSwitch.Value = '0';
                app.Ch1GammaStabilizerSwitch.Enable = false;
                
            elseif  value == 8
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1TrapezoidalFilterTop.Enable = false;
                app.Ch1TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel.Text = 'N/A';
                app.Ch1GammaStabilizerSwitch.Enable = true;
                
            elseif value == 1 || value == 2 || value == 4 || value == 0
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1TrapezoidalFilterTop.Enable = true;
                app.RiseFallLabel.Text = 'Rise/Fall';
                app.Ch1GammaStabilizerSwitch.Enable = true;
                if value == 4
                    app.Ch1TrapezoidalFilterRiseFall.Enable = false;
                else
                    app.Ch1TrapezoidalFilterRiseFall.Enable = true;
                end
                
            elseif value == 3
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1TrapezoidalFilterTop.Enable = false;
                app.Ch1TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel.Text = 'Shp. Const.';
                app.Ch1GammaStabilizerSwitch.Enable = true;
                
            elseif value == 4
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1TrapezoidalFilterTop.Enable = true;
                app.Ch1TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel.Text = 'Rise/Fall';
                app.Ch1GammaStabilizerSwitch.Enable = true;
                
            elseif value == 7
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1TrapezoidalFilterTop.Enable = false;
                app.Ch1TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel.Text = 'N/A';
                app.Ch1GammaStabilizerSwitch.Enable = true;
                
            end
            
        end

        % Callback function
        function AnalysisTypeMenuSelected(app, event)
            global analysisType;
            
            % Gamma-Auger: 1
            % Time of Flight: 2
            % Gamma: 3
            
            [analysisType,tf] = listdlg('PromptString','chType of analysis: ',...
                           'SelectionMode','single',...
                           'ListString',{'Gamma-Auger Coincidence','Time of flight only','Gamma only'});
                       
        end

        % Menu selected function: PeakLocationMenu
        function PeakLocationMenuSelected(app, event)
            global peakStart;
            
            prompt = {'Plot 1: ','Plot 2: ','Plot 3: ','Plot 4: '};
            title = 'Peak Location Selection';
            dims = [1 20];
            definput = {'200','200','200','200'};
            answer = inputdlg(prompt,title,dims,definput);
            
            for c=1:4
                peakStart(c) = str2num(answer{c});
                
            end
            
        end

        % Menu selected function: CollectionTimeMenu
        function CollectionTimeMenuSelected(app, event)
            global selectPath;
            global initialSample;
            global chType;
            
            if isempty(initialSample)
                initialSample = 1;
            end
            
            if isempty(selectPath)
                app.checkPaths(selectPath,'',0);
            end
            
            numSamplesLocal = (app.numSamples.Value - 1);
            numCh = 0;
            
            a = dir([selectPath,'\*.trc']);
            
            for c=1:4
                if chType ~= 4
                    channelNumber(c) = ['C',num2str(c),'1'];
                    numCh = numCh + 1;
                    
                end
                
            end
            
            if initialSample < 10
                appendage1 = ['0000',stringconversion(initialSample)];
            elseif initialSample >= 10 && initialSample < 100
                appendage1 = ['000',stringconversion(initialSample)];
            elseif initialSample >= 100 && initialSample < 1000
                appendage1 = ['00',stringconversion(initialSample)];
            elseif initialSample >= 1000 && initialSample < 10000
                appendage1 = ['0',stringconversion(initialSample)];
            else
                appendage1 = stringconversion(initialSample);
            end

            
            i = numSamplesLocal*numCh - 2*numCh;
            
            if i < 10
                appendage2 = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage2 = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage2 = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage2 = ['0',stringconversion(i)];
            else
                appendage2 = stringconversion(i);
            end
            
            try
                fileName1 = [selectPath,'\',channelNumber(1),appendage1,'.trc']
                fileName2 = [selectPath,'\',channelNumber(numCh),appendage2,'.trc']
                pulse{1}(1) = waveform.ReadLeCroyBinaryWaveform(fileName1);
                pulse{1}(2) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                time1 = pulse{1}(1).info.TRIGGER_TIME;
                time2 = pulse{1}(2).info.TRIGGER_TIME;
                
                f = msgbox({['Collection began on: ',time1];newline;['Collection ended on: ',time2]},'Total Collection Time');
                
            catch
                errordlg('No file.');
                
            end
            
        end

        % Callback function
        function MultiStopMenuSelected(app, event)
 
        end

        % Menu selected function: InitialSampleMenu
        function InitialSampleMenuSelected(app, event)
            global initialSample;
            
            prompt = 'Initial sample number with which to begin analysis: ';
            title = 'Initial Sample';
            dims = [1 20];
            definput = {'1'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result) ~= 1
                beforeRounding = str2num(result{1});
                
                initialSample = round(beforeRounding,-1);
                initialSample = initialSample + 1;
                
            end
            
        end

        % Value changed function: DPlotSwitch
        function DPlotSwitchValueChanged(app, event)
            value = app.DPlotSwitch.Value;
            
            if value == '1'
                    app.plots(0);
                    
            end
                    
        end

        % Menu selected function: EnergyCalibrationMenu
        function EnergyCalibrationMenuSelected(app, event)
            global selectPath;
            global savePath;
            global multiStopConditions;
            
            noFolder = false;
            savePathOriginal = savePath;
            
            try
                calibrationPath = uigetdir;
            catch
                noFolder = true;
            end
            
            if noFolder == false && isnumeric(calibrationPath) == false && exist(calibrationPath,'dir') ~= 0
                folderList = dir(calibrationPath);
                assignin('base','folderList',folderList);
                app.STARTButton_2.Value = 1;
                app.ModeLamp.Color = [0 1 0];
                drawnow;
                originalMultiStop = app.MultiStopSwitch.Value;
                app.MultiStopSwitch.Value = '0';
                %originalMultiStopConditions = multiStopConditions;
                %multiStopConditions = 4;
                i = 3;
                
                while i <= numel(folderList) && app.STARTButton_2.Value == 1
                    
                    folderName = folderList(i).name;
                    
                    if exist([calibrationPath,'\',folderName,'\','1'], 'dir')
                        selectPath = [calibrationPath,'\',folderName,'\','1'];
                        
                    else
                        selectPath = [calibrationPath,'\',folderName];
                        
                    end
                    
                    if numel(str2num(folderName)) > 0 && str2num(folderName) > 0 && str2num(folderName) < 25000
                        
                        selectPath
                        savePath = [savePath,'\EnergyCalibrationAnalyzed\',folderName];
                        
                        if exist(savePath,'dir') == 0
                            mkdir(savePath);
                            
                        end
                        
                        app.analysis();
                        app.saveAll();
                        app.STARTButton_2.Value = 1;
                        pause(1);
                        
                    end


                    i = i + 1;
                    
                    savePath = savePathOriginal;
                end
                
            end
            
            app.ModeLamp.Color = [1 0 0];
            app.STARTButton_2.BackgroundColor = [0 1 0];
            app.STARTButton_2.Text = 'START';
            app.STARTButton_2.Value = 0;
            drawnow;
            app.MultiStopSwitch.Value = originalMultiStop;
            savePath = savePathOriginal;
            %multiStopConditions = originalMultiStopConditions;
            msgbox('Energy calibration analysis complete.','Complete');
            
        end

        % Menu selected function: MultiStopConditionsMenu
        function MultiStopConditionsMenuSelected(app, event)
            global multiStopConditions;
            
            initialConditions = multiStopConditions;
            multiStopString = {'1 Only','2 Only','3 Only','All','> 1 Only','2 Only - Split Spectra','3 Only - Split Spectra'};
            
            [multiStopConditions,tf] = listdlg('PromptString','Number of electrons to count per event: ',...
                           'SelectionMode','single','InitialValue',4,...
                           'ListString',multiStopString);
                       
            if tf == 0
                multiStopConditions = initialConditions;
                
            else
                app.MultiStopSwitch.Value = '1';
                
            end
                           
        end

        % Callback function
        function KDESpectraMenuSelected(app, event)
            global kdeSpectra;
            warning = msgbox('Warning: Saving KDE spectra can only be saved with fewer than 50,000 samples.');
            answer = questdlg('Save KDE spectra?', ...
               	'KDE Spectra', ...
               	'Yes','No','No');
            
            % Handle response
            switch answer
                case 'Yes'
                    kdeSpectra = 1;
                    
                case 'No'
                    kdeSpectra = 0;
                    
            end
        end

        % Value changed function: MultiStopSwitch
        function MultiStopSwitchValueChanged(app, event)
            value = app.MultiStopSwitch.Value;
            
        end

        % Menu selected function: StabilizationSettingsMenu
        function StabilizationSettingsMenuSelected(app, event)
            global stableLength;
            
            prompt = {'Number of samples to fit for stabilization, Ch. 1: ','Ch. 2','Ch. 3', 'Ch. 4'};
            title = 'Stabilization Collection';
            dims = [1 20];
            definput = {'5000','5000','5000','5000'};
            answer = inputdlg(prompt,title,dims,definput);
            
            for c=1:4
                stableLength(c) = str2double(answer{c});
                
                stableLength(c) = round(stableLength(c),-1);
                
            end
        end

        % Menu selected function: ShapingParameterOptimizationMenu
        function ShapingParameterOptimizationMenuSelected(app, event)
            global selectPath;
            global savePath;
            global Ge1;
            global Ge2;
            global Ge3;
            global Ge4;
            global xdataGe1;
            global xdataGe2;
            global xdataGe3;
            global xdataGe4;
            global gammaMaxChange;
            %global runThrough;
            
            [geChannel,tf] = listdlg('PromptString','Channel # for optimization: ', ...
                           'SelectionMode','single','InitialValue',2, ...
                           'ListString',{'1','2','3','4'});
                       
            if tf == 1
                geChannel = '2';
                
            end
            
            geChannel = str2double(geChannel);
            
            switch geChannel
                case 1
                    Ge = Ge1;
                    xdataGe = xdataGe1;
                    
                case 2
                    Ge = Ge2;
                    xdataGe = xdataGe2;
                    
                case 3
                    Ge = Ge3;
                    xdataGe = xdataGe3;
                    
                case 4
                    Ge = Ge4;
                    xdataGe = xdataGe4;
                    
            end
            
            answer = questdlg('Does peak appear in proper position for 2 us top shaping time?', ...
               	'Peak Position', ...
               	'Yes','No','No');
            
            % Handle response
            switch answer
                
                case 'Yes'
                    
                    originalSelectPath = selectPath;
                    originalSavePath = savePath;
                    noFolder = false;
                    gammaMaxChange = true;
                    %originalPlotSwitchValue = app.PlotsSwitch.Value;
                    %app.PlotsSwitch.Value = '1';
                    %originalStabilizerSwitch = app.Ch2GammaStabilizerSwitch.Value;
                    %app.Ch2GammaStabilizerSwitch.Value = '1';
                    %originalStabilizerControlSwitch = app.Ch2SaveStabilizerControlSwitch.Value;
                    %app.Ch2SaveStabilizerControlSwitch.Value = '1';
                    %originalSetStableSwitch = app.Ch2SetStableChSwitch.Value;
                    %app.Ch2SetStableChSwitch.Value = '1';
                    optimizationPath = selectPath;
                    %runThrough = 0;
                    %{
                    try
                        optimizationPath = uigetdir;
                    catch
                        noFolder = true;
                    end
                    %}
                    if noFolder == false && isnumeric(optimizationPath) == false && exist(optimizationPath,'dir') ~= 0 ...
                            && app.Ch2Shaping.Value ~= 0
                        app.STARTButton_2.Value = 1;
                        app.ModeLamp.Color = [0 1 0];
                        drawnow;
                        i = -0.5;
                        
                        evalin('base','numberRuns = 1;');
                        evalin('base','allNeurons.mwdParameters = zeros(2,500);');
                        
                        for y=1:10
                            assignin('base','y',y);
                            evalin('base','allNeurons.mwdNeurons{y} = zeros(100,500);');
                            
                        end
                        
                        %{
                        evalin('base','numberRuns = 1;');
                        evalin('base','allNeurons.mwdParameters = zeros(2,2500);');
                        
                        for y=1:225
                            assignin('base','y',y);
                            evalin('base','allNeurons.mwdNeurons{y} = zeros(250,2500);');
                            
                        end
                        %}
                        
                        while i <= 5 && app.STARTButton_2.Value == 1
                            
                            %selectPath = optimizationPath;
                            
                            % Changing rise time parameter for trapezoid or shaping parameter for Gaussian.
                            %app.Ch1TrapezoidalFilterRiseFall.Value = 4*i - 2;
                            
                            j = 1;
                            
                            app.Ch2TrapezoidalFilterRiseFall.Value = i;
                            
                            while j <= 8
                                
                                app.Ch2TrapezoidalFilterTop.Value = j;
                                
                                assignin('base','MWDParam',[i j]);
                                evalin('base','allNeurons.mwdParameters(:,numberRuns) = MWDParam;');
                                
                                app.analysis();
                                %{
                                smoothedGe = smooth(Ge2,25,'lowess');
                                figure
                                plot(smoothedGe)
                                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                                peakMaxIndex = peakMaxIndex + 10
                                assignin('base','xdataGe',xdataGe2)
                                assignin('base','Ge',Ge2)
                                gaussFit = fit(xdataGe2(peakMaxIndex-40:peakMaxIndex+50), ... 
                                    Ge2(peakMaxIndex-40:peakMaxIndex+50),'gauss1');
                                
                                plot(gaussFit,xdataGe2(peakMaxIndex-40:peakMaxIndex+50),Ge2(peakMaxIndex-40:peakMaxIndex+50));
                                
                                cValues = coeffvalues(gaussFit);
                                FWHM = 2*sqrt(2*log(2))*cValues(3);
                                
                                app.checkPaths('',savePath,1);
                                
                                save([savePath,'\Op_Ge_',num2str(app.Ch2TrapezoidalFilterRiseFall.Value),'usRT_', ...
                                    num2str(app.Ch2TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                                    'Ge2','-ascii','-double','-tabs');
                                %}
                                
                                app.STARTButton_2.Value = 1;
                                pause(1);
                                
                                j = j + 0.5;
                                
                                evalin('base','numberRuns = numberRuns + 1;');
                                
                            end
                            
                            
                            i = i + 0.25;
                            
                        end
                        
                    end
                    
                    gammaMaxChange = false;
                    %app.PlotsSwitch.Value = originalPlotSwitchValue;
                    %app.Ch2GammaStabilizerSwitch.Value = originalStabilizerSwitch;
                    %app.Ch2SaveStabilizerControlSwitch.Value = originalStabilizerControlSwitch;
                    %app.Ch2SetStableChSwitch.Value = originalSetStableSwitch;
                    app.ModeLamp.Color = [1 0 0];
                    app.STARTButton_2.BackgroundColor = [0 1 0];
                    app.STARTButton_2.Text = 'START';
                    app.STARTButton_2.Value = 0;
                    drawnow;
                    msgbox('Optimization analysis complete.','Complete');
                    
                case 'No'
            end
        end

        % Callback function
        function Ch2DropDownTimingValueChanged(app, event)
            value = str2double(app.Ch2DropDownTiming.Value);
            
            if value == 2
                app.RiseTimeFilterLower.Enable = false;
                app.RiseTimeFilterUpper.Enable = false;
                app.ELETUpper.Enable = true;
                app.ELETLower.Enable = true;
                
            else
                app.RiseTimeFilterLower.Enable = true;
                app.RiseTimeFilterUpper.Enable = true;
                app.ELETUpper.Enable = false;
                app.ELETLower.Enable = false;
                
            end
                
        end

        % Value changed function: Ch1SetStableChSwitch
        function Ch1SetStableChSwitchValueChanged(app, event)
            value = app.Ch1SetStableChSwitch.Value;
            global gammaMaxChange;
            
            if value == '0'
                app.GammaStableCh.Enable = false;
                gammaMaxChange(1) = false;
                
            elseif value == '1'
                app.GammaStableCh.Enable = true;
                gammaMaxChange(1) = true;
                
            end
        end

        % Value changed function: Ch1GammaStabilizerSwitch
        function Ch1GammaStabilizerSwitchValueChanged(app, event)
            value = app.Ch1GammaStabilizerSwitch.Value;
           
            if value == '1'
                %app.GammaStableCh.Enable = true;
                app.Ch1SetStableChSwitch.Enable = true;
                app.Ch1SaveStabilizerControlSwitch.Enable = true;
                
            elseif value == '0'
                app.GammaStableCh.Enable = false;
                app.Ch1SetStableChSwitch.Enable = false;
                app.Ch1SaveStabilizerControlSwitch.Enable = false;
                app.Ch1SaveStabilizerControlSwitch.Value = '0';
                app.Ch1SetStableChSwitch.Value = '0';
                
            end
        end

        % Value changed function: FFTFilterSwitch
        function FFTFilterSwitchValueChanged(app, event)
            value = app.FFTFilterSwitch.Value;
            
            if value == '1'
                app.FFTFilterCh1Upper.Editable = true;
                app.FFTFilterCh2Upper.Editable = true;
                app.FFTFilterCh3Upper.Editable = true;
                app.FFTFilterCh4Upper.Editable = true;
                app.FFTFilterCh1Lower.Editable = true;
                app.FFTFilterCh2Lower.Editable = true;
                app.FFTFilterCh3Lower.Editable = true;
                app.FFTFilterCh4Lower.Editable = true;
                
            else
                app.FFTFilterCh1Upper.Editable = false;
                app.FFTFilterCh2Upper.Editable = false;
                app.FFTFilterCh3Upper.Editable = false;
                app.FFTFilterCh4Upper.Editable = false;
                app.FFTFilterCh1Lower.Editable = false;
                app.FFTFilterCh2Lower.Editable = false;
                app.FFTFilterCh3Lower.Editable = false;
                app.FFTFilterCh4Lower.Editable = false;
                
            end
            
        end

        % Value changed function: Channel1Type
        function Channel1TypeValueChanged(app, event)
            value = app.Channel1Type.Value;
            global trapezoidalFilterCounter;
            global LPFCounter;
            LPFCounter = zeros(1,2) + 1;
            trapezoidalFilterCounter = zeros(1,4) + 1;

            switch value
                case '1'
                    app.Plot1.Title.String = 'Time of Flight';
                    app.Plot1.XLabel.String = 'Time of Flight (s)';
                    app.Plot1.YLabel.String = 'Intensity';
                    app.Ch1Timing.Enable = true;
                    app.Ch1Shaping.Enable = false;
                    app.Ch2Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch1Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch1Shaping.Value = '8';
                    app.TrapezoidalFilterButton.Enable = false;
                    app.TrapezoidalFilterButtonNext.Enable = false;
                    app.Ch1TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch1TrapezoidalFilterTop.Editable = false;
                    app.Ch1GammaStabilizerSwitch.Enable = false;
                    
                case '2'
                    app.Plot1.Title.String = 'Gamma Energy';
                    app.Plot1.XLabel.String = 'Energy (ch. #)';
                    app.Plot1.YLabel.String = 'Intensity';
                    app.Ch1Timing.Enable = false;
                    app.Ch1Shaping.Editable = false;
                    app.Ch2Shaping.Enable = true;
                    app.Ch1Shaping.Items = {'Parabolic Fit'};
                    app.Ch1Shaping.ItemsData = {'9'};
                    app.Ch1Shaping.Value = '9';
                    %app.TrapezoidalFilterButton.Enable = false;
                    %app.TrapezoidalFilterButtonNext.Enable = false;
                    app.Ch1TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch1TrapezoidalFilterRiseFall.Enable = true;
                    app.Ch1TrapezoidalFilterTop.Editable = false;
                    app.Ch1GammaStabilizerSwitch.Enable = true;
                    app.TrapezoidalFilterButton.Enable = true;
                    app.TrapezoidalFilterButtonNext.Enable = true;
                    
                    
                case '3'
                    app.Plot1.Title.String = 'Gamma Energy';
                    app.Plot1.XLabel.String = 'Energy (ch. #)';
                    app.Plot1.YLabel.String = 'Intensity';
                    app.Ch1Timing.Enable = true;
                    app.Ch1Timing.Value = '3';
                    app.Ch1Shaping.Enable = true;
                    app.Ch1Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch1Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch1Shaping.Value = '1';
                    app.TrapezoidalFilterButton.Enable = true;
                    app.TrapezoidalFilterButtonNext.Enable = true;
                    app.Ch1TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch1TrapezoidalFilterTop.Editable = true;
                    app.Ch1GammaStabilizerSwitch.Enable = true;
                    
                case '4'
                    app.Plot1.Title.String = 'NC';
                    app.Plot1.XLabel.String = 'X';
                    app.Plot1.YLabel.String = 'Y';
                    app.Ch1Timing.Enable = false;
                    app.Ch1Shaping.Enable = false;
                    app.Ch1Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch1Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch1Shaping.Value = '8';
                    app.TrapezoidalFilterButton.Enable = false;
                    app.TrapezoidalFilterButtonNext.Enable = false;
                    app.Ch1TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch1TrapezoidalFilterTop.Editable = false;
                    app.Ch1GammaStabilizerSwitch.Enable = false;
                    
            end
            
        end

        % Callback function
        function ChannelDropDown_6ValueChanged(app, event)
            value = app.ChannelDropDown_6.Value;
            
            if value ~= 5
                app.Ch2TrapezoidalFilterTop.Enable = true;
                app.Ch2TrapezoidalFilterRiseFall.Enable = true;
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2DropDownShaping_2.Enable = true;
                
            else
                app.Ch2TrapezoidalFilterTop.Enable = false;
                app.Ch2TrapezoidalFilterRiseFall.Enable = false;
                app.TrapezoidalFilterButton_2.Enable = false;
                app.TrapezoidalFilterButtonNext_2.Enable = false;
                app.Ch2DropDownShaping_2.Enable = false;
                
            end
                
        end

        % Callback function
        function ChannelDropDown_5ValueChanged(app, event)
            value = app.ChannelDropDown_5.Value;
            
            if value ~= 5
                app.Ch1TrapezoidalFilterTop.Enable = true;
                app.Ch1TrapezoidalFilterRiseFall.Enable = true;
                app.TrapezoidalFilterButton.Enable = true;
                app.TrapezoidalFilterButtonNext.Enable = true;
                app.Ch1Shaping.Enable = true;
                
            else
                app.Ch1TrapezoidalFilterTop.Enable = false;
                app.Ch1TrapezoidalFilterRiseFall.Enable = false;
                app.TrapezoidalFilterButton.Enable = false;
                app.TrapezoidalFilterButtonNext.Enable = false;
                app.Ch1Shaping.Enable = false;
                
            end
               
        end

        % Callback function
        function DropDown_2ValueChanged(app, event)
            
        end

        % Callback function
        function DropDown_3ValueChanged(app, event)
            
        end

        % Callback function
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;

        end

        % Value changed function: Channel2Type
        function Channel2TypeValueChanged(app, event)
            value = app.Channel2Type.Value;
            global trapezoidalFilterCounter;
            global LPFCounter;
            LPFCounter = zeros(1,2) + 1;
            trapezoidalFilterCounter = zeros(1,4) + 1;
            
            switch value
                case '1'
                    app.Plot2.Title.String = 'Time of Flight';
                    app.Plot2.XLabel.String = 'Time of Flight (s)';
                    app.Plot2.YLabel.String = 'Intensity';
                    app.Ch2Timing.Enable = true;
                    app.Ch2Shaping.Enable = false;
                    app.Ch2Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch2Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch2Shaping.Value = '8';
                    app.TrapezoidalFilterButton_2.Enable = false;
                    app.TrapezoidalFilterButtonNext_2.Enable = false;
                    app.Ch2TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch2TrapezoidalFilterTop.Editable = false;
                    app.Ch2GammaStabilizerSwitch.Enable = false;
                    
                case '2'
                    app.Plot2.Title.String = 'Gamma Energy';
                    app.Plot2.XLabel.String = 'Energy (ch. #)';
                    app.Plot2.YLabel.String = 'Intensity';
                    app.Ch2Timing.Enable = false;
                    app.Ch2Timing.Value = '3';
                    app.Ch2Shaping.Enable = true;
                    app.Ch2Shaping.Editable = false;
                    app.Ch2Shaping.Items = {'Parabolic Fit'};
                    app.Ch2Shaping.ItemsData = {'9'};
                    app.Ch2Shaping.Value = '9';
                    %app.TrapezoidalFilterButton_2.Enable = false;
                    %app.TrapezoidalFilterButtonNext_2.Enable = false;
                    app.Ch2TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch2TrapezoidalFilterRiseFall.Enable = true;
                    app.Ch2TrapezoidalFilterTop.Editable = false;
                    app.Ch2GammaStabilizerSwitch.Enable = true;
                    app.TrapezoidalFilterButton_2.Enable = true;
                    app.TrapezoidalFilterButtonNext_2.Enable = true;
                    
                case '3'
                    app.Plot2.Title.String = 'Gamma Energy';
                    app.Plot2.XLabel.String = 'Energy (ch. #)';
                    app.Plot2.YLabel.String = 'Intensity';
                    app.Ch2Timing.Enable = true;
                    app.Ch2Shaping.Enable = true;
                    app.Ch2Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch2Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch2Shaping.Value = '1';
                    app.TrapezoidalFilterButton_2.Enable = true;
                    app.TrapezoidalFilterButtonNext_2.Enable = true;
                    app.Ch2TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch2TrapezoidalFilterTop.Editable = true;
                    app.Ch2GammaStabilizerSwitch.Enable = true;
                    
                case '4'
                    app.Plot2.Title.String = 'NC';
                    app.Plot2.XLabel.String = 'X';
                    app.Plot2.YLabel.String = 'Y';
                    app.Ch2Timing.Enable = false;
                    app.Ch2Shaping.Enable = false;
                    app.Ch2Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch2Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch2Shaping.Value = '8';
                    app.TrapezoidalFilterButton_2.Enable = false;
                    app.TrapezoidalFilterButtonNext_2.Enable = false;
                    app.Ch2TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch2TrapezoidalFilterTop.Editable = false;
                    app.Ch2GammaStabilizerSwitch.Enable = false;
                    
            end
        end

        % Value changed function: Channel3Type
        function Channel3TypeValueChanged(app, event)
            value = app.Channel3Type.Value;
            global trapezoidalFilterCounter;
            global LPFCounter;
            LPFCounter = zeros(1,2) + 1;
            trapezoidalFilterCounter = zeros(1,4) + 1;
            
            switch value
                case '1'
                    app.Plot3.Title.String = 'Time of Flight';
                    app.Plot3.XLabel.String = 'Time of Flight (s)';
                    app.Plot3.YLabel.String = 'Intensity';
                    app.Ch3Timing.Enable = true;
                    app.Ch3Shaping.Enable = false;
                    app.Ch2Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch3Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch3Shaping.Value = '8';
                    app.TrapezoidalFilterButton_3.Enable = false;
                    app.TrapezoidalFilterButtondNext_3.Enable = false;
                    app.Ch3TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch3TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = false;
                    
                case '2'
                    app.Plot3.Title.String = 'Gamma Energy';
                    app.Plot3.XLabel.String = 'Energy (ch. #)';
                    app.Plot3.YLabel.String = 'Intensity';
                    app.Ch3Timing.Enable = false;
                    app.Ch3Timing.Value = '3';
                    app.Ch3Shaping.Editable = false;
                    app.Ch2Shaping.Enable = true;
                    app.Ch3Shaping.Items = {'Parabolic Fit'};
                    app.Ch3Shaping.ItemsData = {'9'};
                    app.Ch3Shaping.Value = '9';
                    %app.TrapezoidalFilterButton_3.Enable = false;
                    %app.TrapezoidalFilterButtonNext_3.Enable = false;
                    app.Ch3TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch3TrapezoidalFilterRiseFall.Enable = true;
                    app.Ch3TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = true;
                    app.TrapezoidalFilterButton_3.Enable = true;
                    app.TrapezoidalFilterButtonNext_3.Enable = true;
                    
                case '3'
                    app.Plot3.Title.String = 'Gamma Energy';
                    app.Plot3.XLabel.String = 'Energy (ch. #)';
                    app.Plot3.YLabel.String = 'Intensity';
                    app.Ch3Timing.Enable = true;
                    app.Ch3Shaping.Enable = true;
                    app.Ch3Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch3Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch3Shaping.Value = '1';
                    app.TrapezoidalFilterButton_3.Enable = false;
                    app.TrapezoidalFilterButtonNext_3.Enable = false;
                    app.Ch3TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch3TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = true;
                    
                case '4'
                    app.Plot3.Title.String = 'NC';
                    app.Plot3.XLabel.String = 'X';
                    app.Plot3.YLabel.String = 'Y';
                    app.Ch3Timing.Enable = false;
                    app.Ch3Shaping.Enable = false;
                    app.Ch3Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch3Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch3Shaping.Value = '8';
                    app.TrapezoidalFilterButton_3.Enable = false;
                    app.TrapezoidalFilterButtonNext_3.Enable = false;
                    app.Ch3TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch3TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = false;
                    
            end
        end

        % Value changed function: Channel4Type
        function Channel4TypeValueChanged(app, event)
            value = app.Channel4Type.Value;
            global trapezoidalFilterCounter;
            global LPFCounter;
            LPFCounter = zeros(1,2) + 1;
            trapezoidalFilterCounter = zeros(1,4) + 1;
            
            switch value
                case '1'
                    app.Plot4.Title.String = 'Time of Flight';
                    app.Plot4.XLabel.String = 'Time of Flight (s)';
                    app.Plot4.YLabel.String = 'Intensity';
                    app.Ch4Timing.Enable = true;
                    app.Ch4Shaping.Enable = false;
                    app.Ch4Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch4Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch4Shaping.Value = '8';
                    app.TrapezoidalFilterButton_4.Enable = false;
                    app.TrapezoidalFilterButtonNext_4.Enable = false;
                    app.Ch4TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch4TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = false;
                    
                case '2'
                    app.Plot4.Title.String = 'Gamma Energy';
                    app.Plot4.XLabel.String = 'Energy (ch. #)';
                    app.Plot4.YLabel.String = 'Intensity';
                    app.Ch4Timing.Enable = false;
                    app.Ch4Timing.Value = '3';
                    app.Ch4Shaping.Editable = false;
                    app.Ch2Shaping.Enable = true;
                    app.Ch4Shaping.Items = {'Parabolic Fit'};
                    app.Ch4Shaping.ItemsData = {'9'};
                    app.Ch4Shaping.Value = '9';
                    %app.TrapezoidalFilterButton_4.Enable = false;
                    %app.TrapezoidalFilterButtonNext_4.Enable = false;
                    app.Ch4TrapezoidalFilterRiseFall.Editable = true;
                    app.Ch4TrapezoidalFilterRiseFall.Enable = true;
                    app.Ch4TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = true;
                    app.TrapezoidalFilterButton_4.Enable = true;
                    app.TrapezoidalFilterButtonNext_4.Enable = true;
                    
                case '3'
                    app.Plot4.Title.String = 'Gamma Energy';
                    app.Plot4.XLabel.String = 'Energy (ch. #)';
                    app.Plot4.YLabel.String = 'Intensity';
                    app.Ch4Timing.Enable = true;
                    app.Ch4Shaping.Enable = true;
                    app.Ch4Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch4Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch4Shaping.Value = '1';
                    app.TrapezoidalFilterButton_4.Enable = false;
                    app.TrapezoidalFilterButtonNext_4.Enable = false;
                    app.Ch4TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch4TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = true;
                    
                case '4'
                    app.Plot4.Title.String = 'NC';
                    app.Plot4.XLabel.String = 'X';
                    app.Plot4.YLabel.String = 'Y';
                    app.Ch4Timing.Enable = false;
                    app.Ch4Shaping.Enable = false;
                    app.Ch4Shaping.Items = {'Recursive Trapezoidal','Moving Window Deconvolution',...
                        'Manual Convolution (Trapezoidal)','Moving Average Semi-Gaussian',...
                        'Moving Average Trapezoidal','Maximum Likelihood','Fitting','Neural Network','None'};
                    app.Ch4Shaping.ItemsData = {'1','0','2','3','4','5','7','10','8'};
                    app.Ch4Shaping.Value = '8';
                    app.TrapezoidalFilterButton_4.Enable = false;
                    app.TrapezoidalFilterButtonNext_4.Enable = false;
                    app.Ch4TrapezoidalFilterRiseFall.Editable = false;
                    app.Ch4TrapezoidalFilterTop.Editable = false;
                    app.Ch3GammaStabilizerSwitch.Enable = false;
                    
            end
        end

        % Button pushed function: PlotLPFButton_2
        function PlotLPFButton_2Pushed(app, event)
            % Function to plot the band pass filtered pulses in the app window.
            
            global selectPath;
            global LPFCounter;
            
            FFTInput(3,1) = app.FFTFilterCh3Upper.Value*10.^6;
            FFTInput(3,2) = app.FFTFilterCh3Lower.Value*10.^6;
            FFTInput(4,1) = app.FFTFilterCh4Upper.Value*10.^6;
            FFTInput(4,2) = app.FFTFilterCh4Lower.Value*10.^6;
            
            if isempty(LPFCounter) || LPFCounter(2) < 1
                LPFCounter(2) = 1;
            end
            
            channelNumber1 = 'C3Trace';
            channelNumber2 = 'C4Trace';
            i = LPFCounter(2);
            
            if i < 10
                appendage = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
            %try
            
            if app.Channel3Type.Value ~= '4'
                fileName1 = [selectPath,'\',channelNumber1,appendage,'.trc'];
                pulse{3}(i) = waveform.ReadLeCroyBinaryWaveform(fileName1);
                
                if app.FFTFilterSwitch.Value == '1'
                    [pulse{3}(i).y,~] = bpfilter(pulse{3}(i),3,FFTInput,[]);
                    
                else
                    pulse{4}(i).y = pulse{4}(i).y - mean(pulse{4}(i).y(100:800));
                    
                end
                
                plot(app.FFTFilterPlot_4,pulse{3}(i).x,pulse{3}(i).y);
                
            end
            
            if app.Channel4Type.Value ~= '4'
                fileName2 = [selectPath,'\',channelNumber2,appendage,'.trc'];
                pulse{4}(i) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                if app.FFTFilterSwitch.Value == '1'
                    [pulse{4}(i).y,~] = bpfilter(pulse{4}(i),4,FFTInput,[]);
                    
                else
                    pulse{4}(i).y = pulse{4}(i).y - mean(pulse{4}(i).y(100:800));
                    
                end
                
                plot(app.FFTFilterPlot_3,pulse{4}(i).x,pulse{4}(i).y);
                
            end
            %catch
             %   errordlg('No file.');
                
            %end
            
            %assignin('base','pulse',pulse{2}(i).y);
        end

        % Button pushed function: PlotLPFButtonNext_2
        function PlotLPFButtonNext_2Pushed(app, event)
            global LPFCounter;
            LPFCounter(2) = LPFCounter(2) + 1;
            PlotLPFButtonPushed(app);
            
        end

        % Button pushed function: TrapezoidalFilterButton_2
        function TrapezoidalFilterButton_2Pushed(app, event)
            shapingButtons(app,2);
            
        end

        % Button pushed function: TrapezoidalFilterButtonNext_2
        function TrapezoidalFilterButtonNext_2Pushed(app, event)
            global trapezoidalFilterCounter;
            trapezoidalFilterCounter
            trapezoidalFilterCounter(2) = trapezoidalFilterCounter(2) + 1;
            shapingButtons(app,2);
            
        end

        % Button pushed function: TrapezoidalFilterButton_3
        function TrapezoidalFilterButton_3Pushed(app, event)
            shapingButtons(app,3);
            
        end

        % Button pushed function: TrapezoidalFilterButtonNext_3
        function TrapezoidalFilterButtonNext_3Pushed(app, event)
            global trapezoidalFilterCounter;
            trapezoidalFilterCounter(3) = trapezoidalFilterCounter(3) + 1;
            shapingButtons(app,3);
            
        end

        % Button pushed function: TrapezoidalFilterButton_4
        function TrapezoidalFilterButton_4Pushed(app, event)
            shapingButtons(app,4);
            
        end

        % Button pushed function: TrapezoidalFilterButtonNext_4
        function TrapezoidalFilterButtonNext_4Pushed(app, event)
            global trapezoidalFilterCounter;
            trapezoidalFilterCounter(4) = trapezoidalFilterCounter(4) + 1;
            shapingButtons(app,4);
            
        end

        % Value changed function: Ch2GammaStabilizerSwitch
        function Ch2GammaStabilizerSwitchValueChanged(app, event)
            value = app.Ch2GammaStabilizerSwitch.Value;
            
            if value == '1'
                %app.GammaStableCh_2.Enable = true;
                app.Ch2SetStableChSwitch.Enable = true;
                app.Ch2SaveStabilizerControlSwitch.Enable = true;
                
            elseif value == '0'
                app.GammaStableCh_2.Enable = false;
                app.Ch2SetStableChSwitch.Enable = false;
                app.Ch2SaveStabilizerControlSwitch.Enable = false;
                app.Ch2SaveStabilizerControlSwitch.Value = '0';
                app.Ch2SetStableChSwitch.Value = '0';
                
            end
        end

        % Value changed function: Ch3GammaStabilizerSwitch
        function Ch3GammaStabilizerSwitchValueChanged(app, event)
            value = app.Ch3GammaStabilizerSwitch.Value;
            
            if value == '1'
                %app.GammaStableCh_3.Enable = true;
                app.Ch3SetStableChSwitch.Enable = true;
                app.Ch3SaveStabilizerControlSwitch.Enable = true;
                
            elseif value == '0'
                app.GammaStableCh_3.Enable = false;
                app.Ch3SetStableChSwitch.Enable = false;
                app.Ch3SaveStabilizerControlSwitch.Enable = false;
                app.Ch3SaveStabilizerControlSwitch.Value = '0';
                app.Ch3SetStableChSwitch.Value = '0';
                
            end
        end

        % Value changed function: Ch4GammaStabilizerSwitch
        function Ch4GammaStabilizerSwitchValueChanged(app, event)
            value = app.Ch4GammaStabilizerSwitch.Value;
            
            if value == '1'
                %app.GammaStableCh_4.Enable = true;
                app.Ch4SetStableChSwitch.Enable = true;
                app.Ch4SaveStabilizerControlSwitch.Enable = true;
                
            elseif value == '0'
                app.GammaStableCh_4.Enable = false;
                app.Ch4SetStableChSwitch.Enable = false;
                app.Ch4SaveStabilizerControlSwitch.Enable = false;
                app.Ch4SaveStabilizerControlSwitch.Value = '0';
                app.Ch4SetStableChSwitch.Value = '0';
                
            end
        end

        % Value changed function: Ch1SaveStabilizerControlSwitch
        function Ch1SaveStabilizerControlSwitchValueChanged(app, event)
            value = app.Ch1SaveStabilizerControlSwitch.Value;
            
        end

        % Value changed function: Ch2SetStableChSwitch
        function Ch2SetStableChSwitchValueChanged(app, event)
            value = app.Ch2SetStableChSwitch.Value;
            global gammaMaxChange;
            
            if value == '0'
                app.GammaStableCh_2.Enable = false;
                gammaMaxChange(2) = false;
                
            elseif value == '1'
                app.GammaStableCh_2.Enable = true;
                gammaMaxChange(2) = true;
                
            end
        end

        % Value changed function: Ch3SetStableChSwitch
        function Ch3SetStableChSwitchValueChanged(app, event)
            value = app.Ch3SetStableChSwitch.Value;
            global gammaMaxChange;
            
            if value == '0'
                app.GammaStableCh_3.Enable = false;
                gammaMaxChange(3) = false;
                
            elseif value == '1'
                app.GammaStableCh_3.Enable = true;
                gammaMaxChange(3) = true;
                
            end
        end

        % Value changed function: Ch4SetStableChSwitch
        function Ch4SetStableChSwitchValueChanged(app, event)
            value = app.Ch4SetStableChSwitch.Value;
            global gammaMaxChange;
            
            if value == '0'
                app.GammaStableCh_4.Enable = false;
                gammaMaxChange(4) = false;
                
            elseif value == '1'
                app.GammaStableCh_4.Enable = true;
                gammaMaxChange(4) = true;
                
            end
        end

        % Value changed function: Ch2Shaping
        function Ch2ShapingValueChanged(app, event)
            value = str2double(app.Ch2Shaping.Value);
            
            if value == 8
                app.TrapezoidalFilterButton_2.Enable = false;
                app.TrapezoidalFilterButtonNext_2.Enable = false;
                app.Ch2TrapezoidalFilterTop.Enable = false;
                app.Ch2TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_2.Text = 'N/A';
                app.Ch2GammaStabilizerSwitch.Value = '0';
                app.Ch2GammaStabilizerSwitch.Enable = false;
                
            elseif value == 10
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2TrapezoidalFilterTop.Enable = false;
                app.Ch2TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_2.Text = 'N/A';
                app.Ch2GammaStabilizerSwitch.Value = '0';
                app.Ch2GammaStabilizerSwitch.Enable = true;
                
            elseif value == 1 || value == 2 || value == 4 || value == 0
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2TrapezoidalFilterTop.Enable = true;
                app.Ch2TrapezoidalFilterRiseFall.Enable = true;
                app.Ch2TrapezoidalFilterRiseFall.Editable = true;
                app.Ch2TrapezoidalFilterTop.Editable = true;
                app.RiseFallLabel_2.Text = 'Rise/Fall';
                app.Ch2GammaStabilizerSwitch.Enable = true;
                
            elseif value == 3
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2TrapezoidalFilterTop.Enable = false;
                app.Ch2TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_2.Text = 'Shp. Const.';
                app.Ch2GammaStabilizerSwitch.Enable = true;
                
            elseif value == 4
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2TrapezoidalFilterTop.Enable = true;
                app.Ch2TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_2.Text = 'Rise/Fall';
                app.Ch2GammaStabilizerSwitch.Enable = true;
                
            elseif value == 7
                app.TrapezoidalFilterButton_2.Enable = true;
                app.TrapezoidalFilterButtonNext_2.Enable = true;
                app.Ch2TrapezoidalFilterTop.Enable = false;
                app.Ch2TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_2.Text = 'N/A';
                app.Ch2GammaStabilizerSwitch.Enable = true;
                
            end
            
        end

        % Value changed function: Ch3Shaping
        function Ch3ShapingValueChanged(app, event)
            value = str2double(app.Ch3Shaping.Value);
            
            if value == 0 || value == 8 || value == 10
                app.TrapezoidalFilterButton_3.Enable = false;
                app.TrapezoidalFilterButtonNext_3.Enable = false;
                app.Ch3TrapezoidalFilterTop.Enable = false;
                app.Ch3TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_3.Text = 'N/A';
                if value == 8
                    app.Ch3GammaStabilizerSwitch.Value = '0';
                    app.Ch3GammaStabilizerSwitch.Enable = false;
                end
                
            elseif value == 1 || value == 2 || value == 4
                app.TrapezoidalFilterButton_3.Enable = true;
                app.TrapezoidalFilterButtonNext_3.Enable = true;
                app.Ch3TrapezoidalFilterTop.Enable = true;
                app.Ch3TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_3.Text = 'Rise/Fall';
                app.Ch3GammaStabilizerSwitch.Enable = true;
                
            elseif value == 3
                app.TrapezoidalFilterButton_3.Enable = true;
                app.TrapezoidalFilterButtonNext_3.Enable = true;
                app.Ch3TrapezoidalFilterTop.Enable = false;
                app.Ch3TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_3.Text = 'Shp. Const.';
                app.Ch3GammaStabilizerSwitch.Enable = true;
                
            elseif value == 4
                app.TrapezoidalFilterButton_3.Enable = true;
                app.TrapezoidalFilterButtonNext_3.Enable = true;
                app.Ch3TrapezoidalFilterTop.Enable = true;
                app.Ch3TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_3.Text = 'Rise/Fall';
                app.Ch3GammaStabilizerSwitch.Enable = true;
                
            elseif value == 7
                app.TrapezoidalFilterButton_3.Enable = true;
                app.TrapezoidalFilterButtonNext_3.Enable = true;
                app.Ch3TrapezoidalFilterTop.Enable = false;
                app.Ch3TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_3.Text = 'N/A';
                app.Ch3GammaStabilizerSwitch.Enable = true;
                
            end
        end

        % Value changed function: Ch4Shaping
        function Ch4ShapingValueChanged(app, event)
            value = str2double(app.Ch4Shaping.Value);
            
            if value == 0 || value == 8 || value == 10
                app.TrapezoidalFilterButton_4.Enable = false;
                app.TrapezoidalFilterButtonNext_4.Enable = false;
                app.Ch4TrapezoidalFilterTop.Enable = false;
                app.Ch4TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_4.Text = 'N/A';
                if value == 8
                    app.Ch4GammaStabilizerSwitch.Value = '0';
                    app.Ch4GammaStabilizerSwitch.Enable = false;
                end
                
            elseif value == 1 || value == 2 || value == 4
                app.TrapezoidalFilterButton_4.Enable = true;
                app.TrapezoidalFilterButtonNext_4.Enable = true;
                app.Ch4TrapezoidalFilterTop.Enable = true;
                app.Ch4TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_4.Text = 'Rise/Fall';
                app.Ch4GammaStabilizerSwitch.Enable = true;
                
            elseif value == 3
                app.TrapezoidalFilterButton_4.Enable = true;
                app.TrapezoidalFilterButtonNext_4.Enable = true;
                app.Ch4TrapezoidalFilterTop.Enable = false;
                app.Ch4TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_4.Text = 'Shp. Const.';
                app.Ch4GammaStabilizerSwitch.Enable = true;
                
            elseif value == 4
                app.TrapezoidalFilterButton_4.Enable = true;
                app.TrapezoidalFilterButtonNext_4.Enable = true;
                app.Ch4TrapezoidalFilterTop.Enable = true;
                app.Ch4TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel_4.Text = 'Rise/Fall';
                app.Ch4GammaStabilizerSwitch.Enable = true;
                
            elseif value == 7
                app.TrapezoidalFilterButton_4.Enable = true;
                app.TrapezoidalFilterButtonNext_4.Enable = true;
                app.Ch4TrapezoidalFilterTop.Enable = false;
                app.Ch4TrapezoidalFilterRiseFall.Enable = false;
                app.RiseFallLabel_4.Text = 'N/A';
                app.Ch4GammaStabilizerSwitch.Enable = true;
                
            end
        end

        % Menu selected function: SelectMultipleFoldersMenu
        function SelectMultipleFoldersMenuSelected(app, event)
            global selectedMultiFolders;
            global newPaths;
            global selectPath;
            global numFiles;
            
            noFolder = false;
            finishedSelecting = false;
            selectedMultiFolders = false;
            newPaths = {};
            numFiles = 99999;
            folders = [];
            
            while finishedSelecting == false
                
                try
                    folderSelections = uigetdir;
                catch
                    noFolder = true;
                end
                
                if noFolder == false && isnumeric(folderSelections) == false && exist(folderSelections,'dir') ~= 0
                    folderList = dir(folderSelections);
                    %folderList = folderList.name{3:numel(folderList)};
                    numel(folderList)
                    
                    if numel(folderList) < 20
                        
                        for f=3:numel(folderList)
                            
                            if folderList(f).isdir == 1
                                newPaths = horzcat(newPaths,[folderSelections,'\',folderList(f).name]);
                                %[newPaths,~] = app.checkPaths(newPaths,'',0);
                                %fileList = dir([folderSelections,'\',folderList(f).name,'\*.trc']);
                                %numFilesT = length(fileList);
                                %numFiles = numFiles + numFilesT;
                                selectedMultiFolders = true;
                                app.MultiFolderSwitch.Value = '1';
                                numFiles = 99999;
                                folders = [folders,folderList(f).name];
                                assignin('base','folders',folders);
                                
                            end
                            
                        end
                        
                    else
                        newPaths = horzcat(newPaths,folderSelections)
                        %numFiles = numFiles + numel(folderList);
                        numFiles = 99999;
                        
                    end
                    
                end
                
                answer = questdlg('Select another folder?','Multiple Folders');
                
                %try
                switch answer
                    case 'Yes'
                        finishedSelecting = false;
                        %newPaths(cellfun('isempty',newPaths)) = [];
                        selectedMultiFolders = true;
                        
                        app.MultiFolderSwitch.Value = '1';
                        
                    case 'No'
                        finishedSelecting = true;
                        %newPaths(cellfun('isempty',newPaths)) = [];
                        selectPath = newPaths{1};
                        %numFiles = numFiles/2;
                        %app.numSamplesT.Value = numFiles;
                        numFiles = 99999;
                        selectedMultiFolders = true;
                        
                        app.MultiFolderSwitch.Value = '1';
                        
                    case 'Cancel'
                        finishedSelecting = true;
                        %newPaths(cellfun('isempty',newPaths)) = [];
                        selectPath = newPaths{1};
                        %numFiles = numFiles/2;
                        %app.numSamplesT.Value = numFiles;
                        selectedMultiFolders = true;
                        
                    case ''
                        finishedSelecting = true;
                        %newPaths(cellfun('isempty',newPaths)) = [];
                        selectPath = newPaths{1};
                        %numFiles = numFiles/2;
                        %app.numSamplesT.Value = numFiles;
                        selectedMultiFolders = true;
                        
                end
                %catch
                %end
            end
        end

        % Value changed function: MultiFolderSwitch
        function MultiFolderSwitchValueChanged(app, event)
            global selectedMultiFolders;
            
            selectedMultiFolders = false;
            
        end

        % Value changed function: Ch2Timing
        function Ch2TimingValueChanged(app, event)
            value = app.Ch2Timing.Value;
            
            if value == '0'
                app.ELETValuesofMagLabel_2.Text = 'ELET Values (% of Mag.)';
                app.Ch2ELETUpper.Enable = true;
                app.Ch2ELETLower.Enable = true;
                app.Ch2ELETLower.Value = 7;
                app.LowerLabel_4.Text = 'Lower';
                
            elseif value == '1'
                app.ELETValuesofMagLabel_2.Text = 'CFD Value (% of Mag.)';
                app.Ch2ELETUpper.Enable = false;
                app.Ch2ELETLower.Enable = true;
                app.Ch2ELETLower.Value = 42;
                app.LowerLabel_4.Text = 'Value';
                
            else
                app.Ch2ELETUpper.Enable = false;
                app.Ch2ELETLower.Enable = false;
                
            end
        end

        % Value changed function: Ch1Timing
        function Ch1TimingValueChanged(app, event)
            value = app.Ch1Timing.Value;
            
            if value == '0'
                app.CFDValueofMagLabel.Text = 'ELET Values (% of Mag.)';
                app.Ch1ELETUpper.Enable = true;
                app.Ch1ELETLower.Enable = true;
                app.Ch1ELETLower.Value = 7;
                app.ValueLabel.Text = 'Lower';
                
            elseif value == '1'
                app.CFDValueofMagLabel.Text = 'CFD Value (% of Mag.)';
                app.Ch1ELETUpper.Enable = false;
                app.Ch1ELETLower.Enable = true;
                app.Ch1ELETLower.Value = 42;
                app.ValueLabel.Text = 'Value';
                
            else
                app.Ch1ELETUpper.Enable = false;
                app.Ch1ELETLower.Enable = false;
                
            end
        end

        % Value changed function: Ch3Timing
        function Ch3TimingValueChanged(app, event)
            value = app.Ch3Timing.Value;
            
            if value == '0'
                app.Ch3ELETUpper.Enable = true;
                app.Ch3ELETLower.Enable = true;
                
            else
                app.Ch3ELETUpper.Enable = false;
                app.Ch3ELETLower.Enable = false;
                
            end
        end

        % Value changed function: Ch4Timing
        function Ch4TimingValueChanged(app, event)
            value = app.Ch4Timing.Value;
            
            if value == '0'
                app.Ch4ELETUpper.Enable = true;
                app.Ch4ELETLower.Enable = true;
                
            else
                app.Ch4ELETUpper.Enable = false;
                app.Ch4ELETLower.Enable = false;
                
            end
        end

        % Menu selected function: TimingParameterOptimizationMenu
        function TimingParameterOptimizationMenuSelected(app, event)
            global selectPath;
            global savePath;
            global ToF;
            global xdataToF;
            global Ge1;
            global Ge2;
            global xdataGe1;
            global xdataGe2;
            global ELETParam;
            global neuron;
            global ggc;
            global ELETMatrix;
            global DiscriminationVector;
            
            originalSavePath = savePath;
            outerSavePath = [originalSavePath,'\Neurons'];
            optimizationPath = selectPath;
            %originalTiming1 = app.Ch1Timing.Value;
            %originalTiming2 = app.Ch2Timing.Value;
            
            prompt = {'Lower Bound (%) ','Upper Bound (%) ','Min. % Difference ',...
                'Max. Difference ','Total # of Neurons '};
            
            title = 'Timing Parameter Optimization';
            dims = [1 20];
            definput = {'1','20','2','5','49'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result)
                return;
                
            else
                lowerBound = str2double(result{1});
                upperBound = str2double(result{2});
                percentDiff1 = str2double(result{3});
                percentDiff2 = str2double(result{4});
                numNeurons = str2double(result{5});
                
            end
            
            a = fieldnames(DiscriminationVector);
            exact = getfield(DiscriminationVector,a{1});
            %ELETMatrixBase = evalin('base','best;');
            
            if isnumeric(optimizationPath) == false && ...
                    exist(optimizationPath,'dir') ~= 0 && ...
                    isempty(result) == 0
                
                %app.Ch1Timing.Value = '0';
                %app.Ch2Timing.Value = '4';
                ggc = 1;
                app.STARTButton_2.Value = 1;
                app.ModeLamp.Color = [0 1 0];
                drawnow;
                
                savePath = [outerSavePath];
                
                if exist(savePath,'dir') == 0
                    'Making Directory'
                    mkdir(savePath);
                    
                end
                
                app.checkPaths('',savePath,1);
                
                %{
                    fwhmv = ELETMatrix(neuron,4);
                    
                    if fwhmv <= 20E-9
                        
                        i = ELETMatrixBase(neuron,1) + lowerBound;
                        maximum = i;
                        j = ELETMatrixBase(neuron,2) + percentDiff1;
                        
                    else
                        i = 25 + lowerBound;
                        maximum = i + 10;
                        j = i + percentDiff1;
                        
                    end
                %}
                
                
                evalin('base','numberRuns = 1;');
                evalin('base','allNeurons.eletParameters = zeros(2,50);');
                
                for y=1:numNeurons
                    assignin('base','y',y);
                    evalin('base','allNeurons.neurons{y} = zeros(3000,50);');
                    
                end
                
                
                i = lowerBound;%ELETMatrixBase(neuron,1) + lowerBound;
                %maximum = i;%ELETMatrixBase(neuron,1);
                %ELETMatrixBase(neuron,2) + percentDiff1;
                
                %if exact(neuron) == 1
                
                %best = evalin('base','bestNew;');
                
                while i <= upperBound ...
                        && app.STARTButton_2.Value == 1
                    
                    ELETParam(1) = i;
                    j = i + percentDiff1;
                    
                    while j <= i + percentDiff2
                        
                        ELETParam(2) = j;
                        
                        assignin('base','ELETParam',ELETParam);
                        evalin('base','allNeurons.eletParameters(:,numberRuns) = ELETParam;');
                        
                        app.analysis();
                        %smoothedGe = smooth(Ge2,25,'lowess');
                        %figure
                        %plot(ToF)
                        %{
                                [~,peakMaxIndex] = max(ToF(10:numel(ToF)-10));
                                peakMaxIndex = peakMaxIndex + 10
                                assignin('base','xdataToF',xdataToF)
                                assignin('base','ToF',ToF)
                                
                                try
                                    gaussFit = fit(xdataToF,ToF,'gauss1');
                                    
                                    %figure;
                                    %plot(gaussFit,xdataToF,ToF);
                                    
                                    cValues = coeffvalues(gaussFit);
                                    
                                    % REPLACE WITH
                                    % (2*sqrt(log(2))*cValues(3))/sqrt(2)
                                    FWHM = (2*sqrt(log(2))*cValues(3))/sqrt(2);
                                    % For comparison with old values:
                                    % 2*sqrt(2*log(2))*cValues(3)
                                    x0 = cValues(2);
                                    
                                catch
                                    FWHM = 0;
                                    
                                end
                                
                                save([savePath,'\Op_G-G_TD_',num2str(ELETParam(1)),'%OfAmp_', ...
                                    num2str(ELETParam(2)),'%OfAmp_',num2str(FWHM),'FWHM_' ...
                                    num2str(x0),'x0_','Neuron#',num2str(neuron),'.txt'], ...
                                    'ToF','-ascii','-double','-tabs');
                        %}
                        app.STARTButton_2.Value = 1;
                        pause(1);
                        
                        j = j + 4;
                        
                        evalin('base','numberRuns = numberRuns + 1;');
                        
                        %{
                                if fwhmv <= 2E-8
                                    
                                    j = j + 0.5;
                                    
                                else
                                    j = j + 3;
                                    
                                end
                        %}
                        
                    end
                    
                    i = i + 2;
                    
                    %{
                            if fwhmv <= 2E-8
                                
                                i = i + 0.5;
                                
                            else
                                i = i + 5;
                                
                            end
                    %}
                    
                end
                
                %end
                
                neuron = neuron + 1;
                
            end
            
            %app.Ch1Timing.Value = originalTiming1;
            %app.Ch2Timing.Value = originalTiming2;
            neuron = 0;
            savePath = originalSavePath;
            ggc = 0;
            
            app.ModeLamp.Color = [1 0 0];
            app.STARTButton_2.BackgroundColor = [0 1 0];
            app.STARTButton_2.Text = 'START';
            app.STARTButton_2.Value = 0;
            drawnow;
            msgbox('Timing optimization analysis complete.','Complete');
        
        end

        % Menu selected function: TimingMenu
        function TimingMenuSelected(app, event)
    
        end

        % Menu selected function: InstallNewNetworkMenu
        function InstallNewNetworkMenuSelected(app, event)
    global ClusteringNetwork;
            
            [fileName,filePath] = uigetfile;
            homePath = pwd;
            
            delete([homePath,'\SOMClusteringNetwork.mat']);
            
            copyfile([filePath,'\',fileName],[homePath,'\SOMClusteringNetwork.mat']);
            
            ClusteringNetwork = load([homePath,'\SOMClusteringNetwork.mat']);
            
            a = fieldnames(ClusteringNetwork);
            
            ClusteringNetwork = getfield(ClusteringNetwork,a{1});
            
            ClusteringNetwork = network(ClusteringNetwork);
            
        end

        % Menu selected function: InstallNewDiscriminationVectorMenu
        function InstallNewDiscriminationVectorMenuSelected(app, event)
            global DiscriminationVector;
            
            [fileName,filePath] = uigetfile;
            homePath = pwd;
            
            delete([homePath,'\DiscriminationVector.mat']);
            
            copyfile([filePath,'\',fileName],[homePath,'\DiscriminationVector.mat']);
            
            DiscriminationVector = load([homePath,'\DiscriminationVector.mat']);
            
        end

        % Menu selected function: InstallNewParameterMatrixMenu
        function InstallNewParameterMatrixMenuSelected(app, event)
            global ParameterMatrix;
            
            [fileName,filePath] = uigetfile;
            homePath = pwd;
            
            delete([homePath,'\ParameterMatrix.mat']);
            
            copyfile([filePath,'\',fileName],[homePath,'\ParameterMatrix.mat']);
            
            ParameterMatrix = load([homePath,'\ParameterMatrix.mat']);
            
            a = fieldnames(ParameterMatrix);
            ParameterMatrix = getfield(ParameterMatrix,a{1});
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PositroninducedElectronGammaUIFigure = uifigure('Visible', 'off');
            app.PositroninducedElectronGammaUIFigure.Name = 'Positron-induced Electron - Gamma';
            app.OptionsMenu = uimenu(app.PositroninducedElectronGammaUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.RiseTimesMenu = uimenu(app.OptionsMenu);
            app.RiseTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @RiseTimesMenuSelected, true);
            app.RiseTimesMenu.Text = 'Rise Times';
            app.PeakLocationMenu = uimenu(app.OptionsMenu);
            app.PeakLocationMenu.MenuSelectedFcn = createCallbackFcn(app, @PeakLocationMenuSelected, true);
            app.PeakLocationMenu.Text = 'Peak Location';
            app.CollectionTimeMenu = uimenu(app.OptionsMenu);
            app.CollectionTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @CollectionTimeMenuSelected, true);
            app.CollectionTimeMenu.Text = 'Collection Time';
            app.InitialSampleMenu = uimenu(app.OptionsMenu);
            app.InitialSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @InitialSampleMenuSelected, true);
            app.InitialSampleMenu.Text = 'Initial Sample';
            app.EnergyCalibrationMenu = uimenu(app.OptionsMenu);
            app.EnergyCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @EnergyCalibrationMenuSelected, true);
            app.EnergyCalibrationMenu.Text = 'Energy Calibration';
            app.MultiStopConditionsMenu = uimenu(app.OptionsMenu);
            app.MultiStopConditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiStopConditionsMenuSelected, true);
            app.MultiStopConditionsMenu.Text = 'Multi-Stop Conditions';
            app.StabilizationSettingsMenu = uimenu(app.OptionsMenu);
            app.StabilizationSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @StabilizationSettingsMenuSelected, true);
            app.StabilizationSettingsMenu.Text = 'Stabilization Settings';
            app.ShapingParameterOptimizationMenu = uimenu(app.OptionsMenu);
            app.ShapingParameterOptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @ShapingParameterOptimizationMenuSelected, true);
            app.ShapingParameterOptimizationMenu.Text = 'Shaping Parameter Optimization';
            app.TimingParameterOptimizationMenu = uimenu(app.OptionsMenu);
            app.TimingParameterOptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @TimingParameterOptimizationMenuSelected, true);
            app.TimingParameterOptimizationMenu.Text = 'Timing Parameter Optimization';
            app.SelectMultipleFoldersMenu = uimenu(app.OptionsMenu);
            app.SelectMultipleFoldersMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectMultipleFoldersMenuSelected, true);
            app.SelectMultipleFoldersMenu.Text = 'Select Multiple Folders';
            app.ANNsMenu = uimenu(app.PositroninducedElectronGammaUIFigure);
            app.ANNsMenu.Text = 'ANNs';
            app.TimingMenu = uimenu(app.ANNsMenu);
            app.TimingMenu.MenuSelectedFcn = createCallbackFcn(app, @TimingMenuSelected, true);
            app.TimingMenu.Text = 'Timing';
            app.InstallNewNetworkMenu = uimenu(app.TimingMenu);
            app.InstallNewNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallNewNetworkMenuSelected, true);
            app.InstallNewNetworkMenu.Text = 'Install New Network';
            app.InstallNewDiscriminationVectorMenu = uimenu(app.TimingMenu);
            app.InstallNewDiscriminationVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallNewDiscriminationVectorMenuSelected, true);
            app.InstallNewDiscriminationVectorMenu.Text = 'Install New Discrimination Vector';
            app.InstallNewParameterMatrixMenu = uimenu(app.TimingMenu);
            app.InstallNewParameterMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallNewParameterMatrixMenuSelected, true);
            app.InstallNewParameterMatrixMenu.Text = 'Install New Parameter Matrix';
            app.ShapingMenu = uimenu(app.ANNsMenu);
            app.ShapingMenu.Text = 'Shaping';
            app.InstallNewNetworkMenu_2 = uimenu(app.ShapingMenu);
            app.InstallNewNetworkMenu_2.Text = 'Install New Network';
            app.numSamples = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.numSamples.Limits = [0 1000000];
            app.numSamples.ValueDisplayFormat = '%.0f';
            app.numSamples.ValueChangedFcn = createCallbackFcn(app, @numSamplesValueChanged, true);
            app.numSamples.Value = 99999;
            app.NumberofSamplesPerFolderLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.NumberofSamplesPerFolderLabel.Text = {'Number of Samples'; 'Per Folder'};
            app.TracesFolderButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.TracesFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TracesFolderButtonPushed, true);
            app.TracesFolderButton.Text = 'Traces Folder';
            app.PercentageCompleteGaugeLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.PercentageCompleteGaugeLabel.Text = 'Percentage Complete';
            app.PercentageCompleteGauge = uigauge(app.PositroninducedElectronGammaUIFigure, 'circular');
            app.SavingFolderButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.SavingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavingFolderButtonPushed, true);
            app.SavingFolderButton.Text = 'Saving Folder';
            app.TracesFolder = uieditfield(app.PositroninducedElectronGammaUIFigure, 'text');
            app.TracesFolder.ValueChangedFcn = createCallbackFcn(app, @TracesFolderValueChanged, true);
            app.SavingFolder = uieditfield(app.PositroninducedElectronGammaUIFigure, 'text');
            app.SavingFolder.ValueChangedFcn = createCallbackFcn(app, @SavingFolderValueChanged, true);
            app.PlotsSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.PlotsSwitchLabel.Text = 'Plots';
            app.PlotsSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.PlotsSwitch.ItemsData = {'0', '1'};
            app.PlotsSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotsSwitchValueChanged, true);
            app.PlotsSwitch.Value = '1';
            app.PAUSEButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'state');
            app.PAUSEButton.ValueChangedFcn = createCallbackFcn(app, @PAUSEButtonValueChanged, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.ModeLampLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.ModeLampLabel.Text = 'Mode';
            app.ModeLamp = uilamp(app.PositroninducedElectronGammaUIFigure);
            app.DPlotSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.DPlotSwitchLabel.Text = '3D Plot';
            app.DPlotSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.DPlotSwitch.ItemsData = {'0', '1'};
            app.DPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @DPlotSwitchValueChanged, true);
            app.DPlotSwitch.Value = '0';
            app.SAVECURRENTButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.SAVECURRENTButton.ButtonPushedFcn = createCallbackFcn(app, @SAVECURRENTButtonPushed, true);
            app.SAVECURRENTButton.Text = 'SAVE CURRENT';
            app.STARTButton_2 = uibutton(app.PositroninducedElectronGammaUIFigure, 'state');
            app.STARTButton_2.ValueChangedFcn = createCallbackFcn(app, @STARTButton_2ValueChanged, true);
            app.STARTButton_2.Text = 'START';
            app.MultiFolderSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.MultiFolderSwitchLabel.Text = 'Multi-Folder';
            app.MultiFolderSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.MultiFolderSwitch.ItemsData = {'0', '1'};
            app.MultiFolderSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiFolderSwitchValueChanged, true);
            app.MultiFolderSwitch.Value = '0';
            app.AnalysisRateHzLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.AnalysisRateHzLabel.Text = 'Analysis Rate (Hz)';
            app.RateGauge = uigauge(app.PositroninducedElectronGammaUIFigure, 'semicircular');
            app.RateGauge.Limits = [0 25];
            app.TabGroup = uitabgroup(app.PositroninducedElectronGammaUIFigure);
            app.ToFSpectrumControlsTab = uitab(app.TabGroup);
            app.ToFSpectrumControlsTab.Title = 'ToF Spectrum Controls';
            app.TimingWindowsKnobLabel = uilabel(app.ToFSpectrumControlsTab);
            app.TimingWindowsKnobLabel.Text = 'Timing Window (μs)';
            app.TimingWindowsKnob = uiknob(app.ToFSpectrumControlsTab, 'discrete');
            app.TimingWindowsKnob.Items = {'1', '2', '3', '4', '5', '6', '7', 'Cust.'};
            app.TimingWindowsKnob.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '0'};
            app.TimingWindowsKnob.Value = '4';
            app.MultiStopSwitchLabel = uilabel(app.ToFSpectrumControlsTab);
            app.MultiStopSwitchLabel.Text = 'Multi-Stop';
            app.MultiStopSwitch = uiswitch(app.ToFSpectrumControlsTab, 'slider');
            app.MultiStopSwitch.ItemsData = {'0', '1'};
            app.MultiStopSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiStopSwitchValueChanged, true);
            app.MultiStopSwitch.Value = '0';
            app.CoinChDropDownLabel = uilabel(app.ToFSpectrumControlsTab);
            app.CoinChDropDownLabel.Text = 'Coin. Ch. ';
            app.CoinChDropDown = uidropdown(app.ToFSpectrumControlsTab);
            app.CoinChDropDown.Items = {'1', '2', '3', '4'};
            app.CoinChDropDown.ItemsData = {'1', '2', '3', '4'};
            app.CoinChDropDown.Value = '1';
            app.DropDown_5Label_2 = uilabel(app.ToFSpectrumControlsTab);
            app.DropDown_5Label_2.Text = 'Coin. Ch. ';
            app.CoinChDropDown2 = uidropdown(app.ToFSpectrumControlsTab);
            app.CoinChDropDown2.Items = {'1', '2', '3', '4'};
            app.CoinChDropDown2.ItemsData = {'1', '2', '3', '4'};
            app.CoinChDropDown2.Value = '2';
            app.SecondECutoff = uieditfield(app.ToFSpectrumControlsTab, 'numeric');
            app.SecondECutoff.Value = 7000;
            app.LastElectronCutoffnsLabel = uilabel(app.ToFSpectrumControlsTab);
            app.LastElectronCutoffnsLabel.Text = {'Last Electron'; ' Cutoff (ns)'};
            app.UpperLabel_6 = uilabel(app.ToFSpectrumControlsTab);
            app.UpperLabel_6.Text = 'Upper';
            app.TimeWindowUpper = uieditfield(app.ToFSpectrumControlsTab, 'numeric');
            app.TimeWindowUpper.Value = 0.5;
            app.LowerLabel_7 = uilabel(app.ToFSpectrumControlsTab);
            app.LowerLabel_7.Text = 'Lower';
            app.TimeWindowLower = uieditfield(app.ToFSpectrumControlsTab, 'numeric');
            app.TimeWindowLower.Value = -0.25;
            app.MultiStopSwitchLabel_2 = uilabel(app.ToFSpectrumControlsTab);
            app.MultiStopSwitchLabel_2.Text = 'Cust. Limits (μs)';
            app.GammaSpectrumControlsTab = uitab(app.TabGroup);
            app.GammaSpectrumControlsTab.Title = 'Gamma Spectrum Controls';
            app.TabGroup4 = uitabgroup(app.GammaSpectrumControlsTab);
            app.Tab_10 = uitab(app.TabGroup4);
            app.Tab_10.Title = '1';
            app.Ch1GammaEditField = uieditfield(app.Tab_10, 'numeric');
            app.Ch1GammaEditField.Limits = [0 1000];
            app.Ch1GammaEditField.ValueChangedFcn = createCallbackFcn(app, @Ch1GammaEditFieldValueChanged, true);
            app.Ch1GammaEditField.Value = 12;
            app.GammaWindowSliderLabel = uilabel(app.Tab_10);
            app.GammaWindowSliderLabel.Text = 'Upper Thresh.';
            app.GammaStableCh = uieditfield(app.Tab_10, 'numeric');
            app.GammaStableCh.Limits = [0 8192];
            app.GammaStableCh.Enable = 'off';
            app.GammaStableCh.Value = 3500;
            app.GammaStableChLabel = uilabel(app.Tab_10);
            app.GammaStableChLabel.Text = 'Stabilizer Ch. #';
            app.GammaStabilizerSwitchLabel = uilabel(app.Tab_10);
            app.GammaStabilizerSwitchLabel.Text = 'Gamma Stabilizer';
            app.Ch1GammaStabilizerSwitch = uiswitch(app.Tab_10, 'slider');
            app.Ch1GammaStabilizerSwitch.ItemsData = {'0', '1'};
            app.Ch1GammaStabilizerSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch1GammaStabilizerSwitchValueChanged, true);
            app.Ch1GammaStabilizerSwitch.Enable = 'off';
            app.Ch1GammaStabilizerSwitch.Value = '0';
            app.SaveStabilizerControlSwitchLabel = uilabel(app.Tab_10);
            app.SaveStabilizerControlSwitchLabel.Text = 'Save Stabilizer Control';
            app.Ch1SaveStabilizerControlSwitch = uiswitch(app.Tab_10, 'slider');
            app.Ch1SaveStabilizerControlSwitch.ItemsData = {'0', '1'};
            app.Ch1SaveStabilizerControlSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch1SaveStabilizerControlSwitchValueChanged, true);
            app.Ch1SaveStabilizerControlSwitch.Enable = 'off';
            app.Ch1SaveStabilizerControlSwitch.Value = '0';
            app.SetStableChSwitchLabel = uilabel(app.Tab_10);
            app.SetStableChSwitchLabel.Text = 'Set Stable Ch. #';
            app.Ch1SetStableChSwitch = uiswitch(app.Tab_10, 'slider');
            app.Ch1SetStableChSwitch.ItemsData = {'0', '1'};
            app.Ch1SetStableChSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch1SetStableChSwitchValueChanged, true);
            app.Ch1SetStableChSwitch.Enable = 'off';
            app.Ch1SetStableChSwitch.Value = '0';
            app.Ch1GammaEditField_Lower = uieditfield(app.Tab_10, 'numeric');
            app.Ch1GammaEditField_Lower.Limits = [0 1000];
            app.GammaWindowSliderLabel_8 = uilabel(app.Tab_10);
            app.GammaWindowSliderLabel_8.Text = 'Lower Thresh.';
            app.Tab_11 = uitab(app.TabGroup4);
            app.Tab_11.Title = '2';
            app.GammaWindowSliderLabel_2 = uilabel(app.Tab_11);
            app.GammaWindowSliderLabel_2.Text = 'Upper Thresh.';
            app.GammaStableCh_2 = uieditfield(app.Tab_11, 'numeric');
            app.GammaStableCh_2.Limits = [0 8192];
            app.GammaStableCh_2.Enable = 'off';
            app.GammaStableCh_2.Value = 3500;
            app.GammaStableChLabel_2 = uilabel(app.Tab_11);
            app.GammaStableChLabel_2.Text = 'Stabilizer Ch. #';
            app.GammaStabilizerSwitch_2Label = uilabel(app.Tab_11);
            app.GammaStabilizerSwitch_2Label.Text = 'Gamma Stabilizer';
            app.Ch2GammaStabilizerSwitch = uiswitch(app.Tab_11, 'slider');
            app.Ch2GammaStabilizerSwitch.ItemsData = {'0', '1'};
            app.Ch2GammaStabilizerSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch2GammaStabilizerSwitchValueChanged, true);
            app.Ch2GammaStabilizerSwitch.Value = '0';
            app.SaveStabilizerControlSwitch_2Label = uilabel(app.Tab_11);
            app.SaveStabilizerControlSwitch_2Label.Text = 'Save Stabilizer Control';
            app.Ch2SaveStabilizerControlSwitch = uiswitch(app.Tab_11, 'slider');
            app.Ch2SaveStabilizerControlSwitch.ItemsData = {'0', '1'};
            app.Ch2SaveStabilizerControlSwitch.Enable = 'off';
            app.Ch2SaveStabilizerControlSwitch.Value = '0';
            app.SetStableChSwitch_2Label = uilabel(app.Tab_11);
            app.SetStableChSwitch_2Label.Text = 'Set Stable Ch. #';
            app.Ch2SetStableChSwitch = uiswitch(app.Tab_11, 'slider');
            app.Ch2SetStableChSwitch.ItemsData = {'0', '1'};
            app.Ch2SetStableChSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch2SetStableChSwitchValueChanged, true);
            app.Ch2SetStableChSwitch.Enable = 'off';
            app.Ch2SetStableChSwitch.Value = '0';
            app.Ch2GammaEditField = uieditfield(app.Tab_11, 'numeric');
            app.Ch2GammaEditField.Limits = [0 1000];
            app.Ch2GammaEditField.Value = 6.85;
            app.GammaWindowSliderLabel_7 = uilabel(app.Tab_11);
            app.GammaWindowSliderLabel_7.Text = 'Lower Thresh.';
            app.Ch2GammaEditField_Lower = uieditfield(app.Tab_11, 'numeric');
            app.Ch2GammaEditField_Lower.Limits = [0 1000];
            app.Ch2GammaEditField_Lower.Value = 6.25;
            app.Tab_12 = uitab(app.TabGroup4);
            app.Tab_12.Title = '3';
            app.GammaWindowSliderLabel_3 = uilabel(app.Tab_12);
            app.GammaWindowSliderLabel_3.Text = 'Upper Thresh.';
            app.GammaStableCh_3 = uieditfield(app.Tab_12, 'numeric');
            app.GammaStableCh_3.Limits = [0 8192];
            app.GammaStableCh_3.Enable = 'off';
            app.GammaStableCh_3.Value = 3500;
            app.GammaStableChLabel_3 = uilabel(app.Tab_12);
            app.GammaStableChLabel_3.Text = 'Stabilizer Ch. #';
            app.GammaStabilizerSwitch_3Label = uilabel(app.Tab_12);
            app.GammaStabilizerSwitch_3Label.Text = 'Gamma Stabilizer';
            app.Ch3GammaStabilizerSwitch = uiswitch(app.Tab_12, 'slider');
            app.Ch3GammaStabilizerSwitch.ItemsData = {'0', '1'};
            app.Ch3GammaStabilizerSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch3GammaStabilizerSwitchValueChanged, true);
            app.Ch3GammaStabilizerSwitch.Enable = 'off';
            app.Ch3GammaStabilizerSwitch.Value = '0';
            app.SaveStabilizerControlSwitch_3Label = uilabel(app.Tab_12);
            app.SaveStabilizerControlSwitch_3Label.Text = 'Save Stabilizer Control';
            app.Ch3SaveStabilizerControlSwitch = uiswitch(app.Tab_12, 'slider');
            app.Ch3SaveStabilizerControlSwitch.ItemsData = {'0', '1'};
            app.Ch3SaveStabilizerControlSwitch.Enable = 'off';
            app.Ch3SaveStabilizerControlSwitch.Value = '0';
            app.SetStableChSwitch_3Label = uilabel(app.Tab_12);
            app.SetStableChSwitch_3Label.Text = 'Set Stable Ch. #';
            app.Ch3SetStableChSwitch = uiswitch(app.Tab_12, 'slider');
            app.Ch3SetStableChSwitch.ItemsData = {'0', '1'};
            app.Ch3SetStableChSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch3SetStableChSwitchValueChanged, true);
            app.Ch3SetStableChSwitch.Enable = 'off';
            app.Ch3SetStableChSwitch.Value = '0';
            app.Ch3GammaEditField = uieditfield(app.Tab_12, 'numeric');
            app.Ch3GammaEditField.Limits = [0 1000];
            app.Ch3GammaEditField.Value = 10;
            app.GammaWindowSliderLabel_6 = uilabel(app.Tab_12);
            app.GammaWindowSliderLabel_6.Text = 'Lower Thresh.';
            app.Ch3GammaEditField_Lower = uieditfield(app.Tab_12, 'numeric');
            app.Ch3GammaEditField_Lower.Limits = [0 1000];
            app.Tab_9 = uitab(app.TabGroup4);
            app.Tab_9.Title = '4';
            app.GammaWindowSliderLabel_4 = uilabel(app.Tab_9);
            app.GammaWindowSliderLabel_4.Text = 'Upper Thresh.';
            app.GammaStableCh_4 = uieditfield(app.Tab_9, 'numeric');
            app.GammaStableCh_4.Limits = [0 8192];
            app.GammaStableCh_4.Enable = 'off';
            app.GammaStableCh_4.Value = 3500;
            app.GammaStableChLabel_4 = uilabel(app.Tab_9);
            app.GammaStableChLabel_4.Text = 'Stabilizer Ch. #';
            app.Ch4GammaEditField = uieditfield(app.Tab_9, 'numeric');
            app.Ch4GammaEditField.Limits = [0 1000];
            app.Ch4GammaEditField.Value = 10;
            app.GammaStabilizerSwitch_4Label = uilabel(app.Tab_9);
            app.GammaStabilizerSwitch_4Label.Text = 'Gamma Stabilizer';
            app.Ch4GammaStabilizerSwitch = uiswitch(app.Tab_9, 'slider');
            app.Ch4GammaStabilizerSwitch.ItemsData = {'0', '1'};
            app.Ch4GammaStabilizerSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch4GammaStabilizerSwitchValueChanged, true);
            app.Ch4GammaStabilizerSwitch.Enable = 'off';
            app.Ch4GammaStabilizerSwitch.Value = '0';
            app.SaveStabilizerControlSwitch_4Label = uilabel(app.Tab_9);
            app.SaveStabilizerControlSwitch_4Label.Text = 'Save Stabilizer Control';
            app.Ch4SaveStabilizerControlSwitch = uiswitch(app.Tab_9, 'slider');
            app.Ch4SaveStabilizerControlSwitch.ItemsData = {'0', '1'};
            app.Ch4SaveStabilizerControlSwitch.Enable = 'off';
            app.Ch4SaveStabilizerControlSwitch.Value = '0';
            app.SetStableChSwitch_4Label = uilabel(app.Tab_9);
            app.SetStableChSwitch_4Label.Text = 'Set Stable Ch. #';
            app.Ch4SetStableChSwitch = uiswitch(app.Tab_9, 'slider');
            app.Ch4SetStableChSwitch.ItemsData = {'0', '1'};
            app.Ch4SetStableChSwitch.ValueChangedFcn = createCallbackFcn(app, @Ch4SetStableChSwitchValueChanged, true);
            app.Ch4SetStableChSwitch.Enable = 'off';
            app.Ch4SetStableChSwitch.Value = '0';
            app.GammaWindowSliderLabel_5 = uilabel(app.Tab_9);
            app.GammaWindowSliderLabel_5.Text = 'Lower Thresh.';
            app.Ch4GammaEditField_Lower = uieditfield(app.Tab_9, 'numeric');
            app.Ch4GammaEditField_Lower.Limits = [0 1000];
            app.BandPassFilterPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.BandPassFilterPanel.Title = 'Band-Pass Filter';
            app.TabGroup3 = uitabgroup(app.BandPassFilterPanel);
            app.Channels12Tab_2 = uitab(app.TabGroup3);
            app.Channels12Tab_2.Title = 'Channels 1 & 2';
            app.FFTFilterCh1Upper = uieditfield(app.Channels12Tab_2, 'numeric');
            app.FFTFilterCh1Upper.ValueChangedFcn = createCallbackFcn(app, @FFTFilterCh1UpperValueChanged, true);
            app.FFTFilterCh1Upper.Editable = 'off';
            app.FFTFilterCh1Upper.Value = 50;
            app.Ch1Label = uilabel(app.Channels12Tab_2);
            app.Ch1Label.Text = 'Ch. 1';
            app.Ch2Label = uilabel(app.Channels12Tab_2);
            app.Ch2Label.Text = 'Ch. 2';
            app.FFTFilterCh2Upper = uieditfield(app.Channels12Tab_2, 'numeric');
            app.FFTFilterCh2Upper.ValueChangedFcn = createCallbackFcn(app, @FFTFilterCh2UpperValueChanged, true);
            app.FFTFilterCh2Upper.Editable = 'off';
            app.FFTFilterCh2Upper.Value = 10;
            app.FFTFilterCutoffFrequenciesMHzLabel = uilabel(app.Channels12Tab_2);
            app.FFTFilterCutoffFrequenciesMHzLabel.Text = 'FFT Filter Cutoff Frequencies (MHz)';
            app.FFTFilterPlot = uiaxes(app.Channels12Tab_2);
            title(app.FFTFilterPlot, 'Channel 2')
            xlabel(app.FFTFilterPlot, 'time')
            ylabel(app.FFTFilterPlot, 'voltage')
            app.PlotLPFButton = uibutton(app.Channels12Tab_2, 'push');
            app.PlotLPFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButtonPushed, true);
            app.PlotLPFButton.Text = 'Plot';
            app.FFTFilterPlot_2 = uiaxes(app.Channels12Tab_2);
            title(app.FFTFilterPlot_2, 'Channel 1')
            xlabel(app.FFTFilterPlot_2, 'time')
            ylabel(app.FFTFilterPlot_2, 'voltage')
            app.PlotLPFButtonNext = uibutton(app.Channels12Tab_2, 'push');
            app.PlotLPFButtonNext.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButtonNextPushed, true);
            app.PlotLPFButtonNext.Text = 'Plot Next';
            app.FFTFilterCh2Lower = uieditfield(app.Channels12Tab_2, 'numeric');
            app.FFTFilterCh2Lower.Editable = 'off';
            app.FFTFilterCh2Lower.Value = 0.0001;
            app.FFTFilterCh1Lower = uieditfield(app.Channels12Tab_2, 'numeric');
            app.FFTFilterCh1Lower.Editable = 'off';
            app.FFTFilterCh1Lower.Value = 0.3;
            app.Channels34Tab_2 = uitab(app.TabGroup3);
            app.Channels34Tab_2.Title = 'Channels 3 & 4';
            app.FFTFilterCh3Upper = uieditfield(app.Channels34Tab_2, 'numeric');
            app.FFTFilterCh3Upper.Editable = 'off';
            app.FFTFilterCh3Upper.Value = 50;
            app.Ch3Label = uilabel(app.Channels34Tab_2);
            app.Ch3Label.Text = 'Ch. 3';
            app.Ch4Label = uilabel(app.Channels34Tab_2);
            app.Ch4Label.Text = 'Ch. 4';
            app.FFTFilterCh4Upper = uieditfield(app.Channels34Tab_2, 'numeric');
            app.FFTFilterCh4Upper.Editable = 'off';
            app.FFTFilterCh4Upper.Value = 1000;
            app.FFTFilterCutoffFrequenciesMHzLabel_2 = uilabel(app.Channels34Tab_2);
            app.FFTFilterCutoffFrequenciesMHzLabel_2.Text = 'FFT Filter Cutoff Frequencies (MHz)';
            app.FFTFilterPlot_3 = uiaxes(app.Channels34Tab_2);
            title(app.FFTFilterPlot_3, 'Channel 4')
            xlabel(app.FFTFilterPlot_3, 'time')
            ylabel(app.FFTFilterPlot_3, 'voltage')
            app.PlotLPFButton_2 = uibutton(app.Channels34Tab_2, 'push');
            app.PlotLPFButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButton_2Pushed, true);
            app.PlotLPFButton_2.Text = 'Plot';
            app.FFTFilterPlot_4 = uiaxes(app.Channels34Tab_2);
            title(app.FFTFilterPlot_4, 'Channel 3')
            xlabel(app.FFTFilterPlot_4, 'time')
            ylabel(app.FFTFilterPlot_4, 'voltage')
            app.PlotLPFButtonNext_2 = uibutton(app.Channels34Tab_2, 'push');
            app.PlotLPFButtonNext_2.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButtonNext_2Pushed, true);
            app.PlotLPFButtonNext_2.Text = 'Plot Next';
            app.FFTFilterCh4Lower = uieditfield(app.Channels34Tab_2, 'numeric');
            app.FFTFilterCh4Lower.Editable = 'off';
            app.FFTFilterCh4Lower.Value = 1e-05;
            app.FFTFilterCh3Lower = uieditfield(app.Channels34Tab_2, 'numeric');
            app.FFTFilterCh3Lower.Editable = 'off';
            app.FFTFilterCh3Lower.Value = 1;
            app.FFTFilterSwitch = uiswitch(app.BandPassFilterPanel, 'slider');
            app.FFTFilterSwitch.ItemsData = {'0', '1'};
            app.FFTFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FFTFilterSwitchValueChanged, true);
            app.FFTFilterSwitch.Value = '0';
            app.ShapingFittingPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.ShapingFittingPanel.Title = 'Shaping / Fitting';
            app.TabGroup5 = uitabgroup(app.ShapingFittingPanel);
            app.Tab_5 = uitab(app.TabGroup5);
            app.Tab_5.Title = '1';
            app.RiseFallLabel = uilabel(app.Tab_5);
            app.RiseFallLabel.Text = 'Rise/Fall';
            app.TopLabel = uilabel(app.Tab_5);
            app.TopLabel.Text = 'Top';
            app.Ch1TrapezoidalFilterTop = uieditfield(app.Tab_5, 'numeric');
            app.Ch1TrapezoidalFilterTop.ValueChangedFcn = createCallbackFcn(app, @Ch1TrapezoidalFilterTopValueChanged, true);
            app.Ch1TrapezoidalFilterTop.Editable = 'off';
            app.Ch1TrapezoidalFilterTop.Enable = 'off';
            app.Ch1TrapezoidalFilterTop.Value = 5;
            app.ShapingConstantssLabel_2 = uilabel(app.Tab_5);
            app.ShapingConstantssLabel_2.Text = 'Shaping Constants (μs)';
            app.Ch1TrapezoidalFilterRiseFall = uieditfield(app.Tab_5, 'numeric');
            app.Ch1TrapezoidalFilterRiseFall.ValueChangedFcn = createCallbackFcn(app, @Ch1TrapezoidalFilterRiseFallValueChanged, true);
            app.Ch1TrapezoidalFilterRiseFall.Editable = 'off';
            app.Ch1TrapezoidalFilterRiseFall.Enable = 'off';
            app.TrapezoidalFilterPlot = uiaxes(app.Tab_5);
            title(app.TrapezoidalFilterPlot, 'Channel 2')
            xlabel(app.TrapezoidalFilterPlot, 'X')
            ylabel(app.TrapezoidalFilterPlot, 'Y')
            app.TrapezoidalFilterButton = uibutton(app.Tab_5, 'push');
            app.TrapezoidalFilterButton.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButtonPushed, true);
            app.TrapezoidalFilterButton.Enable = 'off';
            app.TrapezoidalFilterButton.Text = 'Plot';
            app.TrapezoidalFilterButtonNext = uibutton(app.Tab_5, 'push');
            app.TrapezoidalFilterButtonNext.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButtonNextPushed, true);
            app.TrapezoidalFilterButtonNext.Enable = 'off';
            app.TrapezoidalFilterButtonNext.Text = 'Plot Next';
            app.Tab_6 = uitab(app.TabGroup5);
            app.Tab_6.Title = '2';
            app.RiseFallLabel_2 = uilabel(app.Tab_6);
            app.RiseFallLabel_2.Text = 'Rise/Fall';
            app.TopLabel_2 = uilabel(app.Tab_6);
            app.TopLabel_2.Text = 'Top';
            app.Ch2TrapezoidalFilterTop = uieditfield(app.Tab_6, 'numeric');
            app.Ch2TrapezoidalFilterTop.Value = 4.75;
            app.ShapingConstantssLabel_3 = uilabel(app.Tab_6);
            app.ShapingConstantssLabel_3.Text = 'Shaping Constants (μs)';
            app.Ch2TrapezoidalFilterRiseFall = uieditfield(app.Tab_6, 'numeric');
            app.Ch2TrapezoidalFilterRiseFall.Editable = 'off';
            app.Ch2TrapezoidalFilterRiseFall.Enable = 'off';
            app.TrapezoidalFilterPlot_2 = uiaxes(app.Tab_6);
            title(app.TrapezoidalFilterPlot_2, 'Channel 2')
            xlabel(app.TrapezoidalFilterPlot_2, 'X')
            ylabel(app.TrapezoidalFilterPlot_2, 'Y')
            app.TrapezoidalFilterButton_2 = uibutton(app.Tab_6, 'push');
            app.TrapezoidalFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButton_2Pushed, true);
            app.TrapezoidalFilterButton_2.Text = 'Plot';
            app.TrapezoidalFilterButtonNext_2 = uibutton(app.Tab_6, 'push');
            app.TrapezoidalFilterButtonNext_2.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButtonNext_2Pushed, true);
            app.TrapezoidalFilterButtonNext_2.Text = 'Plot Next';
            app.Tab_7 = uitab(app.TabGroup5);
            app.Tab_7.Title = '3';
            app.RiseFallLabel_4 = uilabel(app.Tab_7);
            app.RiseFallLabel_4.Text = 'Rise/Fall';
            app.TopLabel_4 = uilabel(app.Tab_7);
            app.TopLabel_4.Text = 'Top';
            app.Ch3TrapezoidalFilterTop = uieditfield(app.Tab_7, 'numeric');
            app.Ch3TrapezoidalFilterTop.Editable = 'off';
            app.Ch3TrapezoidalFilterTop.Enable = 'off';
            app.Ch3TrapezoidalFilterTop.Value = 5;
            app.ShapingConstantssLabel_5 = uilabel(app.Tab_7);
            app.ShapingConstantssLabel_5.Text = 'Shaping Constants (μs)';
            app.Ch3TrapezoidalFilterRiseFall = uieditfield(app.Tab_7, 'numeric');
            app.Ch3TrapezoidalFilterRiseFall.Editable = 'off';
            app.Ch3TrapezoidalFilterRiseFall.Enable = 'off';
            app.TrapezoidalFilterPlot_3 = uiaxes(app.Tab_7);
            title(app.TrapezoidalFilterPlot_3, 'Channel 2')
            xlabel(app.TrapezoidalFilterPlot_3, 'X')
            ylabel(app.TrapezoidalFilterPlot_3, 'Y')
            app.TrapezoidalFilterButton_3 = uibutton(app.Tab_7, 'push');
            app.TrapezoidalFilterButton_3.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButton_3Pushed, true);
            app.TrapezoidalFilterButton_3.Enable = 'off';
            app.TrapezoidalFilterButton_3.Text = 'Plot';
            app.TrapezoidalFilterButtonNext_3 = uibutton(app.Tab_7, 'push');
            app.TrapezoidalFilterButtonNext_3.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButtonNext_3Pushed, true);
            app.TrapezoidalFilterButtonNext_3.Enable = 'off';
            app.TrapezoidalFilterButtonNext_3.Text = 'Plot Next';
            app.Tab_8 = uitab(app.TabGroup5);
            app.Tab_8.Title = '4';
            app.RiseFallLabel_3 = uilabel(app.Tab_8);
            app.RiseFallLabel_3.Text = 'Rise/Fall';
            app.TopLabel_3 = uilabel(app.Tab_8);
            app.TopLabel_3.Text = 'Top';
            app.Ch4TrapezoidalFilterTop = uieditfield(app.Tab_8, 'numeric');
            app.Ch4TrapezoidalFilterTop.Editable = 'off';
            app.Ch4TrapezoidalFilterTop.Enable = 'off';
            app.Ch4TrapezoidalFilterTop.Value = 5;
            app.ShapingConstantssLabel_4 = uilabel(app.Tab_8);
            app.ShapingConstantssLabel_4.Text = 'Shaping Constants (μs)';
            app.Ch4TrapezoidalFilterRiseFall = uieditfield(app.Tab_8, 'numeric');
            app.Ch4TrapezoidalFilterRiseFall.Editable = 'off';
            app.Ch4TrapezoidalFilterRiseFall.Enable = 'off';
            app.TrapezoidalFilterPlot_4 = uiaxes(app.Tab_8);
            title(app.TrapezoidalFilterPlot_4, 'Channel 2')
            xlabel(app.TrapezoidalFilterPlot_4, 'X')
            ylabel(app.TrapezoidalFilterPlot_4, 'Y')
            app.TrapezoidalFilterButton_4 = uibutton(app.Tab_8, 'push');
            app.TrapezoidalFilterButton_4.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButton_4Pushed, true);
            app.TrapezoidalFilterButton_4.Enable = 'off';
            app.TrapezoidalFilterButton_4.Text = 'Plot';
            app.TrapezoidalFilterButtonNext_4 = uibutton(app.Tab_8, 'push');
            app.TrapezoidalFilterButtonNext_4.ButtonPushedFcn = createCallbackFcn(app, @TrapezoidalFilterButtonNext_4Pushed, true);
            app.TrapezoidalFilterButtonNext_4.Enable = 'off';
            app.TrapezoidalFilterButtonNext_4.Text = 'Plot Next';
            app.RiseTimeFilterPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.RiseTimeFilterPanel.Title = 'Rise Time Filter';
            app.UpperLabel = uilabel(app.RiseTimeFilterPanel);
            app.UpperLabel.Text = 'Upper';
            app.RiseTimeFilterUpper = uieditfield(app.RiseTimeFilterPanel, 'numeric');
            app.RiseTimeFilterUpper.ValueChangedFcn = createCallbackFcn(app, @RiseTimeFilterUpperValueChanged, true);
            app.RiseTimeFilterUpper.Value = 300;
            app.LowerLabel = uilabel(app.RiseTimeFilterPanel);
            app.LowerLabel.Text = 'Lower';
            app.RiseTimeFilterLower = uieditfield(app.RiseTimeFilterPanel, 'numeric');
            app.RiseTimeFilterLower.ValueChangedFcn = createCallbackFcn(app, @RiseTimeFilterLowerValueChanged, true);
            app.ChannelDropDownLabel = uilabel(app.RiseTimeFilterPanel);
            app.ChannelDropDownLabel.Text = 'Channel #';
            app.ChannelDropDown = uidropdown(app.RiseTimeFilterPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.Value = '2';
            app.CutOffValuesnsLabel = uilabel(app.RiseTimeFilterPanel);
            app.CutOffValuesnsLabel.Text = 'Cut-Off Values (ns)';
            app.RejectionField1 = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.RejectionField1.Editable = 'off';
            app.RejectionField2 = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.RejectionField2.Editable = 'off';
            app.RejectionRatioLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.RejectionRatioLabel.Text = {'Rejection '; 'Ratio (%)'};
            app.TabGroup2 = uitabgroup(app.PositroninducedElectronGammaUIFigure);
            app.Plots12Tab = uitab(app.TabGroup2);
            app.Plots12Tab.Title = 'Plots 1 & 2';
            app.Plot1 = uiaxes(app.Plots12Tab);
            title(app.Plot1, 'Time of Flight')
            xlabel(app.Plot1, 'Time of Flight (s)')
            ylabel(app.Plot1, 'Intensity')
            app.Plot2 = uiaxes(app.Plots12Tab);
            title(app.Plot2, 'Gamma Energy')
            xlabel(app.Plot2, 'Energy (ch. #)')
            ylabel(app.Plot2, 'Intensity')
            app.Plots34Tab = uitab(app.TabGroup2);
            app.Plots34Tab.Title = 'Plots 3 & 4';
            app.Plot3 = uiaxes(app.Plots34Tab);
            title(app.Plot3, 'NC')
            xlabel(app.Plot3, 'X')
            ylabel(app.Plot3, 'Y')
            app.Plot4 = uiaxes(app.Plots34Tab);
            title(app.Plot4, 'NC')
            xlabel(app.Plot4, 'X')
            ylabel(app.Plot4, 'Y')
            app.RejectionField3 = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.RejectionField3.Editable = 'off';
            app.RejectionField4 = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.RejectionField4.Editable = 'off';
            app.ChannelConfigurationPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.ChannelConfigurationPanel.Title = 'Channel Configuration';
            app.TabGroup6 = uitabgroup(app.ChannelConfigurationPanel);
            app.Tab = uitab(app.TabGroup6);
            app.Tab.Title = '1';
            app.Channel1Type = uidropdown(app.Tab);
            app.Channel1Type.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.Channel1Type.ItemsData = {'1', '2', '3', '4'};
            app.Channel1Type.ValueChangedFcn = createCallbackFcn(app, @Channel1TypeValueChanged, true);
            app.Channel1Type.Value = '1';
            app.ChannelTypeLabel = uilabel(app.Tab);
            app.ChannelTypeLabel.Text = 'Channel Type';
            app.TimingTypeDropDownLabel = uilabel(app.Tab);
            app.TimingTypeDropDownLabel.Text = 'Timing Type';
            app.Ch1Timing = uidropdown(app.Tab);
            app.Ch1Timing.Items = {'ELET', 'CFD', 'Trapezoidal', 'ANN', 'None'};
            app.Ch1Timing.ItemsData = {'0', '1', '2', '4', '3'};
            app.Ch1Timing.ValueChangedFcn = createCallbackFcn(app, @Ch1TimingValueChanged, true);
            app.Ch1Timing.Value = '1';
            app.Ch1Shaping = uidropdown(app.Tab);
            app.Ch1Shaping.Items = {'Recursive Trapezoidal', 'Moving Window Deconvolution', 'Manual Convolution (Trapezoidal)', 'Moving Average Semi-Gaussian', 'Max Value', 'Maximum Likelihood', 'Fitting', 'Neural Network', 'None'};
            app.Ch1Shaping.ItemsData = {'1', '0', '2', '3', '4', '5', '7', '10', '8'};
            app.Ch1Shaping.ValueChangedFcn = createCallbackFcn(app, @Ch1ShapingValueChanged, true);
            app.Ch1Shaping.Enable = 'off';
            app.Ch1Shaping.Value = '8';
            app.ShapingTypeLabel = uilabel(app.Tab);
            app.ShapingTypeLabel.Text = 'Shaping Type';
            app.ofBinsEditFieldLabel_2 = uilabel(app.Tab);
            app.ofBinsEditFieldLabel_2.Text = '# of Bins';
            app.BinsEditField1 = uieditfield(app.Tab, 'numeric');
            app.BinsEditField1.Value = 4096;
            app.CFDValueofMagLabel = uilabel(app.Tab);
            app.CFDValueofMagLabel.Text = 'CFD  Value (% of Mag.)';
            app.Ch1ELETLower = uieditfield(app.Tab, 'numeric');
            app.Ch1ELETLower.Value = 42;
            app.Ch1ELETUpper = uieditfield(app.Tab, 'numeric');
            app.Ch1ELETUpper.Enable = 'off';
            app.Ch1ELETUpper.Value = 9;
            app.ValueLabel = uilabel(app.Tab);
            app.ValueLabel.Text = 'Value';
            app.UpperLabel_2 = uilabel(app.Tab);
            app.UpperLabel_2.Text = 'Upper';
            app.Tab_2 = uitab(app.TabGroup6);
            app.Tab_2.Title = '2';
            app.Channel2Type = uidropdown(app.Tab_2);
            app.Channel2Type.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.Channel2Type.ItemsData = {'1', '2', '3', '4'};
            app.Channel2Type.ValueChangedFcn = createCallbackFcn(app, @Channel2TypeValueChanged, true);
            app.Channel2Type.Value = '3';
            app.ChannelTypeLabel_2 = uilabel(app.Tab_2);
            app.ChannelTypeLabel_2.Text = 'Channel Type';
            app.Ch2Shaping = uidropdown(app.Tab_2);
            app.Ch2Shaping.Items = {'Recursive Trapezoidal', 'Moving Window Deconvolution', 'Manual Convolution (Trapezoidal)', 'Moving Average Semi-Gaussian', 'Max Value', 'Maximum Likelihood', 'Fitting', 'Neural Network', 'None'};
            app.Ch2Shaping.ItemsData = {'1', '0', '2', '3', '4', '5', '7', '10', '8'};
            app.Ch2Shaping.ValueChangedFcn = createCallbackFcn(app, @Ch2ShapingValueChanged, true);
            app.Ch2Shaping.Value = '0';
            app.ShapingTypeLabel_3 = uilabel(app.Tab_2);
            app.ShapingTypeLabel_3.Text = 'Shaping Type';
            app.TimingTypeDropDown_2Label = uilabel(app.Tab_2);
            app.TimingTypeDropDown_2Label.Text = 'Timing Type';
            app.Ch2Timing = uidropdown(app.Tab_2);
            app.Ch2Timing.Items = {'ELET', 'CFD', 'Trapezoidal', 'ANN', 'None'};
            app.Ch2Timing.ItemsData = {'0', '1', '2', '4', '3'};
            app.Ch2Timing.ValueChangedFcn = createCallbackFcn(app, @Ch2TimingValueChanged, true);
            app.Ch2Timing.Value = '0';
            app.ofBinsEditFieldLabel = uilabel(app.Tab_2);
            app.ofBinsEditFieldLabel.Text = '# of Bins';
            app.BinsEditField2 = uieditfield(app.Tab_2, 'numeric');
            app.BinsEditField2.Value = 4096;
            app.ELETValuesofMagLabel_2 = uilabel(app.Tab_2);
            app.ELETValuesofMagLabel_2.Text = 'ELET  Values (% of Mag.)';
            app.Ch2ELETLower = uieditfield(app.Tab_2, 'numeric');
            app.Ch2ELETLower.Value = 7;
            app.Ch2ELETUpper = uieditfield(app.Tab_2, 'numeric');
            app.Ch2ELETUpper.Value = 9;
            app.LowerLabel_4 = uilabel(app.Tab_2);
            app.LowerLabel_4.Text = 'Lower';
            app.UpperLabel_3 = uilabel(app.Tab_2);
            app.UpperLabel_3.Text = 'Upper';
            app.Tab_3 = uitab(app.TabGroup6);
            app.Tab_3.Title = '3';
            app.Channel3Type = uidropdown(app.Tab_3);
            app.Channel3Type.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.Channel3Type.ItemsData = {'1', '2', '3', '4'};
            app.Channel3Type.ValueChangedFcn = createCallbackFcn(app, @Channel3TypeValueChanged, true);
            app.Channel3Type.Value = '4';
            app.ChannelTypeLabel_3 = uilabel(app.Tab_3);
            app.ChannelTypeLabel_3.Text = 'Channel Type';
            app.ShapingTypeLabel_4 = uilabel(app.Tab_3);
            app.ShapingTypeLabel_4.Text = 'Shaping Type';
            app.TimingTypeDropDown_3Label = uilabel(app.Tab_3);
            app.TimingTypeDropDown_3Label.Text = 'Timing Type';
            app.Ch3Timing = uidropdown(app.Tab_3);
            app.Ch3Timing.Items = {'ELET', 'CFD', 'Trapezoidal', 'ANN', 'None'};
            app.Ch3Timing.ItemsData = {'0', '1', '2', '4', '3'};
            app.Ch3Timing.ValueChangedFcn = createCallbackFcn(app, @Ch3TimingValueChanged, true);
            app.Ch3Timing.Enable = 'off';
            app.Ch3Timing.Value = '3';
            app.Ch3Shaping = uidropdown(app.Tab_3);
            app.Ch3Shaping.Items = {'Recursive Trapezoidal', 'Moving Window Deconvolution', 'Manual Convolution (Trapezoidal)', 'Moving Average Semi-Gaussian', 'Max Value', 'Maximum Likelihood', 'Fitting', 'Neural Network', 'None'};
            app.Ch3Shaping.ItemsData = {'1', '0', '2', '3', '4', '5', '7', '10', '8'};
            app.Ch3Shaping.ValueChangedFcn = createCallbackFcn(app, @Ch3ShapingValueChanged, true);
            app.Ch3Shaping.Enable = 'off';
            app.Ch3Shaping.Value = '8';
            app.ofBinsEditFieldLabel_3 = uilabel(app.Tab_3);
            app.ofBinsEditFieldLabel_3.Text = '# of Bins';
            app.BinsEditField3 = uieditfield(app.Tab_3, 'numeric');
            app.BinsEditField3.Value = 4096;
            app.ELETValuesofMagLabel_3 = uilabel(app.Tab_3);
            app.ELETValuesofMagLabel_3.Text = 'ELET  Values (% of Mag.)';
            app.Ch3ELETLower = uieditfield(app.Tab_3, 'numeric');
            app.Ch3ELETLower.Enable = 'off';
            app.Ch3ELETLower.Value = 7;
            app.Ch3ELETUpper = uieditfield(app.Tab_3, 'numeric');
            app.Ch3ELETUpper.Enable = 'off';
            app.Ch3ELETUpper.Value = 9;
            app.LowerLabel_5 = uilabel(app.Tab_3);
            app.LowerLabel_5.Text = 'Lower';
            app.UpperLabel_4 = uilabel(app.Tab_3);
            app.UpperLabel_4.Text = 'Upper';
            app.Tab_4 = uitab(app.TabGroup6);
            app.Tab_4.Title = '4';
            app.Channel4Type = uidropdown(app.Tab_4);
            app.Channel4Type.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.Channel4Type.ItemsData = {'1', '2', '3', '4'};
            app.Channel4Type.ValueChangedFcn = createCallbackFcn(app, @Channel4TypeValueChanged, true);
            app.Channel4Type.Value = '4';
            app.ChannelTypeLabel_4 = uilabel(app.Tab_4);
            app.ChannelTypeLabel_4.Text = 'Channel Type';
            app.ShapingTypeLabel_5 = uilabel(app.Tab_4);
            app.ShapingTypeLabel_5.Text = 'Shaping Type';
            app.TimingTypeDropDown_4Label = uilabel(app.Tab_4);
            app.TimingTypeDropDown_4Label.Text = 'Timing Type';
            app.Ch4Timing = uidropdown(app.Tab_4);
            app.Ch4Timing.Items = {'ELET', 'CFD', 'Trapezoidal', 'ANN', 'None'};
            app.Ch4Timing.ItemsData = {'0', '1', '2', '4', '3'};
            app.Ch4Timing.ValueChangedFcn = createCallbackFcn(app, @Ch4TimingValueChanged, true);
            app.Ch4Timing.Enable = 'off';
            app.Ch4Timing.Value = '3';
            app.Ch4Shaping = uidropdown(app.Tab_4);
            app.Ch4Shaping.Items = {'Recursive Trapezoidal', 'Moving Window Deconvolution', 'Manual Convolution (Trapezoidal)', 'Moving Average Semi-Gaussian', 'Max Value', 'Maximum Likelihood', 'Fitting', 'Neural Network', 'None'};
            app.Ch4Shaping.ItemsData = {'1', '0', '2', '3', '4', '5', '7', '10', '8'};
            app.Ch4Shaping.ValueChangedFcn = createCallbackFcn(app, @Ch4ShapingValueChanged, true);
            app.Ch4Shaping.Enable = 'off';
            app.Ch4Shaping.Value = '8';
            app.ofBinsEditFieldLabel_4 = uilabel(app.Tab_4);
            app.ofBinsEditFieldLabel_4.Text = '# of Bins';
            app.BinsEditField4 = uieditfield(app.Tab_4, 'numeric');
            app.BinsEditField4.Value = 4096;
            app.ELETValuesofMagLabel_4 = uilabel(app.Tab_4);
            app.ELETValuesofMagLabel_4.Text = 'ELET  Values (% of Mag.)';
            app.Ch4ELETLower = uieditfield(app.Tab_4, 'numeric');
            app.Ch4ELETLower.Enable = 'off';
            app.Ch4ELETLower.Value = 7;
            app.Ch4ELETUpper = uieditfield(app.Tab_4, 'numeric');
            app.Ch4ELETUpper.Enable = 'off';
            app.Ch4ELETUpper.Value = 9;
            app.LowerLabel_6 = uilabel(app.Tab_4);
            app.LowerLabel_6.Text = 'Lower';
            app.UpperLabel_5 = uilabel(app.Tab_4);
            app.UpperLabel_5.Text = 'Upper';
            app.GEGammaChannelDropDownLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.GEGammaChannelDropDownLabel.Text = {'G-E Gamma'; 'Channel'};
            app.PEGSGammaChannelDropDown = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.PEGSGammaChannelDropDown.Items = {'1', '2', '3', '4', 'None'};
            app.PEGSGammaChannelDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.PEGSGammaChannelDropDown.Value = '2';
            app.GammaGammaCoincidenceChannelsDropDownLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.GammaGammaCoincidenceChannelsDropDownLabel.Text = {'Gamma-Gamma'; 'Coincidence'; 'Channels'};
            app.GGCChannel_1 = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.GGCChannel_1.Items = {'1', '2', '3', '4', 'None'};
            app.GGCChannel_1.ItemsData = {'1', '2', '3', '4', '5'};
            app.GGCChannel_1.Value = '5';
            app.GGCChannel_2 = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.GGCChannel_2.Items = {'1', '2', '3', '4', 'None'};
            app.GGCChannel_2.ItemsData = {'1', '2', '3', '4', '5'};
            app.GGCChannel_2.Value = '5';
            app.Plot1Label = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Plot1Label.Text = 'Plot 1';
            app.Plot2Label = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Plot2Label.Text = 'Plot 2';
            app.Plot3Label = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Plot3Label.Text = 'Plot 3';
            app.Plot4Label = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Plot4Label.Text = 'Plot 4';
            app.ElectronCoincidenceLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.ElectronCoincidenceLabel.Text = {'2-Electron '; 'Coincidence'};
            app.ElectronCoincidenceSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.ElectronCoincidenceSwitch.ItemsData = {'0', '1'};
            app.ElectronCoincidenceSwitch.Value = '0';
            app.numSamplesT = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.numSamplesT.Limits = [0 100000000];
            app.numSamplesT.RoundFractionalValues = 'on';
            app.numSamplesT.ValueDisplayFormat = '%.0f';
            app.numSamplesT.Editable = 'off';
            app.numSamplesT.Value = 99999;
            app.TotalNumberofSamplesLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.TotalNumberofSamplesLabel.Text = {'Total Number of '; 'Samples'};
            % Show the figure after all components are created
            app.PositroninducedElectronGammaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PEGS_4Channel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PositroninducedElectronGammaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PositroninducedElectronGammaUIFigure)
        end
    end
end