classdef PEGS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PositroninducedElectronGammaUIFigure  matlab.ui.Figure
        OptionsMenu                     matlab.ui.container.Menu
        AnalysisTypeMenu                matlab.ui.container.Menu
        BinningMenu                     matlab.ui.container.Menu
        RiseTimesMenu                   matlab.ui.container.Menu
        PeakLocationMenu                matlab.ui.container.Menu
        CollectionTimeMenu              matlab.ui.container.Menu
        InitialSampleMenu               matlab.ui.container.Menu
        EnergyCalibrationMenu           matlab.ui.container.Menu
        MultiStopConditionsMenu         matlab.ui.container.Menu
        KDESpectraMenu                  matlab.ui.container.Menu
        StabilizationSettingsMenu       matlab.ui.container.Menu
        ShapingParameterOptimizationMenu  matlab.ui.container.Menu
        numSamples                      matlab.ui.control.NumericEditField
        NumberofSamplesLabel            matlab.ui.control.Label
        TracesFolderButton              matlab.ui.control.Button
        PercentageCompleteGaugeLabel    matlab.ui.control.Label
        PercentageCompleteGauge         matlab.ui.control.Gauge
        SavingFolderButton              matlab.ui.control.Button
        TracesFolder                    matlab.ui.control.EditField
        SavingFolder                    matlab.ui.control.EditField
        PlotsSwitchLabel                matlab.ui.control.Label
        PlotsSwitch                     matlab.ui.control.Switch
        PAUSEButton                     matlab.ui.control.StateButton
        ModeLampLabel                   matlab.ui.control.Label
        ModeLamp                        matlab.ui.control.Lamp
        DPlotSwitchLabel                matlab.ui.control.Label
        DPlotSwitch                     matlab.ui.control.Switch
        SAVECURRENTButton               matlab.ui.control.Button
        STARTButton_2                   matlab.ui.control.StateButton
        MultiFolderSwitchLabel          matlab.ui.control.Label
        MultiFolderSwitch               matlab.ui.control.Switch
        AnalysisRateHzLabel             matlab.ui.control.Label
        RateGauge                       matlab.ui.control.SemicircularGauge
        TabGroup                        matlab.ui.container.TabGroup
        ToFSpectrumControlsTab          matlab.ui.container.Tab
        TimingWindowsKnobLabel          matlab.ui.control.Label
        TimingWindowsKnob               matlab.ui.control.DiscreteKnob
        MultiStopSwitchLabel            matlab.ui.control.Label
        MultiStopSwitch                 matlab.ui.control.Switch
        TrapezoidalFilterTop_Fast       matlab.ui.control.NumericEditField
        TrapezoidalFilterRiseFall_Fast  matlab.ui.control.NumericEditField
        TrapTimingFastLabel             matlab.ui.control.Label
        GammaTimingLabel                matlab.ui.control.Label
        Ch2DropDownTiming               matlab.ui.control.DropDown
        GammaSpectrumControlsTab        matlab.ui.container.Tab
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaWindowSliderLabel          matlab.ui.control.Label
        GammaStableCh                   matlab.ui.control.NumericEditField
        GammaStableChLabel              matlab.ui.control.Label
        GammaStabilizerSwitchLabel      matlab.ui.control.Label
        GammaStabilizerSwitch           matlab.ui.control.Switch
        SaveStabilizerControlSwitchLabel  matlab.ui.control.Label
        SaveStabilizerControlSwitch     matlab.ui.control.Switch
        SetStableChSwitchLabel          matlab.ui.control.Label
        SetStableChSwitch               matlab.ui.control.Switch
        FFTFilterPanel                  matlab.ui.container.Panel
        FFTFilterCh1                    matlab.ui.control.NumericEditField
        Ch1Label                        matlab.ui.control.Label
        Ch2Label                        matlab.ui.control.Label
        FFTFilterCh2                    matlab.ui.control.NumericEditField
        FFTFilterCutoffFrequenciesMHzLabel  matlab.ui.control.Label
        FFTFilterPlot                   matlab.ui.control.UIAxes
        PlotLPFButton                   matlab.ui.control.Button
        FFTFilterPlot_2                 matlab.ui.control.UIAxes
        PlotLPFButtonNext               matlab.ui.control.Button
        FFTFilterCh2Lower               matlab.ui.control.NumericEditField
        FFTFilterCh1Lower               matlab.ui.control.NumericEditField
        FFTFilterSwitch                 matlab.ui.control.Switch
        ShapingFittingPanel             matlab.ui.container.Panel
        RiseFallLabel                   matlab.ui.control.Label
        TopLabel                        matlab.ui.control.Label
        TrapezoidalFilterTop            matlab.ui.control.NumericEditField
        EnergySlowsLabel                matlab.ui.control.Label
        TrapezoidalFilterRiseFall       matlab.ui.control.NumericEditField
        TrapezoidalFilterPlot           matlab.ui.control.UIAxes
        PlotTrapezoidalFilterButton     matlab.ui.control.Button
        PlotTrapezoidalFilterButtonNext  matlab.ui.control.Button
        GammaShapingLabel               matlab.ui.control.Label
        Ch2DropDownShaping              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        DropDown_3                      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        ChannelTypesLabel               matlab.ui.control.Label
        RiseTimeFilterPanel             matlab.ui.container.Panel
        UppernsLabel                    matlab.ui.control.Label
        RiseTimeFilterUpper             matlab.ui.control.NumericEditField
        LowernsLabel                    matlab.ui.control.Label
        RiseTimeFilterLower             matlab.ui.control.NumericEditField
        ChannelLabel                    matlab.ui.control.Label
        TimingEditFieldLabel            matlab.ui.control.Label
        TimingEditField                 matlab.ui.control.NumericEditField
        EnergyEditFieldLabel            matlab.ui.control.Label
        EnergyEditField                 matlab.ui.control.NumericEditField
        RejectionRatioLabel             matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        Channels12Tab                   matlab.ui.container.Tab
        ToFPlot                         matlab.ui.control.UIAxes
        GePlot                          matlab.ui.control.UIAxes
        Channels34Tab                   matlab.ui.container.Tab
        ToFPlot_2                       matlab.ui.control.UIAxes
        GePlot_2                        matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        % Plotting function for spectra that can be used throughout the app.
        
        function plots(app, event)
            
            % Defining global variables in order to send to plot() function.
            
            global ToF;
            global Ge;
            global xdataToF;
            global xdataGe;
            global ToFPlotting;
            global GePlotting;
            global AugerGammaPlotting;
            global ax;
            global gammaVsToF;
            global numBinsGe;
            global numBinsToF;
            global analysisType;
            
            if isempty(numBinsGe)
                numBinsGe = 4096;
            end
            
            if isempty(numBinsToF)
                numBinsToF = 4096;
            end
            
            if event == 1
                
                GePlotting = plot(app.GePlot,xdataGe(5:numBinsGe-5),Ge(5:numBinsGe-5));
                GePlotting.XDataSource = 'xdataGe';
                GePlotting.YDataSource = 'Ge';
                
            elseif event == 0
                
                if isempty(analysisType) == 1 || analysisType == 1 || exist('analysisType') == 0
                    GePlotting = plot(app.GePlot,xdataGe(5:numBinsGe-5),Ge(5:numBinsGe-5));
                    GePlotting.XDataSource = 'xdataGe';
                    GePlotting.YDataSource = 'Ge';
                    
                    ToFPlotting = plot(app.ToFPlot,xdataToF(5:numBinsToF-5),ToF(5:numBinsToF-5));
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                    
                elseif analysisType == 2
                    ToFPlotting = plot(app.ToFPlot,xdataToF(5:numBinsToF-5),ToF(5:numBinsToF-5));
                    ToFPlotting.XDataSource = 'xdataToF';
                    ToFPlotting.YDataSource = 'ToF';
                    
                elseif analysisType == 3
                    GePlotting = plot(app.GePlot,xdataGe(5:numBinsGe-5),Ge(5:numBinsGe-5));
                    GePlotting.XDataSource = 'xdataGe';
                    GePlotting.YDataSource = 'Ge';
                    
                end
                
                % Ignore these warnings.
                
                if app.DPlotSwitch.Value == '1'
                    f = figure;
                    ax = axes(f);
                    AugerGammaPlotting = surf(ax,gammaVsToF);
                    linkdata on;
                    shading interp;
                    xlim(ax,[1 500]);
                    ylim(ax,[1000 3000]);
                    zlim(ax,[1 30]);
                    view(ax,[45 45]);
                    
                end
                
            elseif event == 2
                if app.DPlotSwitch.Value == '1'
                    f = figure;
                    ax = axes(f);
                    AugerGammaPlotting = surf(ax,gammaVsToF);
                    linkdata on;
                    shading interp;
                    xlim(ax,[1 500]);
                    ylim(ax,[1000 3000]);
                    zlim(ax,[1 30]);
                    view(ax,[45 45]);
                    
                end
                
            end
            %AugerGammaPlotting.ZDataSource = 'gammaVsToF';
            %AugerGammaPlotting.YDataSource = 'xdataToF';
            %AugerGammaPlotting.XDataSource = 'xdataGe';
            %linkdata on
            %{
                linkdata(app.ToFPlot);
                linkdata(app.GePlot);
                linkdata(app.AugerGammaPlot);
            %}
            
        end
        
        % ////////////////////////////////////////////////////////////////////////////
        % ----------------------------------------------------------------------------
        % Analysis function.
        % ----------------------------------------------------------------------------
        % ////////////////////////////////////////////////////////////////////////////
      
        function results = analysis(app)
            %try
                tic
                
                % Defining globals used between the functions of the app.
                global ToF;
                global Ge;
                global gammaVsToF;
                global selectPath;
                global savePath;
                global xdataToF;
                global xdataGe;
                global GePlotting;
                global ToFPlotting;
                global ax;
                global numBinsGe;
                global numBinsToF;
                global analysisType;
                global peakStart;
                global initialSample;
                global multiStopConditions;
                %global LPFCounter;
                %global trapezoidalFilterCounter;
                global kdeSpectra;
                global timeOfFlightKde;
                global geOutMaxKde;
                global stableLength;
                global control;
                global gammaMaxChange;
                %global runThrough;
                
                numBinsGeLocal = numBinsGe;
                numBinsToFLocal = numBinsToF;
                app.STARTButton_2.BackgroundColor = [1 1 0];
                app.STARTButton_2.Text = 'STARTING...';
                app.ModeLamp.Color = [1 1 0];
                drawnow;
                
                % Checking if a path for traces is selected.
                %{
                if isempty(selectPath) || exist(selectPath,'dir') ~= 7
                    selectPathOriginal = app.TracesFolder.Value;
                    
                    errordlg('No current traces path. Select a folder containing traces.');
                    selectPath = uigetdir('','Select a folder containing traces.');
                    
                    selectPath2 = selectPath;
                    
                    if isempty(selectPathOriginal) || isnumeric(selectPath) == 1
                        if isnumeric(selectPath2) == 1 && isempty(selectPathOriginal)
                            while isnumeric(selectPath2) == 1
                                errordlg('No current traces path. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        elseif isnumeric(selectPath) == 1 && isempty(selectPathOriginal) == 0
                            selectPath = selectPathOriginal;
                            
                        elseif exist(selectPath2,'dir') ~= 7
                            while exist(selectPath2,'dir') ~= 7
                                errordlg('Folder does not exist. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        end
                        
                    else
                        app.TracesFolder.Value = selectPath;
                    end
                end
                %}
                selectPath
                savePath
                app.checkPaths(selectPath,savePath,2);
                
                % Default peak start variable for gamma stabilization.
                if isempty(peakStart) == 1 || exist('peakStart') == 0
                    peakStart = 1;
                end
                
                % Assigning app inputs to variables so that they cannot be accidentally changed during analysis.
                app.TracesFolder.Value = selectPath;
                app.SavingFolder.Value = savePath;
                FFTFilterCh1Input = app.FFTFilterCh1.Value*10.^6;
                FFTFilterCh2Input = app.FFTFilterCh2.Value*10.^6;
                FFTFilterCh1LowerInput = app.FFTFilterCh1Lower.Value*10.^6;
                FFTFilterCh2LowerInput = app.FFTFilterCh2Lower.Value*10.^6;
                riseTimeFilterUpperInput = app.RiseTimeFilterUpper.Value*10.^(-9);
                riseTimeFilterLowerInput = app.RiseTimeFilterLower.Value*10.^(-9);
                trapezoidalFilterTopInput = app.TrapezoidalFilterTop.Value*10.^(-6);
                trapezoidalFilterRiseFallInput = app.TrapezoidalFilterRiseFall.Value*10.^(-6);
                ch2Timing = str2double(app.Ch2DropDownTiming.Value);
                ch2Shaping = str2double(app.Ch2DropDownShaping.Value);
                timingWindow = str2double(app.TimingWindowsKnob.Value);
                gammaWindow = app.GammaEditField.Value;
                stableSwitch = app.GammaStabilizerSwitch.Value;
                multiStop = app.MultiStopSwitch.Value;
                trapezoidalFilterTopInputFast = app.TrapezoidalFilterTop_Fast.Value*10.^(-6);
                trapezoidalFilterRiseFallInputFast = app.TrapezoidalFilterRiseFall_Fast.Value*10.^(-6);
                gammaStableCh = app.GammaStableCh.Value;
                BPFSwitch = app.FFTFilterSwitch.Value;
                geOutMax = 0;
                timeOfFlight2 = 0;
                folderNumber = 1;
                geOutMaxKde = 0;
                timeOfFlightKde = 0;
                timeOfFlight5 = 0;
                gammaMax = 0;
                rejectionTime = 0;
                rejectionEnergy = 0;
                
                %ch1Polarity = app.Channel1PolarityDropDown.Value;
                %ch2Polarity = app.Channel2PolarityDropDown.Value;
                
                if (app.SaveStabilizerControlSwitch.Value == '0' && app.MultiFolderSwitch.Value == '0') ...
                        || isempty(control) == true
                    control = 0;
                end
                
                % Default initial sample.
                if isempty(initialSample) == 1 || exist('initialSample') == 0
                    i = 1;
                else
                    i = initialSample;
                end
                
                % Default analysis type.
                if isempty(analysisType) == 1 || exist('analysisType') == 0
                    analysisType = 1;
                end
                
                % Default numbers of bins.
                if isempty(numBinsGeLocal) || exist('numBinsGeLocal') == 0
                    numBinsGeLocal = 4096;
                end
                
                if isempty(numBinsToFLocal) || exist('numBinsToFLocal') == 0
                    numBinsToFLocal = 4096;
                end
                
                % Default multi-stop conditions.
                if isempty(multiStopConditions) || exist('multiStopConditions') == 0
                    multiStopConditions = 4;
                end
                
                % Default KDE Spectra setting.
                if isempty(kdeSpectra) || exist('kdeSpectra') == 0
                    kdeSpectra = 0;
                end
                
                % Default number of samples to fit for the stabilizer.
                if isempty(stableLength) || exist('stableLength') == 0
                    stableLength = 10000;
                end
                
                % Defining spectra vectors.
                xdataToF = linspace(-250*10.^(-9),timingWindow*10.^(-6),numBinsToFLocal)';
                xdataGe = linspace(0,numBinsGeLocal-1,numBinsGeLocal)';
                ToF = zeros(1,numBinsToFLocal)';
                Ge = zeros(1,numBinsGeLocal)';
                gammaVsToF = zeros(numBinsGeLocal,numBinsToFLocal);
                
                % Begin plots if switch is turned on.
                if app.PlotsSwitch.Value == '1'
                    app.plots(0);
                end
                
                if app.SetStableChSwitch.Value == '1'
                    gammaMaxChange = true;
                end
                
                app.STARTButton_2.BackgroundColor = [1 0 0];
                app.STARTButton_2.Text = 'STOP/SAVE';
                app.ModeLamp.Color = [0 1 0];
                drawnow;
                
                % ////////////////////////////////////////////////////////////////////////////
                % ----------------------------------------------------------------------------
                % Primary Loop.
                % ----------------------------------------------------------------------------
                % ////////////////////////////////////////////////////////////////////////////
                
                while app.STARTButton_2.Value == 1 && i + 10 <= app.numSamples.Value
                    %try
                        %app = guidata(app);
                        
                        % Primary function.
                        [VoutMax,timeOfFlight] = tripleCoincidence_GeTiming_Trapezoidal_Functional_Input(selectPath, ...
                            i,analysisType,multiStop,multiStopConditions,ch2Timing,ch2Shaping,FFTFilterCh1Input,FFTFilterCh2Input, ...
                            FFTFilterCh1LowerInput,FFTFilterCh2LowerInput,riseTimeFilterLowerInput, ...
                            riseTimeFilterUpperInput,trapezoidalFilterTopInput,trapezoidalFilterRiseFallInput, ...
                            trapezoidalFilterTopInputFast,trapezoidalFilterRiseFallInputFast,BPFSwitch);
                        %timeOfFlight
                        %gammaWindow
                        % Histogram function.
                        [hToF,hGe,geMaxVsToF] = histo(10,multiStop,numBinsGeLocal,numBinsToFLocal,gammaWindow,timingWindow, ...
                            VoutMax,timeOfFlight);
                        
                        ToF = ToF + hToF;
                        
                        % Gamma spectrum stabilization.
                        
                        if stableSwitch == '1' && analysisType ~= 2
                            VoutMax3 = VoutMax';
                            timeOfFlight4 = timeOfFlight';
                            geOutMax = vertcat(geOutMax,VoutMax3);
                            timeOfFlight2 = vertcat(timeOfFlight2,timeOfFlight4);
                            
                            iTemp = i;
                            
                            % Remove try and fix stabilizer
                            if floor((i-1)/stableLength) == ((i-1)/stableLength) && i ~= 1
                                
                                % To allow stabilization with an initial sample number other than 1.
                                if i - initialSample <= stableLength
                                    newSampleNumber = i - initialSample;
                                    
                                else
                                    newSampleNumber = stableLength;
                                    
                                end
                                
                                if (i-1)/stableLength == 1 && gammaMaxChange == true && app.SetStableChSwitch.Value == '1' %% && runThrough > 1
                                    gammaMax = max(geOutMax)
                                    gammaWindow = gammaMax;
                                    control = gammaStableCh;
                                    
                                end
                                
                                % Stabilize and then send to the send to the histogram function.
                                [stableGe,control] = stable(peakStart,folderNumber,geOutMax,control,numBinsGeLocal,gammaWindow);
                                [~,GeTemp,geMaxVsToF] = histo(newSampleNumber,multiStop,numBinsGeLocal,numBinsToFLocal,gammaWindow, ...
                                    timingWindow,stableGe,timeOfFlight2);
                                assignin('base','stableGe',stableGe);
                                assignin('base','GeTemp',GeTemp);
                                control
                                i
                                toc
                                gammaVsToF = gammaVsToF + geMaxVsToF;
                                Ge = Ge + GeTemp;
                                
                                if kdeSpectra == 1
                                    geOutMaxKde = vertcat(geOutMaxKde,stableGe);
                                    
                                end
                                
                                assignin('base','geOutMax',geOutMax);
                                geOutMax = 0;
                                timeOfFlight2 = 0;
                                refreshdata(GePlotting,'caller');
                                
                            end
                            
                            i = iTemp;
                            % If gamma stabilization is turned off.
                            
                        else
                            
                            gammaVsToF = gammaVsToF + geMaxVsToF;
                            Ge = Ge + hGe;
                            
                        end
                        
                        if kdeSpectra == 1
                            VoutMax2 = VoutMax';
                            timeOfFlight3 = timeOfFlight';
                            
                            if multiStop == '1'
                                %assignin('base','timeOfFlight3',timeOfFlight3);
                                for L=1:10 % This was 1:10
                                    
                                    for PS=1:numel(timeOfFlight3{L})
                                        timeOfFlight5 = vertcat(timeOfFlight5,timeOfFlight3{L}(PS));
                                        
                                    end
                                    
                                end
                                timeOfFlightKde = vertcat(timeOfFlightKde,timeOfFlight5);
                                
                            else
                                timeOfFlightKde = vertcat(timeOfFlightKde,timeOfFlight3);
                                
                            end
                            
                            if stableSwitch ~= '1'
                                geOutMaxKde = vertcat(geOutMaxKde,VoutMax2);
                                
                            end
                            
                        end
                        
                        % Removing edges of histogram.
                        ToF(1,1) = 0;
                        ToF(numBinsToFLocal,1) = 0;
                        Ge(1,1) = 0;
                        Ge(numBinsGeLocal,1) = 0;
                        
                        % Plot if the corresponding switches are turned on.
                        if app.PlotsSwitch.Value == '1'
                            
                            if analysisType == 2
                                refreshdata(ToFPlotting,'caller');
                                
                            elseif analysisType == 3
                                refreshdata(GePlotting,'caller');
                                
                            else
                                refreshdata(GePlotting,'caller');
                                refreshdata(ToFPlotting,'caller');
                                
                            end
                            
                            if exist('ax') && app.DPlotSwitch.Value == '1'
                                refreshdata(ax,'caller');
                                
                            end
                            
                            %refreshdata(AugerGammaPlotting,'caller');
                            %{
                    GePlotting.XDataSource = 'xdataGe';
                    GePlotting.YDataSource = 'Ge';
                            %}
                            
                            %refreshdata(ToFPlotting,'caller');
                            %refreshdata(GePlotting,'caller');
                            
                            
                            %ToFPlotting = plot(app.ToFPlot,xdataToF,ToF);
                            %{
                    plot(app.GePlot,xdataGe,Ge);
                    surf(app.AugerGammaPlot,geMaxVsToF);
                            %}
                            
                        end
                        
                        switch multiStop
                            
                            case '0'
                                rejectionTime = rejectionTime + sum(timeOfFlight(:) == 1);
                                rejectionEnergy = rejectionEnergy + sum(VoutMax(:) == 0);
                                
                            case '1'
                                findInCellTime = cellfun(@(x) find(x == 1),timeOfFlight,'Uni',0);
                                rejectionTime = rejectionTime + numel([findInCellTime{:}]);
                                rejectionEnergy = rejectionEnergy + sum(VoutMax(:) == 0);
                                
                        end
                        
                        if i >= 50 && floor((i-1)/50) == ((i-1)/50)
                            app.TimingEditField.Value = (rejectionTime/i)*100;
                            app.EnergyEditField.Value = (rejectionEnergy/i)*100;
                            
                        end
                        
                        elapsedTime = toc
                        % Percentage gauge update and pause for callbacks.
                        app.PercentageCompleteGauge.Value = i/app.numSamples.Value*100;
                        app.RateGauge.Value = (i-initialSample)/elapsedTime;
                        drawnow;
                        pause(0.00001);
                        
                        % If pause button is pushed.
                        while app.PAUSEButton.Value == 1
                            pause(1);
                            app.ModeLamp.Color = [0.65 0.65 0.65];
                            if app.PAUSEButton.Value == 0
                                app.ModeLamp.Color = [0 1 0];
                            end
                        end
                        
                        i = i + 10; % This was i + 10
                        
                        % If Multi-Folder switch is switched to On.
                        if i + 10 >= app.numSamples.Value
                            if app.MultiFolderSwitch.Value == '1'
                                % Insert yes/no dialog box here.
                                answer = questdlg(['Folder Complete: ',num2str(folderNumber), ...
                                    '.',newline,'Select another folder?'],'Continue','Yes','No','No');
                                % Handle response.
                                switch answer
                                    case 'Yes'
                                        if kdeSpectra == 1
                                            app.kdeFunction();
                                        end
                                        
                                        app.saveAll();
                                        app.TracesFolderButtonPushed();
                                        app.InitialSampleMenuSelected();
                                        folderNumber = folderNumber + 1;
                                        i = 1;
                                        
                                    case 'No'
                                        if kdeSpectra == 1
                                            app.kdeFunction();
                                        end
                                        
                                        app.saveAll();
                                        app.STARTButton_2.BackgroundColor = [0 1 0];
                                        app.STARTButton_2.Text = 'START';
                                        app.STARTButton_2.Value = 0;
                                        app.ModeLamp.Color = [1 0 0];
                                        drawnow;
                                        
                                    case ''
                                        if kdeSpectra == 1
                                            app.kdeFunction();
                                        end
                                        
                                        app.saveAll();
                                        app.STARTButton_2.BackgroundColor = [0 1 0];
                                        app.STARTButton_2.Text = 'START';
                                        app.STARTButton_2.Value = 0;
                                        app.ModeLamp.Color = [1 0 0];
                                        drawnow;
                                        
                                end
                                
                                % Once all samples have been analyzed, save spectra and files.
                            else
                                if kdeSpectra == 1
                                    app.kdeFunction();
                                end
                                
                                app.saveAll();
                                app.STARTButton_2.BackgroundColor = [0 1 0];
                                app.STARTButton_2.Text = 'START';
                                app.STARTButton_2.Value = 0;
                                app.ModeLamp.Color = [1 0 0];
                                drawnow;
                                
                            end
                            
                        end
                        
                       %{ 
                    catch
                        errordlg('Unknown error. Try again or restart the program.','Error');
                        app.STARTButton_2.BackgroundColor = [0 1 0];
                        app.STARTButton_2.Text = 'START';
                        app.STARTButton_2.Value = 0;
                        app.ModeLamp.Color = [1 0 0];
                        drawnow;
                        break;
                    end
                    %}
                    end
                %{    
            catch
                errordlg('Unknown error. Try again or restart the program.','Error');
                app.STARTButton_2.BackgroundColor = [0 1 0];
                app.STARTButton_2.Text = 'START';
                app.STARTButton_2.Value = 0;
                app.ModeLamp.Color = [1 0 0];
                drawnow;
            end
            %}
        end
        
        function results2 = saveAll(app)
            global ToF;
            global Ge;
            global gammaVsToF;
            global savePath;
            global xdataToF;
            global xdataGe;
            global ax;
            global analysisType;
            global kdeSpectra;
            global geOutMaxKde;
            global timeOfFlightKde;
            
            savePath
            
            [~,savePath] = app.checkPaths('',savePath,1);
            
            ToFMatrix = horzcat(xdataToF,ToF);
            GeMatrix = horzcat(xdataGe,Ge);
            save([savePath,'\ToF.txt'], 'ToFMatrix','-ascii','-double','-tabs');
            save([savePath,'\Ge.txt'], 'GeMatrix','-ascii','-double','-tabs');
            save([savePath,'\gammaVsToF.mat'], 'gammaVsToF');
            
            if app.PlotsSwitch.Value == '1'
                
                if analysisType ~= 3
                    % Saving ToF figure (spectrum) as necessary.
                    x = xdataToF; %app.ToFPlot.XAxis.Parent.Children.XData;
                    y = ToF; %app.ToFPlot.YAxis.Parent.Children.YData;
                    f1 = figure('Visible','off');
                    h1 = axes(f1);
                    newPlot1 = plot(x,y);
                    h1.YLabel.String = app.ToFPlot.YLabel.String;
                    h1.YLabel.FontSize = app.ToFPlot.YLabel.FontSize;
                    h1.XLabel.String = app.ToFPlot.XLabel.String;
                    h1.XLabel.FontSize = app.ToFPlot.XLabel.FontSize;
                    h1.Title.String = app.ToFPlot.Title.String;
                    h1.Title.FontSize = app.ToFPlot.Title.FontSize;
                    %h1.XLim = [0 max(x)];
                    %h1.YLim = [0 max(y)+1];
                    saveas(h1,[savePath,'\ToF.jpg']);
                    saveas(h1,[savePath,'\ToF.fig']);
                    delete(newPlot1)
                    delete(h1)
                    delete(f1)
                    
                end
                
                if analysisType ~= 2
                    % Saving Ge figure (spectrum) as necessary.
                    x = xdataGe; %app.GePlot.XAxis.Parent.Children.XData;
                    y = Ge; %app.GePlot.YAxis.Parent.Children.YData;
                    f2 = figure('Visible','off');
                    h2 = axes(f2);
                    newPlot2 = plot(x,y);
                    h2.YLabel.String = app.GePlot.YLabel.String;
                    h2.YLabel.FontSize = app.GePlot.YLabel.FontSize;
                    h2.XLabel.String = app.GePlot.XLabel.String;
                    h2.XLabel.FontSize = app.GePlot.XLabel.FontSize;
                    h2.Title.String = app.GePlot.Title.String;
                    h2.Title.FontSize = app.GePlot.Title.FontSize;
                    %h2.XLim = [0 max(x)];
                    %h2.YLim = [0 max(y)+1];
                    saveas(h2,[savePath,'\Ge.jpg']);
                    saveas(h2,[savePath,'\Ge.fig']);
                    delete(newPlot2)
                    delete(h2)
                    delete(f2)
                end
                
            end
            
            if exist('ax') && app.DPlotSwitch.Value == '1'
                saveas(ax,[savePath,'\gammaVsToF.fig']);
            end
            
        end
        
        % Function for checking the existence of directories.
        function [selectPath,savePath] = checkPaths(app,selectPath,savePath,pathNumber)
            % -------------------------------------------------------------
            % PathNumber:
            % 0 = selectPath only.
            % 1 = savePath only.
            % 2 = both.
            % -------------------------------------------------------------
            isempty(selectPath)
            isnumeric(selectPath)
            exist(selectPath,'dir')
            selectPath
            if pathNumber ~= 1
                if isempty(selectPath) || isnumeric(selectPath) == 1 || exist(selectPath,'dir') == 0
                    
                    errordlg('Select a folder containing traces.');
                    selectPath = uigetdir('','Select a folder containing traces.');
                    selectPath2 = selectPath;
                    selectPathOriginal = app.TracesFolder.Value;
                    
                    if isempty(selectPath) || isnumeric(selectPath) == 1
                        if isnumeric(selectPath2) == 1 && isempty(selectPathOriginal)
                            while isnumeric(selectPath2) == 1
                                errordlg('No current traces path. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        elseif isnumeric(selectPath) == 1 && isempty(selectPathOriginal) == 0
                            selectPath = selectPathOriginal;
                            
                        elseif exist(selectPath2,'dir') == 0
                            while exist(selectPath2,'dir') == 0
                                errordlg('Folder does not exist. Select a folder containing traces.');
                                selectPath2 = uigetdir('','Select a folder containing traces');
                                selectPath = selectPath2;
                            end
                            app.TracesFolder.Value = selectPath;
                            
                        end
                        
                    else
                        app.TracesFolder.Value = selectPath;
                    end
                end
            end
            
            if pathNumber ~= 0
                if isempty(savePath) || isnumeric(savePath) == 1 || exist(savePath,'dir') == 0
                    savePathOriginal = app.SavingFolder.Value;
                    
                    errordlg('Select a folder in which to save the histogram.');
                    savePath = uigetdir('','Select a folder in which to save the histogram.');
                    app.SavingFolder.Value = savePath;
                    
                    savePath2 = savePath;
                    
                    % Checking if a folder in which to save the spectra and data has been selected.
                    if isempty(savePathOriginal) || isnumeric(savePath) == 1
                        if isnumeric(savePath2) == 1 && isempty(savePathOriginal)
                            while isnumeric(savePath2) == 1
                                errordlg('No current path. Select a folder in which to save the spectra.');
                                savePath2 = uigetdir('','Select a folder in which to save the spectra');
                            end
                            savePath = savePath2;
                            app.SavingFolder.Value = savePath;
                            
                        elseif isnumeric(savePath2) == 1 && isempty(savePathOriginal) == 0
                            savePath = savePathOriginal;
                            
                        elseif exist(savePath2,'dir') == 0
                            while exist(savePath2,'dir') == 0
                                errordlg('Folder does not exist. Select a folder in which to save the spectra.');
                                savePath2 = uigetdir('','Select a folder in which to save the spectra');
                            end
                            savePath = savePath2;
                            app.SavingFolder.Value = savePath;
                            
                        end
                        
                    else
                        app.SavingFolder.Value = savePath;
                    end
                end
            end
        end
        
        function results4 = kdeFunction(app,i)    
            global timeOfFlightKde;
            global geOutMaxKde;
            global numBinsGe;
            global numBinsToF;
            global xdataToF;
            global xdataGe;
            global ToFKde;
            global GeKde;
            global savePath;
            
            timingWindowTemp = str2double(app.TimingWindowsKnob.Value);
            timingWindow = timingWindowTemp*10.^(-6);
            gammaWindow = app.GammaEditField.Value;
            assignin('base','ToFKde',timeOfFlightKde);
            [~,ToFKde,~,~] = kde(timeOfFlightKde,numBinsToF,-250*10.^(-9), ...
                timingWindow);
            figure;
            plot(ToFKde);
            ToFKdeMatrix = horzcat(xdataToF,ToFKde);
            save([savePath,'\ToFKDE.txt'], 'ToFKdeMatrix','-ascii','-double','-tabs');
            [~,GeKde,~,~] = kde(geOutMaxKde,numBinsGe,0, ...
                gammaWindow);
            figure;
            plot(GeKde);
            GeKdeMatrix = horzcat(xdataGe,GeKde);
            save([savePath,'\GeKDE.txt'], 'GeKdeMatrix','-ascii','-double','-tabs');
            
        end
        
    end

    methods (Access = public)
    
        function out = stopped(app)
            out = app.STARTButton_2.Value;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Startup function to initialize variables that should reset.
            
            global LPFCounter;
            global trapezoidalFilterCounter;
            global initialSample;
            global kdeSpectra;
            global control;
            global gammaMaxChange;
            
            LPFCounter = 1;
            trapezoidalFilterCounter = 1;
            initialSample = 1;
            kdeSpectra = 0;
            control = 0;
            gammaMaxChange = false;
            i = 1;
            
        end

        % Button pushed function: TracesFolderButton
        function TracesFolderButtonPushed(app, event)
            
            global selectPath;
            global LPFCounter;
            global trapezoidalFilterCounter;
            global initialSample;
            global gammaMaxChange;
            selectPathOriginal = selectPath;
            
            try
                selectPath = uigetdir;
                app.TracesFolder.Value = selectPath;
                
                % Resetting trapezoidal filter and band pass filter counters if a new folder is selected.
                LPFCounter = 1;
                trapezoidalFilterCounter = 1;
                initialSample = 1;
                gammaMaxChange = false;
                
            catch
                selectPath2 = selectPath;
                
                if isnumeric(selectPath)
                    [selectPath,~] = app.checkPaths(selectPathOriginal,'',0);
                else
                    [selectPath,~] = app.checkPaths(selectPath,'',0);
                end
                
            end
        end

        % Value changed function: FFTFilterCh1
        function FFTFilterCh1ValueChanged(app, event)
            FFTFilterCh1Input = app.FFTFilterCh1.Value;
            
        end

        % Value changed function: FFTFilterCh2
        function FFTFilterCh2ValueChanged(app, event)
            FFTFilterCh2Input = app.FFTFilterCh2.Value;
            
        end

        % Value changed function: numSamples
        function numSamplesValueChanged(app, event)
            numSamplesInput = app.numSamples.Value;
            
        end

        % Value changed function: RiseTimeFilterUpper
        function RiseTimeFilterUpperValueChanged(app, event)
            riseTimeFilterUpperInput = app.RiseTimeFilterUpper.Value;
            
        end

        % Value changed function: RiseTimeFilterLower
        function RiseTimeFilterLowerValueChanged(app, event)
            riseTimeFilterLowerInput = app.RiseTimeFilterLower.Value;
            
        end

        % Value changed function: TrapezoidalFilterTop
        function TrapezoidalFilterTopValueChanged(app, event)
            trapezoidalFilterTopInput = app.TrapezoidalFilterTop.Value;
            
        end

        % Value changed function: TrapezoidalFilterRiseFall
        function TrapezoidalFilterRiseFallValueChanged(app, event)
            trapezoidalFilterRiseFallInput = app.TrapezoidalFilterRiseFall.Value;
            
        end

        % Callback function
        function STOPSAVEButtonPushed(app, event)
            
        end

        % Button pushed function: PlotLPFButton
        function PlotLPFButtonPushed(app, event)
            
            % Function to plot the band pass filtered pulses in the app window.
            
            global selectPath;
            global LPFCounter;
            global analysisType;
            
            if isempty(LPFCounter)
                LPFCounter = 1;
            end
            
            channelNumber1 = 'C1Trace';
            channelNumber2 = 'C2Trace';
            i = LPFCounter;
            
            if i < 10
                appendage = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
            try
                
                if analysisType ~= 3
                    fileName1 = [selectPath,'\',channelNumber1,appendage,'.trc'];
                    pulse{1}(i) = waveform.ReadLeCroyBinaryWaveform(fileName1);
                    pulse{1}(i).y = bpfilter(pulse{1}(i),1,app.FFTFilterCh1.Value*10.^6,app.FFTFilterCh2.Value*10.^6, ...
                        app.FFTFilterCh1Lower.Value*10.^6,app.FFTFilterCh2Lower.Value*10.^6);
                    plot(app.FFTFilterPlot_2,pulse{1}(i).x,pulse{1}(i).y);
                    
                end
                
                fileName2 = [selectPath,'\',channelNumber2,appendage,'.trc'];
                pulse{2}(i) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                if app.FFTFilterSwitch.Value == '1'
                    pulse{2}(i).y = bpfilter(pulse{2}(i),2,app.FFTFilterCh1.Value*10.^6,app.FFTFilterCh2.Value*10.^6, ...
                        app.FFTFilterCh1Lower.Value*10.^6,app.FFTFilterCh2Lower.Value*10.^6);
                    
                end
                
                plot(app.FFTFilterPlot,pulse{2}(i).x,pulse{2}(i).y);
                
            catch
                errordlg('No file.');
                
            end
            
            %assignin('base','pulse',pulse{2}(i).y);
            
        end

        % Button pushed function: PlotTrapezoidalFilterButton
        function PlotTrapezoidalFilterButtonPushed(app, event)
            
            % Function to plot a trapezoidal-shaped pulse in the app window.
            
            global selectPath;
            global trapezoidalFilterCounter;
            global savePath;
            
            shapingTypeIn = str2double(app.Ch2DropDownShaping.Value);
            
            if isempty(trapezoidalFilterCounter) || isnumeric(trapezoidalFilterCounter) == 0
                trapezoidalFilterCounter = 1;
            end
            
            channelNumber = 'C2Trace';
            i = trapezoidalFilterCounter;
            
            if i < 10
                appendage = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage = ['0',stringconversion(i)];
            else
                appendage = stringconversion(i);
            end
            
            %try
                fileName = [selectPath,'\',channelNumber,appendage,'.trc'];
                pulse{2}(trapezoidalFilterCounter) = waveform.ReadLeCroyBinaryWaveform(fileName);
                
                TRFIn = app.TrapezoidalFilterRiseFall.Value*10.^(-6);
                TFTopIn = app.TrapezoidalFilterTop.Value*10.^(-6);
                FFTUpCh1In = app.FFTFilterCh1.Value*10.^6;
                FFTUpCh2In = app.FFTFilterCh2.Value*10.^6;
                FFTLowCh1In = app.FFTFilterCh1Lower.Value*10.^6;
                FFTLowCh2In = app.FFTFilterCh2Lower.Value*10.^6;
                pulse{2}(trapezoidalFilterCounter).y = bpfilter(pulse{2}(trapezoidalFilterCounter),2,FFTUpCh1In, ...
                    FFTUpCh2In,FFTLowCh1In,FFTLowCh2In);
                [~,Vout] = trapezoidalFilter(pulse{2}(trapezoidalFilterCounter),shapingTypeIn,TRFIn,TFTopIn);
                plot(app.TrapezoidalFilterPlot,Vout);
                trapezoidalFilterCounter
                Vout = Vout';
                %assignin('base','unshaped',pulse{2}(trapezoidalFilterCounter).y);
                %assignin('base','shaped',Vout);
                %save([savePath,'\trapezoid',num2str(trapezoidalFilterCounter),'.txt'], 'Vout','-ascii','-double','-tabs');
                
            %catch
             %   errordlg('No file.');
                
            %end
            
        end

        % Button pushed function: SavingFolderButton
        function SavingFolderButtonPushed(app, event)
            global savePath;
            
            savePathOriginal = savePath;
            
            try
                savePath = uigetdir;
                app.SavingFolder.Value = savePath;
                
            catch
                savePath2 = savePath;
                
                % Checking if a folder in which to save the spectra and data has been selected.
                if isnumeric(savePath)
                    [~,savePath] = app.checkPaths('',savePathOriginal,1);
                else
                    [~,savePath] = app.checkPaths('',savePath,1);
                end
                
            end
        end

        % Value changed function: PAUSEButton
        function PAUSEButtonValueChanged(app, event)
            value = app.PAUSEButton.Value;
            
        end

        % Value changed function: PlotsSwitch
        function PlotsSwitchValueChanged(app, event)
            value = app.PlotsSwitch.Value;
            
            % Allowing plots to be turned on or off during analysis.
            if value == '1'
                app.plots(0);
            end
        end

        % Callback function
        function GammaWindowSliderValueChanging(app, event)
            changingValue = event.Value;
            % Setting gamma edit field value to be equal to the gamma window slider.
            app.GammaEditField.Value = changingValue;
            
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            value = app.GammaEditField.Value;
            
        end

        % Button pushed function: PlotTrapezoidalFilterButtonNext
        function PlotTrapezoidalFilterButtonNextPushed(app, event)
            %global selectPath;
            global trapezoidalFilterCounter;
            trapezoidalFilterCounter = trapezoidalFilterCounter + 1;
            PlotTrapezoidalFilterButtonPushed(app);
            
        end

        % Button pushed function: PlotLPFButtonNext
        function PlotLPFButtonNextPushed(app, event)
            global LPFCounter;
            LPFCounter = LPFCounter + 1;
            PlotLPFButtonPushed(app);
        end

        % Callback function
        function SAVECURRENTButtonValueChanged(app, event)
            
        end

        % Button pushed function: SAVECURRENTButton
        function SAVECURRENTButtonPushed(app, event)
            originalLampColor = app.ModeLamp.Color;
            originalButtonColor = app.SAVECURRENTButton.BackgroundColor;
            app.ModeLamp.Color = [1 1 0];
            app.SAVECURRENTButton.BackgroundColor = [1 1 0];
            app.SAVECURRENTButton.Text = 'SAVING...';
            drawnow;
            
            app.saveAll();
            
            pause(1)
            app.ModeLamp.Color = originalLampColor;
            app.SAVECURRENTButton.BackgroundColor = originalButtonColor;
            app.SAVECURRENTButton.Text = 'SAVE CURRENT';
            drawnow;
        end

        % Value changed function: STARTButton_2
        function STARTButton_2ValueChanged(app, event)
            
            % Defining globals used between the functions of the app.
            global stopped
            global kdeSpectra
            
            % Begin analysis if start button has been pressed.
            if app.STARTButton_2.Value == 1
                stopped = 0;
                app.analysis();
                
                % Save spectra and files if stop button is pushed.
            elseif app.STARTButton_2.Value == 0
                stopped = 1;
                originalLamp = app.ModeLamp.Color;
                app.ModeLamp.Color = [1 1 0];
                app.STARTButton_2.BackgroundColor = [1 1 0];
                app.STARTButton_2.Text = 'SAVING...';
                drawnow;
                
                if kdeSpectra == 1
                    app.kdeFunction();
                end
                
                app.saveAll();
                
                app.ModeLamp.Color = [1 0 0];
                app.STARTButton_2.BackgroundColor = [0 1 0];
                app.STARTButton_2.Text = 'START';
                drawnow;
                
            end
            
        end

        % Value changed function: TracesFolder
        function TracesFolderValueChanged(app, event)
            global selectPath;
            value = app.TracesFolder.Value;
            selectPath = value;
            
            if exist(selectPath,'dir') == 0
                errordlg('Traces folder does not exist.');
            end
            
        end

        % Value changed function: SavingFolder
        function SavingFolderValueChanged(app, event)
            global savePath;
            value = app.SavingFolder.Value;
            savePath = value;
            
            if exist(savePath,'dir') == 0
                errordlg('Saving folder does not exist.');
            end
            
        end

        % Menu selected function: BinningMenu
        function BinningMenuSelected(app, event)
            global numBinsGe;
            global numBinsToF;
            prompt = {'ToF','Gamma Energy'};
            title = 'Number of bins';
            dims = [1 20];
            definput = {'4096','4096'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result)
                numBinsToF = 4096;
                numBinsGe = 4096;
            else
                numBinsToF = str2num(result{1});
                numBinsGe = str2num(result{2});
            end
        end

        % Menu selected function: RiseTimesMenu
        function RiseTimesMenuSelected(app, event)
            global selectPath;
            global savePath;
            
            FFTUpCh2 = app.FFTFilterCh2.Value;
            FFTLowCh2 = app.FFTFilterCh2Lower.Value;
            
            [selectPath,savePath] = app.checkPaths(selectPath,savePath,2);
            
            prompt = 'Number of samples: ';
            title = 'Ge Rise Time Histogram';
            dims = [1 20];
            definput = {'1000'};
            result = inputdlg(prompt,title,dims,definput);
            
            if isempty(result) == 0
                numSamples2 = str2num(result{1});
                opts.Interpreter = 'none';
                opts.Default = 'No';
                answer = questdlg('Plot result?','Ge Rise Time Histogram','Yes','No',opts);
                
                app.ModeLamp.Color = [1 1 0];
                app.STARTButton_2.Enable = false;
                app.PAUSEButton.Enable = false;
                app.SAVECURRENTButton.Enable = false;
                drawnow;
                
                [binsRiseTime,riseTimeHistogram] = RiseTimeHistogram(numSamples2,selectPath,FFTUpCh2,FFTLowCh2);
                outputs = horzcat(binsRiseTime,riseTimeHistogram);
                
                save([savePath,'\riseTimeHistogram.txt'], 'outputs','-ascii','-double','-tabs');
                
                switch answer
                    
                    case 'Yes'
                        figure;
                        plot(binsRiseTime,riseTimeHistogram);
                        saveas(gcf,[savePath,'\Rise Time Histogram.jpg']);
                        
                    case 'No'
                        'Did not plot.'
                        
                end
                
                app.ModeLamp.Color = [1 0 0];
                app.STARTButton_2.Enable = true;
                app.PAUSEButton.Enable = true;
                app.SAVECURRENTButton.Enable = true;
                drawnow;
            end
            
        end

        % Value changed function: Ch2DropDownShaping
        function Ch2DropDownShapingValueChanged(app, event)
            value = str2double(app.Ch2DropDownShaping.Value);
            
            if value == 0
                app.PlotTrapezoidalFilterButton.Enable = false;
                app.PlotTrapezoidalFilterButtonNext.Enable = false;
                app.TrapezoidalFilterTop.Enable = false;
                app.TrapezoidalFilterRiseFall.Enable = false;
                
            elseif value == 1 || value == 2
                app.PlotTrapezoidalFilterButton.Enable = true;
                app.PlotTrapezoidalFilterButtonNext.Enable = true;
                app.TrapezoidalFilterTop.Enable = true;
                app.TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel.Text = 'Rise/Fall';
                
            elseif value == 3
                app.PlotTrapezoidalFilterButton.Enable = true;
                app.PlotTrapezoidalFilterButtonNext.Enable = true;
                app.TrapezoidalFilterTop.Enable = false;
                app.TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel.Text = 'Shp. Const.';
                
            elseif value == 4
                app.PlotTrapezoidalFilterButton.Enable = true;
                app.PlotTrapezoidalFilterButtonNext.Enable = true;
                app.TrapezoidalFilterTop.Enable = true;
                app.TrapezoidalFilterRiseFall.Enable = true;
                app.RiseFallLabel.Text = 'Rise/Fall';
                
            end
            
        end

        % Menu selected function: AnalysisTypeMenu
        function AnalysisTypeMenuSelected(app, event)
            global analysisType;
            
            % Gamma-Auger: 1
            % Time of Flight: 2
            % Gamma: 3
            
            [analysisType,tf] = listdlg('PromptString','Type of analysis: ',...
                           'SelectionMode','single',...
                           'ListString',{'Gamma-Auger Coincidence','Time of flight only','Gamma only'});
                       
        end

        % Menu selected function: PeakLocationMenu
        function PeakLocationMenuSelected(app, event)
            global peakStart;
            
            prompt = 'Channel number where the 511 keV peak begins: ';
            title = 'Peak Location Selection';
            dims = [1 20];
            definput = {'10'};
            answer = inputdlg(prompt,title,dims,definput);
            answer = cell2mat(answer);
            peakStart = str2num(answer);
            
        end

        % Menu selected function: CollectionTimeMenu
        function CollectionTimeMenuSelected(app, event)
            global selectPath;
            global initialSample;
            global analysisType;
            
            if isempty(initialSample)
                initialSample = 1;
            end
            
            if isempty(analysisType)
                analysisType = 1;
            end
            
            if isempty(selectPath)
                app.checkPaths(selectPath,'',0);
            end
            
            numSamplesLocal = (app.numSamples.Value - 1);
            analysisType
            
            a = dir([selectPath,'\*.trc']);
            
            if analysisType == 1
                channelNumber1 = 'C1Trace';
                i = numel(a)/2 - 2;
                
            elseif analysisType == 2
                channelNumber1 = 'C1Trace';
                i = numel(a) - 2;
                
            elseif analysisType == 3
                channelNumber1 = 'C2Trace';
                i = numel(a) - 2;
                
            end
            
            if initialSample < 10
                appendage1 = ['0000',stringconversion(initialSample)];
            elseif initialSample >= 10 && initialSample < 100
                appendage1 = ['000',stringconversion(initialSample)];
            elseif initialSample >= 100 && initialSample < 1000
                appendage1 = ['00',stringconversion(initialSample)];
            elseif initialSample >= 1000 && initialSample < 10000
                appendage1 = ['0',stringconversion(initialSample)];
            else
                appendage1 = stringconversion(initialSample);
            end

            
            i = i - 1
            
            if i < 10
                appendage2 = ['0000',stringconversion(i)];
            elseif i >= 10 && i < 100
                appendage2 = ['000',stringconversion(i)];
            elseif i >= 100 && i < 1000
                appendage2 = ['00',stringconversion(i)];
            elseif i >= 1000 && i < 10000
                appendage2 = ['0',stringconversion(i)];
            else
                appendage2 = stringconversion(i);
            end
            
            try
                fileName1 = [selectPath,'\',channelNumber1,appendage1,'.trc'];
                fileName2 = [selectPath,'\',channelNumber1,appendage2,'.trc'];
                pulse{1}(1) = waveform.ReadLeCroyBinaryWaveform(fileName1);
                pulse{1}(2) = waveform.ReadLeCroyBinaryWaveform(fileName2);
                
                time1 = pulse{1}(1).info.TRIGGER_TIME;
                time2 = pulse{1}(2).info.TRIGGER_TIME;
                
                f = msgbox({['Collection began on: ',time1];newline;['Collection ended on: ',time2]},'Total Collection Time');
                
            catch
                errordlg('No file.');
                
            end
            
        end

        % Callback function
        function MultiStopMenuSelected(app, event)
 
        end

        % Menu selected function: InitialSampleMenu
        function InitialSampleMenuSelected(app, event)
            global initialSample;
            
            prompt = 'Initial sample number with which to begin analysis: ';
            title = 'Initial Sample';
            dims = [1 20];
            definput = {'1'};
            result = inputdlg(prompt,title,dims,definput);
            
            beforeRounding = str2num(result{1});
            
            initialSample = round(beforeRounding,-1);
            initialSample = initialSample + 1;
            
        end

        % Value changed function: DPlotSwitch
        function DPlotSwitchValueChanged(app, event)
            value = app.DPlotSwitch.Value;
            
            if value == '1'
                    app.plots(0);
                    
            end
                    
        end

        % Menu selected function: EnergyCalibrationMenu
        function EnergyCalibrationMenuSelected(app, event)
            global selectPath;
            global savePath;
            global analysisType;
            
            originalSelectPath = selectPath;
            originalSavePath = savePath;
            noFolder = false;
            originalAnalysisType = analysisType;
            analysisType = 2;
            
            try
                calibrationPath = uigetdir;
            catch
                noFolder = true;
            end
            
            if noFolder == false && isnumeric(calibrationPath) == false && exist(calibrationPath,'dir') ~= 0
                folderList = dir(calibrationPath);
                assignin('base','folderList',folderList);
                app.STARTButton_2.Value = 1;
                app.ModeLamp.Color = [0 1 0];
                drawnow;
                i = 3;
                
                while i <= numel(folderList) && app.STARTButton_2.Value == 1
                    
                    folderName = folderList(i).name;
                    if numel(str2num(folderName)) > 0 && str2num(folderName) > 0 && str2num(folderName) < 25000
                        selectPath = [calibrationPath,'\',folderName,'\Autosave'];
                        savePath = [calibrationPath,'\',folderName,'\Analyzed'];

                        if exist(savePath,'dir') == 0
                            currentFolder = pwd;
                            cd([calibrationPath,'\',folderName,]);
                            mkdir('Analyzed');
                            
                        end
                        
                        app.analysis();
                        app.saveAll();
                        app.STARTButton_2.Value = 1;
                        pause(1);
                        
                    end
                    
                    i = i + 1;
                end
                
            end
            
            analysisType = originalAnalysisType;
            app.ModeLamp.Color = [1 0 0];
            app.STARTButton_2.BackgroundColor = [0 1 0];
            app.STARTButton_2.Text = 'START';
            app.STARTButton_2.Value = 0;
            drawnow;
            msgbox('Energy calibration analysis complete.','Complete');
            
        end

        % Menu selected function: MultiStopConditionsMenu
        function MultiStopConditionsMenuSelected(app, event)
            global multiStopConditions;
            
            initialConditions = multiStopConditions;
            
            [multiStopConditions,tf] = listdlg('PromptString','Number of electrons to count per event: ',...
                           'SelectionMode','single','InitialValue',4,...
                           'ListString',{'1 Only','2 Only','3 Only','All'});
                       
            if tf == 0
                multiStopConditions = initialConditions;
                
            end
                           
        end

        % Menu selected function: KDESpectraMenu
        function KDESpectraMenuSelected(app, event)
            global kdeSpectra;
            warning = msgbox('Warning: Saving KDE spectra can only be saved with fewer than 50,000 samples.');
            answer = questdlg('Save KDE spectra?', ...
               	'KDE Spectra', ...
               	'Yes','No','No');
            
            % Handle response
            switch answer
                case 'Yes'
                    kdeSpectra = 1;
                    
                case 'No'
                    kdeSpectra = 0;
                    
            end
        end

        % Value changed function: MultiStopSwitch
        function MultiStopSwitchValueChanged(app, event)
            value = app.MultiStopSwitch.Value;
            
        end

        % Menu selected function: StabilizationSettingsMenu
        function StabilizationSettingsMenuSelected(app, event)
            global stableLength;
            
            prompt = 'Number of samples to fit for stabilization: ';
            title = 'Stabilization Collection';
            dims = [1 20];
            definput = {'10000'};
            answer = inputdlg(prompt,title,dims,definput);
            answer = cell2mat(answer);
            stableLength = str2num(answer);
            
            stableLength = round(stableLength,-1)
        end

        % Menu selected function: ShapingParameterOptimizationMenu
        function ShapingParameterOptimizationMenuSelected(app, event)
            global selectPath;
            global savePath;
            global analysisType;
            global Ge;
            global xdataGe;
            global peakStart;
            global gammaMaxChange;
            %global runThrough;
            
            answer = questdlg('Does peak appear in proper position for 2 us/1 us shaping times?', ...
               	'Peak Position', ...
               	'Yes','No','No');
            
            % Handle response
            switch answer
                
                case 'Yes'
                    
                    originalSelectPath = selectPath;
                    originalSavePath = savePath;
                    noFolder = false;
                    originalAnalysisType = analysisType;
                    analysisType = 3;
                    gammaMaxChange = true;
                    %originalPlotSwitchValue = app.PlotsSwitch.Value;
                    %app.PlotsSwitch.Value = '1';
                    originalStabilizerSwitch = app.GammaStabilizerSwitch.Value;
                    app.GammaStabilizerSwitch.Value = '1';
                    originalStabilizerControlSwitch = app.SaveStabilizerControlSwitch.Value;
                    app.SaveStabilizerControlSwitch.Value = '1';
                    originalSetStableSwitch = app.SetStableChSwitch.Value;
                    app.SetStableChSwitch.Value = '1';
                    optimizationPath = selectPath;
                    %runThrough = 0;
                    %{
                    try
                        optimizationPath = uigetdir;
                    catch
                        noFolder = true;
                    end
                    %}
                    if noFolder == false && isnumeric(optimizationPath) == false && exist(optimizationPath,'dir') ~= 0 ...
                            && app.Ch2DropDownShaping.Value ~= 0
                        app.STARTButton_2.Value = 1;
                        app.ModeLamp.Color = [0 1 0];
                        drawnow;
                        i = 1;
                        
                        while i <= 10 && app.STARTButton_2.Value == 1
                            
                            %selectPath = optimizationPath;
                            
                            % Changing rise time parameter for trapezoid or shaping parameter for Gaussian.
                            app.TrapezoidalFilterRiseFall.Value = 4*i - 2;
                            
                            if app.Ch2DropDownShaping.Value == 3
                                numParam = 1;
                                
                            else
                                numParam = 10;
                                
                            end
                            
                            j = 1;
                            
                            while j <= numParam && (j) < (4*i - 2)
                                
                                if numParam ~= 1
                                    app.TrapezoidalFilterTop.Value = (j);
                                    
                                end
                                
                                app.analysis();
                                smoothedGe = smooth(Ge);
                                [~,peakMaxIndex] = max(smoothedGe(10:numel(smoothedGe)-10));
                                peakMaxIndex = peakMaxIndex + 10;
                                
                                gaussFit = fit(xdataGe(peakMaxIndex-100:peakMaxIndex+120), ... 
                                    Ge(peakMaxIndex-100:peakMaxIndex+120),'gauss1');
                                
                                cValues = coeffvalues(gaussFit);
                                FWHM = 2*sqrt(2*log(2))*cValues(3);
                                
                                app.checkPaths('',savePath,1);
                                
                                save([savePath,'\Op_Ge_',num2str(app.TrapezoidalFilterRiseFall.Value),'usRT_', ...
                                    num2str(app.TrapezoidalFilterTop.Value),'usTop_',num2str(FWHM),'FWHM','.txt'], ...
                                    'Ge','-ascii','-double','-tabs');
                                
                                app.STARTButton_2.Value = 1;
                                pause(1);
                                
                                j = j + 1;
                                
                            end
                            
                            
                            i = i + 1;
                            
                        end
                        
                    end
                    
                    analysisType = originalAnalysisType;
                    gammaMaxChange = false;
                    %app.PlotsSwitch.Value = originalPlotSwitchValue;
                    app.GammaStabilizerSwitch.Value = originalStabilizerSwitch;
                    app.SaveStabilizerControlSwitch.Value = originalStabilizerControlSwitch;
                    app.SetStableChSwitch.Value = originalSetStableSwitch;
                    app.ModeLamp.Color = [1 0 0];
                    app.STARTButton_2.BackgroundColor = [0 1 0];
                    app.STARTButton_2.Text = 'START';
                    app.STARTButton_2.Value = 0;
                    drawnow;
                    msgbox('Optimization analysis complete.','Complete');
                    
                case 'No'
            end
        end

        % Value changed function: Ch2DropDownTiming
        function Ch2DropDownTimingValueChanged(app, event)
            value = str2double(app.Ch2DropDownTiming.Value);
            
            if value == 2
                app.RiseTimeFilterLower.Enable = false;
                app.RiseTimeFilterUpper.Enable = false;
                app.TrapezoidalFilterTop_Fast.Enable = true;
                app.TrapezoidalFilterRiseFall_Fast.Enable = true;
                
            else
                app.RiseTimeFilterLower.Enable = true;
                app.RiseTimeFilterUpper.Enable = true;
                app.TrapezoidalFilterTop_Fast.Enable = false;
                app.TrapezoidalFilterRiseFall_Fast.Enable = false;
                
            end
                
        end

        % Value changed function: SetStableChSwitch
        function SetStableChSwitchValueChanged(app, event)
            value = app.SetStableChSwitch.Value;
            global gammaMaxChange;
            
            if value == '0'
                app.GammaStableCh.Enable = false;
                gammaMaxChange = false;
                
            elseif value == '1'
                app.GammaStableCh.Enable = true;
                gammaMaxChange = true;
                
            end
        end

        % Value changed function: GammaStabilizerSwitch
        function GammaStabilizerSwitchValueChanged(app, event)
            value = app.GammaStabilizerSwitch.Value;
           
            if value == '1'
                app.GammaStableCh.Enable = true;
                app.SetStableChSwitch.Enable = true;
                app.SaveStabilizerControlSwitch.Enable = true;
                
            elseif value == '0'
                app.GammaStableCh.Enable = false;
                app.SetStableChSwitch.Enable = false;
                app.SaveStabilizerControlSwitch.Enable = false;
                app.SaveStabilizerControlSwitch.Value = '0';
                app.SetStableChSwitch.Value = '0';
                
            end
        end

        % Value changed function: FFTFilterSwitch
        function FFTFilterSwitchValueChanged(app, event)
            value = app.FFTFilterSwitch.Value;
            
            if value == 1
                app.FFTFilterCh1.Enable = true;
                app.FFTFilterCh2.Enable = true;
                app.FFTFilterCh1Lower.Enable = true;
                app.FFTFilterCh2Lower.Enable = true;
                
            else
                app.FFTFilterCh1.Enable = false;
                app.FFTFilterCh2.Enable = false;
                app.FFTFilterCh1Lower.Enable = false;
                app.FFTFilterCh2Lower.Enable = false;
                
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PositroninducedElectronGammaUIFigure = uifigure;
            app.PositroninducedElectronGammaUIFigure.Name = 'Positron-induced Electron - Gamma';
            app.OptionsMenu = uimenu(app.PositroninducedElectronGammaUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.AnalysisTypeMenu = uimenu(app.OptionsMenu);
            app.AnalysisTypeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisTypeMenuSelected, true);
            app.AnalysisTypeMenu.Text = 'Analysis Type';
            app.BinningMenu = uimenu(app.OptionsMenu);
            app.BinningMenu.MenuSelectedFcn = createCallbackFcn(app, @BinningMenuSelected, true);
            app.BinningMenu.Text = 'Binning';
            app.RiseTimesMenu = uimenu(app.OptionsMenu);
            app.RiseTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @RiseTimesMenuSelected, true);
            app.RiseTimesMenu.Text = 'Rise Times';
            app.PeakLocationMenu = uimenu(app.OptionsMenu);
            app.PeakLocationMenu.MenuSelectedFcn = createCallbackFcn(app, @PeakLocationMenuSelected, true);
            app.PeakLocationMenu.Text = 'Peak Location';
            app.CollectionTimeMenu = uimenu(app.OptionsMenu);
            app.CollectionTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @CollectionTimeMenuSelected, true);
            app.CollectionTimeMenu.Text = 'Collection Time';
            app.InitialSampleMenu = uimenu(app.OptionsMenu);
            app.InitialSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @InitialSampleMenuSelected, true);
            app.InitialSampleMenu.Text = 'Initial Sample';
            app.EnergyCalibrationMenu = uimenu(app.OptionsMenu);
            app.EnergyCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @EnergyCalibrationMenuSelected, true);
            app.EnergyCalibrationMenu.Text = 'Energy Calibration';
            app.MultiStopConditionsMenu = uimenu(app.OptionsMenu);
            app.MultiStopConditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiStopConditionsMenuSelected, true);
            app.MultiStopConditionsMenu.Text = 'Multi-Stop Conditions';
            app.KDESpectraMenu = uimenu(app.OptionsMenu);
            app.KDESpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @KDESpectraMenuSelected, true);
            app.KDESpectraMenu.Text = 'KDE Spectra';
            app.StabilizationSettingsMenu = uimenu(app.OptionsMenu);
            app.StabilizationSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @StabilizationSettingsMenuSelected, true);
            app.StabilizationSettingsMenu.Text = 'Stabilization Settings';
            app.ShapingParameterOptimizationMenu = uimenu(app.OptionsMenu);
            app.ShapingParameterOptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @ShapingParameterOptimizationMenuSelected, true);
            app.ShapingParameterOptimizationMenu.Text = 'Shaping Parameter Optimization';
            app.numSamples = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.numSamples.ValueChangedFcn = createCallbackFcn(app, @numSamplesValueChanged, true);
            app.numSamples.Value = 99999;
            app.NumberofSamplesLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.NumberofSamplesLabel.Text = 'Number of Samples';
            app.TracesFolderButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.TracesFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TracesFolderButtonPushed, true);
            app.TracesFolderButton.Text = 'Traces Folder';
            app.PercentageCompleteGaugeLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.PercentageCompleteGaugeLabel.Text = 'Percentage Complete';
            app.PercentageCompleteGauge = uigauge(app.PositroninducedElectronGammaUIFigure, 'circular');
            app.SavingFolderButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.SavingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavingFolderButtonPushed, true);
            app.SavingFolderButton.Text = 'Saving Folder';
            app.TracesFolder = uieditfield(app.PositroninducedElectronGammaUIFigure, 'text');
            app.TracesFolder.ValueChangedFcn = createCallbackFcn(app, @TracesFolderValueChanged, true);
            app.SavingFolder = uieditfield(app.PositroninducedElectronGammaUIFigure, 'text');
            app.SavingFolder.ValueChangedFcn = createCallbackFcn(app, @SavingFolderValueChanged, true);
            app.PlotsSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.PlotsSwitchLabel.Text = 'Plots';
            app.PlotsSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.PlotsSwitch.ItemsData = {'0', '1'};
            app.PlotsSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotsSwitchValueChanged, true);
            app.PlotsSwitch.Value = '1';
            app.PAUSEButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'state');
            app.PAUSEButton.ValueChangedFcn = createCallbackFcn(app, @PAUSEButtonValueChanged, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.ModeLampLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.ModeLampLabel.Text = 'Mode';
            app.ModeLamp = uilamp(app.PositroninducedElectronGammaUIFigure);
            app.DPlotSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.DPlotSwitchLabel.Text = '3D Plot';
            app.DPlotSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.DPlotSwitch.ItemsData = {'0', '1'};
            app.DPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @DPlotSwitchValueChanged, true);
            app.DPlotSwitch.Value = '0';
            app.SAVECURRENTButton = uibutton(app.PositroninducedElectronGammaUIFigure, 'push');
            app.SAVECURRENTButton.ButtonPushedFcn = createCallbackFcn(app, @SAVECURRENTButtonPushed, true);
            app.SAVECURRENTButton.Text = 'SAVE CURRENT';
            app.STARTButton_2 = uibutton(app.PositroninducedElectronGammaUIFigure, 'state');
            app.STARTButton_2.ValueChangedFcn = createCallbackFcn(app, @STARTButton_2ValueChanged, true);
            app.STARTButton_2.Text = 'START';
            app.MultiFolderSwitchLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.MultiFolderSwitchLabel.Text = 'Multi-Folder';
            app.MultiFolderSwitch = uiswitch(app.PositroninducedElectronGammaUIFigure, 'slider');
            app.MultiFolderSwitch.ItemsData = {'0', '1'};
            app.MultiFolderSwitch.Value = '0';
            app.AnalysisRateHzLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.AnalysisRateHzLabel.Text = 'Analysis Rate (Hz)';
            app.RateGauge = uigauge(app.PositroninducedElectronGammaUIFigure, 'semicircular');
            app.RateGauge.Limits = [0 20];
            app.TabGroup = uitabgroup(app.PositroninducedElectronGammaUIFigure);
            app.ToFSpectrumControlsTab = uitab(app.TabGroup);
            app.ToFSpectrumControlsTab.Title = 'ToF Spectrum Controls';
            app.TimingWindowsKnobLabel = uilabel(app.ToFSpectrumControlsTab);
            app.TimingWindowsKnobLabel.Text = 'Timing Window (μs)';
            app.TimingWindowsKnob = uiknob(app.ToFSpectrumControlsTab, 'discrete');
            app.TimingWindowsKnob.Items = {'1', '2', '3', '4', '5', '7'};
            app.TimingWindowsKnob.Value = '4';
            app.MultiStopSwitchLabel = uilabel(app.ToFSpectrumControlsTab);
            app.MultiStopSwitchLabel.Text = 'Multi-Stop';
            app.MultiStopSwitch = uiswitch(app.ToFSpectrumControlsTab, 'slider');
            app.MultiStopSwitch.ItemsData = {'0', '1'};
            app.MultiStopSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiStopSwitchValueChanged, true);
            app.MultiStopSwitch.Value = '0';
            app.TrapezoidalFilterTop_Fast = uieditfield(app.ToFSpectrumControlsTab, 'numeric');
            app.TrapezoidalFilterTop_Fast.Enable = 'off';
            app.TrapezoidalFilterTop_Fast.Value = 1;
            app.TrapezoidalFilterRiseFall_Fast = uieditfield(app.ToFSpectrumControlsTab, 'numeric');
            app.TrapezoidalFilterRiseFall_Fast.Enable = 'off';
            app.TrapezoidalFilterRiseFall_Fast.Value = 2;
            app.TrapTimingFastLabel = uilabel(app.ToFSpectrumControlsTab);
            app.TrapTimingFastLabel.Text = 'Trap. Timing (Fast)';
            app.GammaTimingLabel = uilabel(app.ToFSpectrumControlsTab);
            app.GammaTimingLabel.Text = 'Gamma Timing';
            app.Ch2DropDownTiming = uidropdown(app.ToFSpectrumControlsTab);
            app.Ch2DropDownTiming.Items = {'ELET', 'CFD (no RTF)', 'Trapezoidal'};
            app.Ch2DropDownTiming.ItemsData = {'1', '0', '2'};
            app.Ch2DropDownTiming.ValueChangedFcn = createCallbackFcn(app, @Ch2DropDownTimingValueChanged, true);
            app.Ch2DropDownTiming.Value = '1';
            app.GammaSpectrumControlsTab = uitab(app.TabGroup);
            app.GammaSpectrumControlsTab.Title = 'Gamma Spectrum Controls';
            app.GammaEditField = uieditfield(app.GammaSpectrumControlsTab, 'numeric');
            app.GammaEditField.Limits = [0 1000];
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.GammaEditField.Value = 200;
            app.GammaWindowSliderLabel = uilabel(app.GammaSpectrumControlsTab);
            app.GammaWindowSliderLabel.Text = 'Gamma Window';
            app.GammaStableCh = uieditfield(app.GammaSpectrumControlsTab, 'numeric');
            app.GammaStableCh.Limits = [0 8192];
            app.GammaStableCh.Enable = 'off';
            app.GammaStableCh.Value = 3500;
            app.GammaStableChLabel = uilabel(app.GammaSpectrumControlsTab);
            app.GammaStableChLabel.Text = 'Stabilizer Ch. #';
            app.GammaStabilizerSwitchLabel = uilabel(app.GammaSpectrumControlsTab);
            app.GammaStabilizerSwitchLabel.Text = 'Gamma Stabilizer';
            app.GammaStabilizerSwitch = uiswitch(app.GammaSpectrumControlsTab, 'slider');
            app.GammaStabilizerSwitch.ItemsData = {'0', '1'};
            app.GammaStabilizerSwitch.ValueChangedFcn = createCallbackFcn(app, @GammaStabilizerSwitchValueChanged, true);
            app.GammaStabilizerSwitch.Value = '0';
            app.SaveStabilizerControlSwitchLabel = uilabel(app.GammaSpectrumControlsTab);
            app.SaveStabilizerControlSwitchLabel.Enable = 'off';
            app.SaveStabilizerControlSwitchLabel.Text = 'Save Stabilizer Control';
            app.SaveStabilizerControlSwitch = uiswitch(app.GammaSpectrumControlsTab, 'slider');
            app.SaveStabilizerControlSwitch.ItemsData = {'0', '1'};
            app.SaveStabilizerControlSwitch.Enable = 'off';
            app.SaveStabilizerControlSwitch.Value = '0';
            app.SetStableChSwitchLabel = uilabel(app.GammaSpectrumControlsTab);
            app.SetStableChSwitchLabel.Text = 'Set Stable Ch. #';
            app.SetStableChSwitch = uiswitch(app.GammaSpectrumControlsTab, 'slider');
            app.SetStableChSwitch.ItemsData = {'0', '1'};
            app.SetStableChSwitch.ValueChangedFcn = createCallbackFcn(app, @SetStableChSwitchValueChanged, true);
            app.SetStableChSwitch.Enable = 'off';
            app.SetStableChSwitch.Value = '0';
            app.FFTFilterPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.FFTFilterPanel.Title = 'FFT Filter';
            app.FFTFilterCh1 = uieditfield(app.FFTFilterPanel, 'numeric');
            app.FFTFilterCh1.ValueChangedFcn = createCallbackFcn(app, @FFTFilterCh1ValueChanged, true);
            app.FFTFilterCh1.Value = 50;
            app.Ch1Label = uilabel(app.FFTFilterPanel);
            app.Ch1Label.Text = 'Ch. 1';
            app.Ch2Label = uilabel(app.FFTFilterPanel);
            app.Ch2Label.Text = 'Ch. 2';
            app.FFTFilterCh2 = uieditfield(app.FFTFilterPanel, 'numeric');
            app.FFTFilterCh2.ValueChangedFcn = createCallbackFcn(app, @FFTFilterCh2ValueChanged, true);
            app.FFTFilterCh2.Value = 1000;
            app.FFTFilterCutoffFrequenciesMHzLabel = uilabel(app.FFTFilterPanel);
            app.FFTFilterCutoffFrequenciesMHzLabel.Text = 'FFT Filter Cutoff Frequencies (MHz)';
            app.FFTFilterPlot = uiaxes(app.FFTFilterPanel);
            title(app.FFTFilterPlot, 'Channel 2')
            xlabel(app.FFTFilterPlot, 'time')
            ylabel(app.FFTFilterPlot, 'voltage')
            app.PlotLPFButton = uibutton(app.FFTFilterPanel, 'push');
            app.PlotLPFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButtonPushed, true);
            app.PlotLPFButton.Text = 'Plot';
            app.FFTFilterPlot_2 = uiaxes(app.FFTFilterPanel);
            title(app.FFTFilterPlot_2, 'Channel 1')
            xlabel(app.FFTFilterPlot_2, 'time')
            ylabel(app.FFTFilterPlot_2, 'voltage')
            app.PlotLPFButtonNext = uibutton(app.FFTFilterPanel, 'push');
            app.PlotLPFButtonNext.ButtonPushedFcn = createCallbackFcn(app, @PlotLPFButtonNextPushed, true);
            app.PlotLPFButtonNext.Text = 'Plot Next';
            app.FFTFilterCh2Lower = uieditfield(app.FFTFilterPanel, 'numeric');
            app.FFTFilterCh2Lower.Value = 1e-05;
            app.FFTFilterCh1Lower = uieditfield(app.FFTFilterPanel, 'numeric');
            app.FFTFilterCh1Lower.Value = 1;
            app.FFTFilterSwitch = uiswitch(app.FFTFilterPanel, 'slider');
            app.FFTFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FFTFilterSwitchValueChanged, true);
            app.ShapingFittingPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.ShapingFittingPanel.Title = 'Shaping / Fitting';
            app.RiseFallLabel = uilabel(app.ShapingFittingPanel);
            app.RiseFallLabel.Text = 'Rise/Fall';
            app.TopLabel = uilabel(app.ShapingFittingPanel);
            app.TopLabel.Text = 'Top';
            app.TrapezoidalFilterTop = uieditfield(app.ShapingFittingPanel, 'numeric');
            app.TrapezoidalFilterTop.ValueChangedFcn = createCallbackFcn(app, @TrapezoidalFilterTopValueChanged, true);
            app.TrapezoidalFilterTop.Value = 1;
            app.EnergySlowsLabel = uilabel(app.ShapingFittingPanel);
            app.EnergySlowsLabel.Text = 'Energy (Slow) (μs)';
            app.TrapezoidalFilterRiseFall = uieditfield(app.ShapingFittingPanel, 'numeric');
            app.TrapezoidalFilterRiseFall.ValueChangedFcn = createCallbackFcn(app, @TrapezoidalFilterRiseFallValueChanged, true);
            app.TrapezoidalFilterRiseFall.Value = 2;
            app.TrapezoidalFilterPlot = uiaxes(app.ShapingFittingPanel);
            title(app.TrapezoidalFilterPlot, 'Channel 2')
            xlabel(app.TrapezoidalFilterPlot, 'X')
            ylabel(app.TrapezoidalFilterPlot, 'Y')
            app.PlotTrapezoidalFilterButton = uibutton(app.ShapingFittingPanel, 'push');
            app.PlotTrapezoidalFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrapezoidalFilterButtonPushed, true);
            app.PlotTrapezoidalFilterButton.Text = 'Plot';
            app.PlotTrapezoidalFilterButtonNext = uibutton(app.ShapingFittingPanel, 'push');
            app.PlotTrapezoidalFilterButtonNext.ButtonPushedFcn = createCallbackFcn(app, @PlotTrapezoidalFilterButtonNextPushed, true);
            app.PlotTrapezoidalFilterButtonNext.Text = 'Plot Next';
            app.GammaShapingLabel = uilabel(app.ShapingFittingPanel);
            app.GammaShapingLabel.Text = 'Gamma Shaping';
            app.Ch2DropDownShaping = uidropdown(app.ShapingFittingPanel);
            app.Ch2DropDownShaping.Items = {'Recursive Trapezoidal', 'Integral', 'Manual Convolution (Trapezoidal)', 'Moving Average Semi-Gaussian', 'Moving Average Trapezoidal', 'Finite Length Cusp', 'Fitting'};
            app.Ch2DropDownShaping.ItemsData = {'1', '0', '2', '3', '4', '5', '7'};
            app.Ch2DropDownShaping.ValueChangedFcn = createCallbackFcn(app, @Ch2DropDownShapingValueChanged, true);
            app.Ch2DropDownShaping.Value = '1';
            app.Label = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Label.Text = '1';
            app.DropDown = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.DropDown.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.DropDown.ItemsData = {'1', '2', '3', '4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.Label_2 = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Label_2.Text = '2';
            app.DropDown_2 = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.DropDown_2.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.DropDown_2.ItemsData = {'1', '2', '3', '4'};
            app.DropDown_2.Value = '3';
            app.Label_3 = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Label_3.Text = '3';
            app.DropDown_3 = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.DropDown_3.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.DropDown_3.ItemsData = {'1', '2', '3', '4'};
            app.DropDown_3.Value = '4';
            app.Label_4 = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.Label_4.Text = '4';
            app.DropDown_4 = uidropdown(app.PositroninducedElectronGammaUIFigure);
            app.DropDown_4.Items = {'Electron', 'Gamma (Pre-Shaped)', 'Gamma (Pulse)', 'NC'};
            app.DropDown_4.ItemsData = {'1', '2', '3', '4'};
            app.DropDown_4.Value = '4';
            app.ChannelTypesLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.ChannelTypesLabel.Text = 'Channel Types';
            app.RiseTimeFilterPanel = uipanel(app.PositroninducedElectronGammaUIFigure);
            app.RiseTimeFilterPanel.Title = 'Rise Time Filter';
            app.UppernsLabel = uilabel(app.RiseTimeFilterPanel);
            app.UppernsLabel.Text = 'Upper (ns)';
            app.RiseTimeFilterUpper = uieditfield(app.RiseTimeFilterPanel, 'numeric');
            app.RiseTimeFilterUpper.ValueChangedFcn = createCallbackFcn(app, @RiseTimeFilterUpperValueChanged, true);
            app.RiseTimeFilterUpper.Value = 300;
            app.LowernsLabel = uilabel(app.RiseTimeFilterPanel);
            app.LowernsLabel.Text = 'Lower (ns)';
            app.RiseTimeFilterLower = uieditfield(app.RiseTimeFilterPanel, 'numeric');
            app.RiseTimeFilterLower.ValueChangedFcn = createCallbackFcn(app, @RiseTimeFilterLowerValueChanged, true);
            app.ChannelLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.ChannelLabel.Text = 'Channel #';
            app.TimingEditFieldLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.TimingEditFieldLabel.Text = 'Timing';
            app.TimingEditField = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.TimingEditField.Editable = 'off';
            app.EnergyEditFieldLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.PositroninducedElectronGammaUIFigure, 'numeric');
            app.EnergyEditField.Editable = 'off';
            app.RejectionRatioLabel = uilabel(app.PositroninducedElectronGammaUIFigure);
            app.RejectionRatioLabel.Text = 'Rejection Ratio (%)';
            app.TabGroup2 = uitabgroup(app.PositroninducedElectronGammaUIFigure);
            app.Channels12Tab = uitab(app.TabGroup2);
            app.Channels12Tab.Title = 'Channels 1 & 2';
            app.ToFPlot = uiaxes(app.Channels12Tab);
            title(app.ToFPlot, 'Time-of-Flight')
            xlabel(app.ToFPlot, 'Time of Flight (s)')
            ylabel(app.ToFPlot, 'Intensity')
            app.GePlot = uiaxes(app.Channels12Tab);
            title(app.GePlot, 'Gamma Energy')
            xlabel(app.GePlot, 'Energy (ch. #)')
            ylabel(app.GePlot, 'Intensity')
            app.Channels34Tab = uitab(app.TabGroup2);
            app.Channels34Tab.Title = 'Channels 3 & 4';
            app.ToFPlot_2 = uiaxes(app.Channels34Tab);
            title(app.ToFPlot_2, 'NC')
            xlabel(app.ToFPlot_2, 'X')
            ylabel(app.ToFPlot_2, 'Y')
            app.GePlot_2 = uiaxes(app.Channels34Tab);
            title(app.GePlot_2, 'NC')
            xlabel(app.GePlot_2, 'X')
            ylabel(app.GePlot_2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = PEGS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PositroninducedElectronGammaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PositroninducedElectronGammaUIFigure)
        end
    end
end